classdef application_prototype_TEST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Status                      matlab.ui.control.Label
        TimesLabel                  matlab.ui.control.Label
        TimerLabel                  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        ConsignedetempratureCPanel  matlab.ui.container.Panel
        ConsigneField               matlab.ui.control.NumericEditField
        ConsigneEditFieldLabel      matlab.ui.control.Label
        ChargerParametresButton     matlab.ui.control.Button
        ArrterButton                matlab.ui.control.Button
        DmarrerButton               matlab.ui.control.Button
        RgulateurPIDFPanel          matlab.ui.container.Panel
        TdField                     matlab.ui.control.NumericEditField
        TdLabel                     matlab.ui.control.Label
        TfField                     matlab.ui.control.NumericEditField
        TfLabel                     matlab.ui.control.Label
        InfoTfButtonPushed          matlab.ui.control.Button
        InfoTdButtonPushed          matlab.ui.control.Button
        TiField                     matlab.ui.control.NumericEditField
        TiEditFieldLabel            matlab.ui.control.Label
        KcField                     matlab.ui.control.NumericEditField
        KcEditFieldLabel            matlab.ui.control.Label
        InfoKcButtonPushed          matlab.ui.control.Button
        InfoTiButtonPushed          matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %serialObj        serialport
        running          logical = false;
        serialObj; % Description
    end
    
    methods (Access = public)
        
        function DmarrerButtonPushedCallback(app, event)
            DmarrerButtonPushed(app, event);
       end
        % Callback function for the STOP Button press
        function ArrterButtonPushedCallback(app, event)
            ArrterButtonPushed(app, event);
        end
         % Callback function for the STOP Button press
        function ChargerParametresButtonPushedCallback(app, event)
           ChargerParametresButtonPushed(app, event);
        end
        % Callback function for the UIAxesButtonDown event
        function UIAxesButtonDownCallback(app, event)
            UIAxesButtonDown(app, event);
        end
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        try
                % Initialize the serial object for communication with Arduino
                app.serialObj = serialport('COM3', 115200);
                configureTerminator(app.serialObj, "LF"); % Configure the serial object to use LF terminator
                app.Status.Text = 'Status: Connected to Arduino';
            catch
                app.Status.Text = 'Status: Failed to connect';
            end
        end

        % Button pushed function: DmarrerButton
        function DmarrerButtonPushed(app, event)

       try

        flushinput(app.serialObj);  % Vide le buffer de lecture (entrée)
        % Vérifier si la connexion série existe et est valide
            % Envoi du signal "START" à Arduino pour démarrer l'asservissement
            writeline(app.serialObj, 'START');
            
            % Mise à jour du statut dans l'interface pour indiquer l'envoi
            app.Status.Text = 'Status: Envoi du signal START à Arduino...';
            
            % Attendre la réponse d'Arduino (l'Arduino doit répondre avec "Status: OK")
            response = fgets(app.serialObj);  % Lire la réponse
            disp(['Réponse Arduino: ', response]);  % Afficher la réponse pour le débogage
            
            % Vérification de la réponse d'Arduino
            if contains(response, 'P')  % Vérifie si Arduino a démarré l'asservissement
                app.Status.Text = 'Status: Asservissement démarré avec succès!';
                app.running = true;  % Marquer le système comme en cours d'exécution
            else
                app.Status.Text = 'Reponse inattendue';
            end
    catch ME
        % En cas d'erreur, afficher un message dans le statut et dans la console
        app.Status.Text = ['Status: Failed to start system - ', ME.message];
        disp(['Erreur : ', ME.message]);  % Affichage dans la console pour le débogage
    end
            
        end

        % Button pushed function: ArrterButton
        function ArrterButtonPushed(app, event)
              % Créer une connexion série à l'Arduino sur le port COMx
             %s = serialport('COM3', 115200);
             %fopen(s)
             %if isempty(app.serialObj)
                %return;
            %end
            % Envoyer la commande 'STOP' à l'Arduino
             %writeline(app.serialObj, 'STOP');
    
            % Attendre la réponse de l'Arduino
            %response = fscanf(s);  % Lire la réponse de l'Arduino
           % writeline(app.serialObj, "STOP");
            %app.running = false;
            % Vérifier la réponse et mettre à jour le statut
            %if contains(response, "Arrêt de l\'asservissement")
              %app.Status.Text = 'Asservissement arrêté !';
            %else
              %app.Status.Text = 'Erreur de communication avec Arduino.';
            %end
            %clear s;  % Ferme et libère le port série
            %delete(s);
    try
        flushinput(app.serialObj);  % Vide le buffer de lecture (entrée)
            writeline(app.serialObj, 'STOP');
            pause(0.5);
            response = readline(app.serialObj);
            if contains(response, "P")
                  app.running = false; % Mark the system as stopped
                  app.Status.Text = 'Asservissement arrêté !';
            else
                 app.Status.Text = 'Erreur de communication avec Arduino.';

            end
            
    catch
        app.Status.Text = 'Status: Failed to stop system';
    end
        end

        % Button pushed function: ChargerParametresButton
        function ChargerParametresButtonPushed(app, event)
try
    % Affichage du statut d'envoi des données
    app.Status.Text = 'Envoi des paramètres en cours...';  % Mise à jour du statut
    disp('Envoi des paramètres en cours...');  % Affichage console pour le débogage
    
    % Vider le buffer de l'objet série avant d'envoyer les données
    flushinput(app.serialObj);  % Vide le buffer de lecture (entrée)
    
    % Envoi des paramètres via l'objet série déjà existant (app.s)
    writeline(app.serialObj, sprintf("SET_Kc %.6f", app.KcField.Value));
    pause(0.1);  % Pause pour permettre à Arduino de traiter
    
    writeline(app.serialObj, sprintf("SET_Ti %.6f", app.TiField.Value));
    pause(0.1);
    
    writeline(app.serialObj, sprintf("SET_Td %.6f", app.TdField.Value));
    pause(0.1);
    
    writeline(app.serialObj, sprintf("SET_Tf %.6f", app.TfField.Value));
    pause(0.1);
    
    writeline(app.serialObj, sprintf("SET_Consigne %.6f", app.ConsigneField.Value));
    pause(0.1);
    
    % Lecture de la réponse d'Arduino pour vérifier la réception
    response = fgets(app.serialObj);  % ou readline(app.serialObj)
    disp("Réponse Arduino : " +response);  % Affichage de la réponse d'Arduino
    
    % Vérification de la réponse d'Arduino
    if contains(response, 'PID_SET')  % Vérifie que la réponse contient "Status: OK"
        app.Status.Text = 'Données envoyées avec succès.';  % Mise à jour du statut
        disp('Données envoyées avec succès.');
    elseif contains(response, 'Status: ERROR')
        app.Status.Text = 'Erreur lors de lenvoi des données.';  % Mise à jour du statut
        disp('Erreur lors de lenvoi des données.');
    else
        app.Status.Text = 'Réponse inattendue Arduino.';  % Mise à jour du statut
        disp('Réponse inattendue Arduino.');
    end
    
catch ME
    % Gestion des erreurs
    app.Status.Text = 'Erreur lors de lenvoi des paramètres.';  % Mise à jour du statut
    uialert(app.UIFigure, sprintf('Erreur lors de lenvoi des paramètres : %s', ME.message), 'Erreur Communication');
    disp(['Erreur : ', ME.message]);  % Affichage du message d'erreur
end

        end

        % Callback function
        function UIAxesButtonDown(app, event)
                  % Button down function: UIAxes
            if ~app.running
                return; % Exit if the system is not running
            end
            % Initialisation des variables
            time_data = [];  % Tableau pour stocker les valeurs de temps
            temp_data = [];  % Tableau pour stocker les valeurs de température
            app.Status.Text = 'Status: Plotting Temperature...';
            % Connexion série avec Arduino (Assurez-vous que le port COM est correct)
            %s = serialport('COM3', 115200);
            %fopen(s);
            % Configuration du graphique
            figure;
            h = plot(app.UIAxes, [], []);
            xlabel('Temps (s)');
            ylabel('Température (°C)');
            title('Température en Temps Réel');
            grid on;
        
            % Boucle principale pour lire les données
            while app.running
                % Lire la température depuis l'Arduino (adaptez la commande à votre besoin)
                writeline(app.serialObj, 'GET_TEMP');  % Exemple de commande pour demander la température à Arduino
                temp = read(app.serialObj, 1,'uint8');  % Lire la réponse de l'Arduino (attendez une valeur numérique)
        
                % Récupérer le temps actuel (en secondes ou en utilisant le champ "time")
                current_time_str = seconds(datetime('now'));
                % Mettre à jour le label TimerLabel avec le temps actuel
                app.TimesLabel.Text = current_time_str;  % Afficher l'heure actuelle dans le label
        
                % Ajouter la nouvelle valeur de température et de temps aux tableaux
                time_data = [time_data, str2double(current_time_str)];  % Ajouter le temps actuel sous forme de nombre
                temp_data = [temp_data, temp];          % Ajouter la température mesurée
                
                % Mettre à jour le graphique avec les nouvelles données
                set(h, 'XData', time_data, 'YData', temp_data);  % Mettre à jour les données du graphique
                
                % Re-dessiner le graphique
                drawnow;  % Actualise le graphique en temps réel
        
                % Ajouter une petite pause pour éviter que la boucle ne s'exécute trop rapidement
                pause(1);  % Attendre 1 seconde avant de prendre une nouvelle lecture
            end
            %fclose(s);
            clear s;
            %delete(s)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Température  à T3')
            xlabel(app.UIAxes, 'Temps[s]')
            ylabel(app.UIAxes, 'Température[°C]')
            zlabel(app.UIAxes, 'Z')
            app.RgulateurPIDFPanel = uipanel(app.GridLayout);
            app.RgulateurPIDFPanel.Title = 'Régulateur PIDF';
            app.InfoTiButtonPushed = uibutton(app.RgulateurPIDFPanel, 'push');
            app.InfoTiButtonPushed.Tooltip = {'''Entrez ici la valeur du coefficient Ti du PI'', ...'; '''Enter here the value of the Ti coefficient of the PI controller.'' ...'};
            app.InfoTiButtonPushed.Text = 'ⓘ';
            app.InfoKcButtonPushed = uibutton(app.RgulateurPIDFPanel, 'push');
            app.InfoKcButtonPushed.Tooltip = {'''Entrez ici la valeur du coefficient Kc du PI'', ...'; '''Enter here the value of the Kc coefficient of the PI controller.'' ...'};
            app.InfoKcButtonPushed.Text = 'ⓘ';
            app.KcEditFieldLabel = uilabel(app.RgulateurPIDFPanel);
            app.KcEditFieldLabel.Text = 'Kc';
            app.KcField = uieditfield(app.RgulateurPIDFPanel, 'numeric');
            app.TiEditFieldLabel = uilabel(app.RgulateurPIDFPanel);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiField = uieditfield(app.RgulateurPIDFPanel, 'numeric');
            app.InfoTdButtonPushed = uibutton(app.RgulateurPIDFPanel, 'push');
            app.InfoTdButtonPushed.Tooltip = {'''Entrez ici la valeur du coefficient Ti du PI'', ...'; '''Enter here the value of the Ti coefficient of the PI controller.'' ...'};
            app.InfoTdButtonPushed.Text = 'ⓘ';
            app.InfoTfButtonPushed = uibutton(app.RgulateurPIDFPanel, 'push');
            app.InfoTfButtonPushed.Tooltip = {'''Entrez ici la valeur du coefficient Kc du PI'', ...'; '''Enter here the value of the Kc coefficient of the PI controller.'' ...'};
            app.InfoTfButtonPushed.Text = 'ⓘ';
            app.TfLabel = uilabel(app.RgulateurPIDFPanel);
            app.TfLabel.Text = 'Tf';
            app.TfField = uieditfield(app.RgulateurPIDFPanel, 'numeric');
            app.TdLabel = uilabel(app.RgulateurPIDFPanel);
            app.TdLabel.Text = 'Td';
            app.TdField = uieditfield(app.RgulateurPIDFPanel, 'numeric');
            app.DmarrerButton = uibutton(app.GridLayout, 'push');
            app.DmarrerButton.ButtonPushedFcn = createCallbackFcn(app, @DmarrerButtonPushed, true);
            app.DmarrerButton.Text = 'Démarrer';
            app.ArrterButton = uibutton(app.GridLayout, 'push');
            app.ArrterButton.ButtonPushedFcn = createCallbackFcn(app, @ArrterButtonPushed, true);
            app.ArrterButton.Text = 'Arrêter';
            app.ChargerParametresButton = uibutton(app.GridLayout, 'push');
            app.ChargerParametresButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerParametresButtonPushed, true);
            app.ChargerParametresButton.Text = 'Charger Paramètres';
            app.ConsignedetempratureCPanel = uipanel(app.GridLayout);
            app.ConsignedetempratureCPanel.Title = 'Consigne de température [°C] ';
            app.ConsigneEditFieldLabel = uilabel(app.ConsignedetempratureCPanel);
            app.ConsigneEditFieldLabel.Text = 'Consigne';
            app.ConsigneField = uieditfield(app.ConsignedetempratureCPanel, 'numeric');
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, '89d9531e-83be-4734-95d5-eb811c90cad8_removalai_preview.png');
            app.TimerLabel = uilabel(app.GridLayout);
            app.TimerLabel.Text = '';
            app.TimesLabel = uilabel(app.GridLayout);
            app.TimesLabel.Text = 'Time(s)';
            app.Status = uilabel(app.GridLayout);
            app.Status.Text = 'En attente .......';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application_prototype_TEST

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end