classdef application_prototype_TEST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        SauvgarderdonnesButton      matlab.ui.control.StateButton
        Lamp_2                      matlab.ui.control.Lamp
        PortUSBDropDownLabel        matlab.ui.control.Label
        port                        matlab.ui.control.DropDown
        Lamp                        matlab.ui.control.Lamp
        Connecter                   matlab.ui.control.Button
        Status                      matlab.ui.control.Label
        TimesLabel                  matlab.ui.control.Label
        TimerLabel                  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        ConsignedetempratureCPanel  matlab.ui.container.Panel
        ConsigneField               matlab.ui.control.NumericEditField
        ConsigneEditFieldLabel      matlab.ui.control.Label
        ChargerParametresButton     matlab.ui.control.Button
        ArrterButton                matlab.ui.control.Button
        DmarrerButton               matlab.ui.control.Button
        RgulateurPIDFPanel          matlab.ui.container.Panel
        Label                       matlab.ui.control.Label
        TdField                     matlab.ui.control.NumericEditField
        TdLabel                     matlab.ui.control.Label
        TfField                     matlab.ui.control.NumericEditField
        TfLabel                     matlab.ui.control.Label
        InfoTfButtonPushed          matlab.ui.control.Button
        InfoTdButtonPushed          matlab.ui.control.Button
        TiField                     matlab.ui.control.NumericEditField
        TiEditFieldLabel            matlab.ui.control.Label
        KcField                     matlab.ui.control.NumericEditField
        KcEditFieldLabel            matlab.ui.control.Label
        InfoKcButtonPushed          matlab.ui.control.Button
        InfoTiButtonPushed          matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        preport = "a";
        Timer;
        t0;
        %serialObj        serialport
        running          logical = false;
        serialObj; % Description
        temps ;
        temp3;
        h3;
    end
    
    methods (Access = public)
        
        function DmarrerButtonPushedCallback(app, event)
            DmarrerButtonPushed(app, event);
       end
        % Callback function for the STOP Button press
        function ArrterButtonPushedCallback(app, event)
            ArrterButtonPushed(app, event);
        end
         % Callback function for the STOP Button press
        function ChargerParametresButtonPushedCallback(app, event)
           ChargerParametresButtonPushed(app, event);
        end
        
        
        function ConnecterButtonPushedCallback(app, event)
            ConnecterButtonPushed(app, event);
        end 
           
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
       
        end

        % Callback function: not associated with a component
        function DmarrerButtonPushed(app, event)
try
    % **1. Vider le buffer série en lecture et en écriture pour ne pas avoir d'anciennes données**
    flushinput(app.serialObj);
    flushoutput(app.serialObj);

    % **2. Envoi du signal "START" à Arduino**
    writeline(app.serialObj, 'START');
    app.Status.Text = 'Status: Envoi du signal START à Arduino...';
    disp("Signal START envoyé à Arduino");

    % **3. Attendre une réponse avec un timeout**
    timeout = 5;  % Timeout en secondes
    tStart = tic;  % Démarrer le chrono
    
    response = "";
    while toc(tStart) < timeout
        if app.serialObj.NumBytesAvailable > 0
            response = readline(app.serialObj);
            break;
        end
        pause(0.1);  % Éviter d'utiliser trop de CPU
    end
    
    % Vérifier si la réponse a été reçue
    if response == ""
        app.Status.Text = 'Erreur: Timeout - Aucune réponse d’Arduino';
        disp('Erreur: Timeout - Aucune réponse d’Arduino');
        return;
    end
    
    disp(['Réponse Arduino : ', response]);

    % **4. Vérification de la réponse d'Arduino**
    if contains(response, 'Asservissement commence.')
        app.Status.Text = 'Asservissement démarré avec succès!';
        app.running = true;
        app.t0 = tic;

        % Vérification et démarrage du timer
        if ~isempty(app.TimerLabel)  % Vérifie si TimerLabel est défini
            app.Timer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 1, ...
                'TimerFcn', @(~,~) set(app.TimerLabel, 'Text', sprintf('%.2f s', toc(app.t0))) ...
            );
            start(app.Timer);
        else
            disp('TimerLabel non défini');
        end
    else
        app.Status.Text = 'Réponse inattendue';
        return;
    end

    % **5. Initialisation du graphique si première exécution**
    if isempty(app.temps)
        app.temps = [];
        app.temp3 = [];
        app.t0 = tic;
        axes(app.UIAxes);
        hold(app.UIAxes, 'on');
        app.h3 = plot(app.UIAxes, app.temps, app.temp3, 'b', 'LineWidth', 2);
        xlabel(app.UIAxes, 'Temps (s)');
        ylabel(app.UIAxes, 'Température (°C)');
        %legend(app.UIAxes, 'Température 3');
        grid(app.UIAxes, 'on');
    end

    % **6. Lecture des données série en continu**
    while app.running
    if app.serialObj.NumBytesAvailable > 0
        data = readline(app.serialObj);

        disp("Réponse Arduino : " + data);
        temp = str2double(data);
        %disp(temp(1));

        % Vérifier si temp contient des NaN
        if any(isnan(temp))
            %disp("Donnée invalide (NaN) reçue, ignorée");
            continue;  % Ignorer cette donnée et passer à la suivante
        end

        % Ajouter uniquement les valeurs valides
        t = toc(app.t0);

        % Assurez-vous que le temps n'est pas NaN (c'est peu probable, mais c'est pour la sécurité)
         app.temps = [app.temps, t];  % Ajouter le temps
         app.temp3 = [app.temp3, temp];  % Ajouter la température
         disp(app.temp3);
         %disp(app.temps);
         

        % Mettre à jour les données du graphique
        %set(app.h3, 'XData', app.temps, 'YData', app.temp3);
        app.h3 = plot(app.UIAxes, app.temps, app.temp3, 'b', 'LineWidth', 2);
        drawnow;
        if (app.ConsigneField.Value - 0.1 < app.temp3(end)) && (app.temp3(end) < app.ConsigneField.Value + 0.1)
            app.Status.Text = sprintf("Asservissement Terminé en %.2f s!", t);
            app.Lamp_2.Color = 'g';
            app.Lamp_2.Color = 'white';
            app.Lamp_2.Color = 'g';
            app.Lamp_2.Color = 'white';
            app.Lamp_2.Color = 'g';
        end
    end
    pause(0.1);
end


catch ME
    app.Status.Text = ['Erreur : ', ME.message];
    disp(['Erreur : ', ME.message]);
end
        end

        % Callback function: not associated with a component
        function ArrterButtonPushed(app, event)
   try
       flushinput(app.serialObj);
        flushoutput(app.serialObj);  % Vide le buffer de lecture (entrée)
            writeline(app.serialObj, 'STOP');
            app.Status.Text = 'Status: Envoi du signal STOP à Arduino...';
            pause(1);
            response = readline(app.serialObj);
            disp(['Réponse Arduino0: ', response]);  % Afficher la réponse pour le débogage
            if contains(response, "Asservissement STOPPEE.")
                  app.running = false; % Mark the system as stopped
                  stop(app.Timer); % Arrêter le timer
                  app.Status.Text = 'Asservissement arrêté !';
            else
                 app.Status.Text = 'arrêt impossible.';

            end
            
    catch   
        app.Status.Text = 'Status: Failed to stop system';
    end
        end

        % Callback function: not associated with a component
        function ChargerParametresButtonPushed(app, event)
try
    % Affichage du statut d'envoi des données
    app.Status.Text = 'Envoi des paramètres en cours...';  % Mise à jour du statut
    disp('Envoi des paramètres en cours...');  % Affichage console pour le débogage
    
    % Vider le buffer de l'objet série avant d'envoyer les données
    flushinput(app.serialObj);  % Vide le buffer de lecture (entrée)
    flushoutput(app.serialObj);
Kc = app.KcField.Value;
Ti = app.TiField.Value;
Td = app.TdField.Value;
Tf = app.TfField.Value;
Consigne =app.ConsigneField.Value;
    % Concaténer tous les paramètres dans une seule chaîne
   params_str = sprintf('PID-SET-%f-%f-%f-%f-%f', Kc, Ti, Td, Tf, Consigne);
    % Envoi des paramètres via l'objet série déjà existant
    writeline(app.serialObj, params_str);  % Envoie tous les paramètres en une seule fois
    pause(3);  % Pause pour permettre à Arduino de traiter
    
    % Lecture de la réponse d'Arduino pour vérifier la réception
    response = readline(app.serialObj);  % ou readline(app.serialObj)
    disp("Réponse Arduino1 : " + response);  % Affichage de la réponse d'Arduino
    
    % Vérification de la réponse d'Arduino
    if contains(response, 'Commande valide.')  % Vérifie que la réponse contient "PID_SET"
        app.Status.Text = 'Données envoyées avec succès.';  % Mise à jour du statut
        disp('Données envoyées avec succès.');
    elseif contains(response, 'Commande invalide.')
        app.Status.Text = "Echec d'envoi des données.';  % Mise à jour du statut";
        disp("Erreur lors de l'envoi des données.");
    else
        app.Status.Text = 'Réponse inattendue Arduino.';  % Mise à jour du statut
        disp('Réponse inattendue Arduino.');
    end
    
catch ME
    % Gestion des erreurs
    app.Status.Text = "Erreur lors de l\'envoi des paramètres.';  % Mise à jour du statut";
    uialert(app.UIFigure, sprintf("Choisissez un autre port"), 'Erreur Communication');
    disp(['Erreur : ', ME.message]);  % Affichage du message d'erreur
end


        end

        % Callback function: not associated with a component
        function ConnecterButtonPushed(app, event)
            disp(app.preport);
            disp(app.port.Value);
            if ~strcmp(app.preport, app.port.Value)
            try
                disp(app.preport);

                % Initialize the serial object for communication with Arduino
                app.serialObj = serialport(app.port.Value, 115200);
                configureTerminator(app.serialObj, "LF"); % Configure the serial object to use LF terminator
                app.Status.Text = 'Communication Série initialisée';
                app.preport = app.port.Value;
                
               
                if app.Status.Text == "Communication Série initialisée"
                    app.Lamp.Color = 'g';
                end
                %app.TimerExecutionMode = 'fixedRate';
                %app.Timer.Period = 1; % Mettre à jour toutes les secondes
                %app.Timer.TimerFcn = @(~,~) updateTime(app); % Callback
            catch
                app.Status.Text = "Échec de l'initialisation";
                app.preport = app.port.Value;
                app.Lamp.Color = 'r';
           
            end
            end
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Température  à T3')
            xlabel(app.UIAxes, 'Temps[s]')
            ylabel(app.UIAxes, 'Température[°C]')
            zlabel(app.UIAxes, 'Z')
            app.RgulateurPIDFPanel = uipanel(app.GridLayout);
            app.RgulateurPIDFPanel.Title = 'Régulateur PIDF';
            app.InfoTiButtonPushed = uibutton(app.RgulateurPIDFPanel, 'push');
            app.InfoTiButtonPushed.Tooltip = {'Entrez ici la valeur du gain intégral de votre PIDF'};
            app.InfoTiButtonPushed.Text = 'ⓘ';
            app.InfoKcButtonPushed = uibutton(app.RgulateurPIDFPanel, 'push');
            app.InfoKcButtonPushed.Tooltip = {'Entrez ici la valeur du gain proportionnel de votre PIDF'};
            app.InfoKcButtonPushed.Text = 'ⓘ';
            app.KcEditFieldLabel = uilabel(app.RgulateurPIDFPanel);
            app.KcEditFieldLabel.Text = 'Kc';
            app.KcField = uieditfield(app.RgulateurPIDFPanel, 'numeric');
            app.TiEditFieldLabel = uilabel(app.RgulateurPIDFPanel);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiField = uieditfield(app.RgulateurPIDFPanel, 'numeric');
            app.InfoTdButtonPushed = uibutton(app.RgulateurPIDFPanel, 'push');
            app.InfoTdButtonPushed.Tooltip = {'Entrez ici la valeur de la constante de temps de la partie dérivatrice de votre PIDF'};
            app.InfoTdButtonPushed.Text = 'ⓘ';
            app.InfoTfButtonPushed = uibutton(app.RgulateurPIDFPanel, 'push');
            app.InfoTfButtonPushed.Tooltip = {'Entrez ici la valeur de la constante de temps de la partie filtre de votre PIDF'};
            app.InfoTfButtonPushed.Text = 'ⓘ';
            app.TfLabel = uilabel(app.RgulateurPIDFPanel);
            app.TfLabel.Text = 'Tf';
            app.TfField = uieditfield(app.RgulateurPIDFPanel, 'numeric');
            app.TfField.ValueDisplayFormat = '%11.40g';
            app.TdLabel = uilabel(app.RgulateurPIDFPanel);
            app.TdLabel.Text = 'Td';
            app.TdField = uieditfield(app.RgulateurPIDFPanel, 'numeric');
            app.Label = uilabel(app.RgulateurPIDFPanel);
            app.Label.Tooltip = {'a;ldmqamo;ams'};
            app.Label.Text = 'ⓘ';
            app.DmarrerButton = uibutton(app.GridLayout, 'push');
            app.DmarrerButton.Text = 'Démarrer';
            app.ArrterButton = uibutton(app.GridLayout, 'push');
            app.ArrterButton.Text = 'Arrêter';
            app.ChargerParametresButton = uibutton(app.GridLayout, 'push');
            app.ChargerParametresButton.Text = 'Charger Paramètres';
            app.ConsignedetempratureCPanel = uipanel(app.GridLayout);
            app.ConsignedetempratureCPanel.Title = 'Consigne de température [°C] ';
            app.ConsigneEditFieldLabel = uilabel(app.ConsignedetempratureCPanel);
            app.ConsigneEditFieldLabel.Text = 'Consigne';
            app.ConsigneField = uieditfield(app.ConsignedetempratureCPanel, 'numeric');
            app.ConsigneField.Limits = [20 30];
            app.ConsigneField.Value = 25;
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
            app.TimerLabel = uilabel(app.GridLayout);
            app.TimerLabel.Text = '';
            app.TimesLabel = uilabel(app.GridLayout);
            app.TimesLabel.Text = 'Time(s)';
            app.Status = uilabel(app.GridLayout);
            app.Status.Text = 'En attente .......';
            app.Connecter = uibutton(app.GridLayout, 'push');
            app.Connecter.Text = 'Connecter à l''Arduino';
            app.Lamp = uilamp(app.GridLayout);
            app.port = uidropdown(app.GridLayout);
            app.port.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.port.Value = 'COM1';
            app.PortUSBDropDownLabel = uilabel(app.GridLayout);
            app.PortUSBDropDownLabel.Text = 'Port USB';
            app.Lamp_2 = uilamp(app.GridLayout);
            app.SauvgarderdonnesButton = uibutton(app.GridLayout, 'state');
            app.SauvgarderdonnesButton.Text = 'Sauvgarder données';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application_prototype_TEST

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end