classdef application_prototype_TEST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Status                      matlab.ui.control.Label
        TimesLabel                  matlab.ui.control.Label
        TimerLabel                  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        ConsignedetempratureCPanel  matlab.ui.container.Panel
        ConsigneField               matlab.ui.control.NumericEditField
        ConsigneEditFieldLabel      matlab.ui.control.Label
        ChargerParametresButton     matlab.ui.control.Button
        ArrterButton                matlab.ui.control.Button
        DmarrerButton               matlab.ui.control.Button
        RgulateurPIDFPanel          matlab.ui.container.Panel
        TdField                     matlab.ui.control.NumericEditField
        TdLabel                     matlab.ui.control.Label
        TfField                     matlab.ui.control.NumericEditField
        TfLabel                     matlab.ui.control.Label
        InfoTfButtonPushed          matlab.ui.control.Button
        InfoTdButtonPushed          matlab.ui.control.Button
        TiField                     matlab.ui.control.NumericEditField
        TiEditFieldLabel            matlab.ui.control.Label
        KcField                     matlab.ui.control.NumericEditField
        KcEditFieldLabel            matlab.ui.control.Label
        InfoKcButtonPushed          matlab.ui.control.Button
        InfoTiButtonPushed          matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %serialObj        serialport
        running          logical = false;
        serialObj; % Description
    end
    
    methods (Access = public)
        
        function DmarrerButtonPushedCallback(app, event)
            DmarrerButtonPushed(app, event);
       end
        % Callback function for the STOP Button press
        function ArrterButtonPushedCallback(app, event)
            ArrterButtonPushed(app, event);
        end
         % Callback function for the STOP Button press
        function ChargerParametresButtonPushedCallback(app, event)
           ChargerParametresButtonPushed(app, event);
        end
        % Callback function for the UIAxesButtonDown event
        function UIAxesButtonDownCallback(app, event)
            UIAxesButtonDown(app, event);
        end
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        try
                % Initialize the serial object for communication with Arduino
                app.serialObj = serialport('COM4', 115200);
                configureTerminator(app.serialObj, "LF"); % Configure the serial object to use LF terminator
                app.Status.Text = 'Status: Connected to Arduino';
            catch
                app.Status.Text = 'Status: Failed to connect';
            end
        end

        % Button pushed function: DmarrerButton
        function DmarrerButtonPushed(app, event)

             % Créer une connexion série à l'Arduino sur le port COMx
                %s = serialport('COM3', 115200);
                %fopen(s);
                %if isempty(app.serialObj)
                %warning("Port série non initialisé.");
                %return;
                %end
                % Modifier le texte du label pour indiquer que l'on attend le signal
                %app.Status.Text = 'Envoi du signal START à Arduino...';
                
                % Envoi d'un signal à l'Arduino pour démarrer l'asservissement
                %writeline(app.serialObj, 'START');
                
                % Attendre une confirmation de l'Arduino
                %response = fscanf(s);  % Lire la réponse de l'Arduino
                %response = readline(app.serialObj);
                %fprintf("Réponse Arduino: %s\n", response);
                %if contains(response, "Démarrage de l\'asservissement")  % Vérifier si l'Arduino a démarré(c'est ce message que je dois mettre dans arduino)
                    %app.Status.Text = 'Asservissement démarré !';  % Mise à jour du statut
                %else
                    %app.Status.Text = 'Erreur de communication avec Arduino.';
                %end
                
                % Fermer la communication série après l'envoi
                %clear s;
                %delete(s);
                try
        if exist('app.serialObj', 'var') && isvalid(app.serialObj)
            writeline(app.serialObj, 'START', "char");
            app.Status.Text = 'Status: System started';
            app.running = true; % Mark the system as running
            
            % Créer un timer pour réinitialiser le statut après 2 secondes
            t = timer('StartDelay', 2, 'TimerFcn', @(~,~) set(app.Status, 'Text', ''));
            start(t);
        else
            app.Status.Text = 'Status: No connection';
        end
    catch
        app.Status.Text = 'Status: Failed to start system';
                end
                
            
        end

        % Button pushed function: ArrterButton
        function ArrterButtonPushed(app, event)
              % Créer une connexion série à l'Arduino sur le port COMx
             %s = serialport('COM3', 115200);
             %fopen(s)
             %if isempty(app.serialObj)
                %return;
            %end
            % Envoyer la commande 'STOP' à l'Arduino
             %writeline(app.serialObj, 'STOP');
    
            % Attendre la réponse de l'Arduino
            %response = fscanf(s);  % Lire la réponse de l'Arduino
           % writeline(app.serialObj, "STOP");
            %app.running = false;
            % Vérifier la réponse et mettre à jour le statut
            %if contains(response, "Arrêt de l\'asservissement")
              %app.Status.Text = 'Asservissement arrêté !';
            %else
              %app.Status.Text = 'Erreur de communication avec Arduino.';
            %end
            %clear s;  % Ferme et libère le port série
            %delete(s);
    try
        if exist('app.serialObj', 'var') && isvalid(app.serialObj)
            writeline(app.serialObj, 'STOP', "char");
            pause(0.5);
            response = readline(app.serialObj);
            if contains(response, "Arrêt de l'asservissement")
                  app.running = false; % Mark the system as stopped
                  app.Status.Text = 'Asservissement arrêté !';
                  % Créer un timer pour réinitialiser le statut après 2 secondes
                  t = timer('StartDelay', 2, 'TimerFcn', @(~,~) set(app.Status, 'Text', ''));
                  start(t);
            else
                 app.Status.Text = 'Erreur de communication avec Arduino.';
                 % Créer un timer pour réinitialiser le statut après 2 secondes
                  t = timer('StartDelay', 2, 'TimerFcn', @(~,~) set(app.Status, 'Text', ''));
                  start(t);
            end
            
        else
            app.Status.Text = 'Status: No connection';
        end
    catch
        app.Status.Text = 'Status: Failed to stop system';
    end
        end

        % Button pushed function: ChargerParametresButton
        function ChargerParametresButtonPushed(app, event)
      try
        if isvalid(app.serialObj)
            params = {
                {'SET_Ti', app.TiField.Value},
                {'SET_Kc', app.KcField.Value},
                {'SET_Consigne', app.ConsigneField.Value},
                {'SET_Td', app.TdField.Value},
                {'SET_Tf', app.TfField.Value}
            };
        
            for i = 1:length(params)
                command = sprintf('%s %f', params{i}{1}, params{i}{2});
                disp(command);
                writeline(app.serialObj, command);
                
                pause(0.2); % Attente pour éviter les collisions
                response = readline(app.serialObj);
                
                if ~strcmp(response, 'OK')
                    app.Status.Text = sprintf('Status: Error sending %s', params{i}{1});
                    
                    % Créer un timer pour réinitialiser le statut après 2 secondes
                    t = timer('StartDelay', 2, 'TimerFcn', @(~,~) set(app.Status, 'Text', ''));
                    start(t);
                    return;
                end
            end
            app.Status.Text = 'Status: All PID parameters set';
        else
            app.Status.Text = 'Status: No connection';
        end
    catch
        app.Status.Text = 'Status: Failed to send data';
    end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
                  % Button down function: UIAxes
            if ~app.running
                return; % Exit if the system is not running
            end
            % Initialisation des variables
            time_data = [];  % Tableau pour stocker les valeurs de temps
            temp_data = [];  % Tableau pour stocker les valeurs de température
            app.Status.Text = 'Status: Plotting Temperature...';
            % Connexion série avec Arduino (Assurez-vous que le port COM est correct)
            %s = serialport('COM3', 115200);
            %fopen(s);
            % Configuration du graphique
            figure;
            h = plot(app.UIAxes, [], []);
            xlabel('Temps (s)');
            ylabel('Température (°C)');
            title('Température en Temps Réel');
            grid on;
        
            % Boucle principale pour lire les données
            while app.running
                % Lire la température depuis l'Arduino (adaptez la commande à votre besoin)
                writeline(app.serialObj, 'GET_TEMP');  % Exemple de commande pour demander la température à Arduino
                temp = read(app.serialObj, 1,'uint8');  % Lire la réponse de l'Arduino (attendez une valeur numérique)
        
                % Récupérer le temps actuel (en secondes ou en utilisant le champ "time")
                current_time_str = seconds(datetime('now'));
                % Mettre à jour le label TimerLabel avec le temps actuel
                app.TimesLabel.Text = current_time_str;  % Afficher l'heure actuelle dans le label
        
                % Ajouter la nouvelle valeur de température et de temps aux tableaux
                time_data = [time_data, str2double(current_time_str)];  % Ajouter le temps actuel sous forme de nombre
                temp_data = [temp_data, temp];          % Ajouter la température mesurée
                
                % Mettre à jour le graphique avec les nouvelles données
                set(h, 'XData', time_data, 'YData', temp_data);  % Mettre à jour les données du graphique
                
                % Re-dessiner le graphique
                drawnow;  % Actualise le graphique en temps réel
        
                % Ajouter une petite pause pour éviter que la boucle ne s'exécute trop rapidement
                pause(1);  % Attendre 1 seconde avant de prendre une nouvelle lecture
            end
            %fclose(s);
            clear s;
            %delete(s)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Température  à T3')
            xlabel(app.UIAxes, 'Temps[s]')
            ylabel(app.UIAxes, 'Température[°C]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.RgulateurPIDFPanel = uipanel(app.GridLayout);
            app.RgulateurPIDFPanel.Title = 'Régulateur PIDF';
            app.InfoTiButtonPushed = uibutton(app.RgulateurPIDFPanel, 'push');
            app.InfoTiButtonPushed.Tooltip = {'''Entrez ici la valeur du coefficient Ti du PI'', ...'; '''Enter here the value of the Ti coefficient of the PI controller.'' ...'};
            app.InfoTiButtonPushed.Text = 'ⓘ';
            app.InfoKcButtonPushed = uibutton(app.RgulateurPIDFPanel, 'push');
            app.InfoKcButtonPushed.Tooltip = {'''Entrez ici la valeur du coefficient Kc du PI'', ...'; '''Enter here the value of the Kc coefficient of the PI controller.'' ...'};
            app.InfoKcButtonPushed.Text = 'ⓘ';
            app.KcEditFieldLabel = uilabel(app.RgulateurPIDFPanel);
            app.KcEditFieldLabel.Text = 'Kc';
            app.KcField = uieditfield(app.RgulateurPIDFPanel, 'numeric');
            app.TiEditFieldLabel = uilabel(app.RgulateurPIDFPanel);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiField = uieditfield(app.RgulateurPIDFPanel, 'numeric');
            app.InfoTdButtonPushed = uibutton(app.RgulateurPIDFPanel, 'push');
            app.InfoTdButtonPushed.Tooltip = {'''Entrez ici la valeur du coefficient Ti du PI'', ...'; '''Enter here the value of the Ti coefficient of the PI controller.'' ...'};
            app.InfoTdButtonPushed.Text = 'ⓘ';
            app.InfoTfButtonPushed = uibutton(app.RgulateurPIDFPanel, 'push');
            app.InfoTfButtonPushed.Tooltip = {'''Entrez ici la valeur du coefficient Kc du PI'', ...'; '''Enter here the value of the Kc coefficient of the PI controller.'' ...'};
            app.InfoTfButtonPushed.Text = 'ⓘ';
            app.TfLabel = uilabel(app.RgulateurPIDFPanel);
            app.TfLabel.Text = 'Tf';
            app.TfField = uieditfield(app.RgulateurPIDFPanel, 'numeric');
            app.TdLabel = uilabel(app.RgulateurPIDFPanel);
            app.TdLabel.Text = 'Td';
            app.TdField = uieditfield(app.RgulateurPIDFPanel, 'numeric');
            app.DmarrerButton = uibutton(app.GridLayout, 'push');
            app.DmarrerButton.ButtonPushedFcn = createCallbackFcn(app, @DmarrerButtonPushed, true);
            app.DmarrerButton.Text = 'Démarrer';
            app.ArrterButton = uibutton(app.GridLayout, 'push');
            app.ArrterButton.ButtonPushedFcn = createCallbackFcn(app, @ArrterButtonPushed, true);
            app.ArrterButton.Text = 'Arrêter';
            app.ChargerParametresButton = uibutton(app.GridLayout, 'push');
            app.ChargerParametresButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerParametresButtonPushed, true);
            app.ChargerParametresButton.Text = 'Charger Paramètres';
            app.ConsignedetempratureCPanel = uipanel(app.GridLayout);
            app.ConsignedetempratureCPanel.Title = 'Consigne de température [°C] ';
            app.ConsigneEditFieldLabel = uilabel(app.ConsignedetempratureCPanel);
            app.ConsigneEditFieldLabel.Text = 'Consigne';
            app.ConsigneField = uieditfield(app.ConsignedetempratureCPanel, 'numeric');
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, '89d9531e-83be-4734-95d5-eb811c90cad8_removalai_preview.png');
            app.TimerLabel = uilabel(app.GridLayout);
            app.TimerLabel.Text = '';
            app.TimesLabel = uilabel(app.GridLayout);
            app.TimesLabel.Text = 'Time(s)';
            app.Status = uilabel(app.GridLayout);
            app.Status.Text = 'En attente .......';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application_prototype_TEST

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end