classdef APP_simulink_2023 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        save_file_name                  matlab.ui.control.EditField
        NomdufichierLabel               matlab.ui.control.Label
        SauvegarderButton               matlab.ui.control.Button
        ParamtrespardfautButton         matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PrincipaleTab                   matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
        UI_perturb                      matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        TensionsauxthermistancesTab     matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        UIAxesV                         matlab.ui.control.UIAxes
        RsistancesdesthermistancesTab   matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        UIAxesR                         matlab.ui.control.UIAxes
        EstimationdeT3Tab               matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        UIAxesESTIMT3                   matlab.ui.control.UIAxes
        SortiesADCTab                   matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        UIAxesADC                       matlab.ui.control.UIAxes
        AutressignauxTab                matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        UIAxesTEC_out                   matlab.ui.control.UIAxes
        UIAxesTEC_in                    matlab.ui.control.UIAxes
        UIAxesAMP_in                    matlab.ui.control.UIAxes
        UIAxesPWM                       matlab.ui.control.UIAxes
        ParamtresavancsTab              matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        lectroniqueLabel                matlab.ui.control.Label
        PointdoprationCPanel            matlab.ui.container.Panel
        GridLayout4_2                   matlab.ui.container.GridLayout
        temp_init                       matlab.ui.control.NumericEditField
        SimulationduPWMPanel            matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        Switch                          matlab.ui.control.Switch
        CoefficientsdeSteinhartHartPanel  matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        CoefficientspourRsistanceTempratureLabel  matlab.ui.control.Label
        CoefficientspourTempratureRsistanceLabel  matlab.ui.control.Label
        sth_rfixe                       matlab.ui.control.NumericEditField
        R25CLabel                       matlab.ui.control.Label
        sth_d2                          matlab.ui.control.NumericEditField
        D_2EditFieldLabel               matlab.ui.control.Label
        sth_c2                          matlab.ui.control.NumericEditField
        C_2EditFieldLabel               matlab.ui.control.Label
        sth_b2                          matlab.ui.control.NumericEditField
        B_2EditFieldLabel               matlab.ui.control.Label
        sth_a2                          matlab.ui.control.NumericEditField
        A_2EditFieldLabel               matlab.ui.control.Label
        sth_d                           matlab.ui.control.NumericEditField
        DEditFieldLabel                 matlab.ui.control.Label
        sth_c                           matlab.ui.control.NumericEditField
        CEditFieldLabel                 matlab.ui.control.Label
        sth_b                           matlab.ui.control.NumericEditField
        BEditFieldLabel                 matlab.ui.control.Label
        sth_a                           matlab.ui.control.NumericEditField
        AEditField_2Label               matlab.ui.control.Label
        BitADCLabel                     matlab.ui.control.Label
        bit_adc                         matlab.ui.control.DropDown
        BitDACLabel_2                   matlab.ui.control.Label
        bit_dac                         matlab.ui.control.DropDown
        TabGroup2                       matlab.ui.container.TabGroup
        PWMetAmpliTab                   matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        gain_amp                        matlab.ui.control.NumericEditField
        GainLabel_2                     matlab.ui.control.Label
        AmplificateurdepuissanceLabel_2  matlab.ui.control.Label
        ParamtreduPWMLabel              matlab.ui.control.Label
        pwm_freq                        matlab.ui.control.NumericEditField
        FrquenceduPWMLabel              matlab.ui.control.Label
        FiltrepostPWMTab                matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        FiltrePWM_DEN                   matlab.ui.control.EditField
        DnominateurenpuissancesdcroissantesdesexxxxxLabel  matlab.ui.control.Label
        FiltrePWM_NUM                   matlab.ui.control.EditField
        NumrateurenpuissancesdcroissantesdesexxxxxLabel  matlab.ui.control.Label
        FonctiondetransfertdufiltrepostPWMLabel  matlab.ui.control.Label
        CircuitsdemesureTab             matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        mes_offset_in_3                 matlab.ui.control.NumericEditField
        offset_T1Label_3                matlab.ui.control.Label
        mes_gain_in_4                   matlab.ui.control.NumericEditField
        GainT3Label                     matlab.ui.control.Label
        mes_gain_in_3                   matlab.ui.control.NumericEditField
        GainT1Label_2                   matlab.ui.control.Label
        mes_gain_in_2                   matlab.ui.control.NumericEditField
        GainT1Label                     matlab.ui.control.Label
        mes_offset_in_2                 matlab.ui.control.NumericEditField
        offset_T1Label_2                matlab.ui.control.Label
        mes_offset_in                   matlab.ui.control.NumericEditField
        offset_T1Label                  matlab.ui.control.Label
        ConditionnementprADCLabel       matlab.ui.control.Label
        sample_freq                     matlab.ui.control.NumericEditField
        FrquencedchantillonageLabel     matlab.ui.control.Label
        Slider_sat                      matlab.ui.control.RangeSlider
        FonctionsdetransfertdelaperturbationsurlaplaquePanel  matlab.ui.container.Panel
        GridLayout3_3                   matlab.ui.container.GridLayout
        Label_9                         matlab.ui.control.Label
        prt3                            matlab.ui.control.NumericEditField
        prt2                            matlab.ui.control.NumericEditField
        prt1                            matlab.ui.control.NumericEditField
        pct3                            matlab.ui.control.NumericEditField
        T3EditFieldLabel_2              matlab.ui.control.Label
        T2EditFieldLabel_2              matlab.ui.control.Label
        T1EditFieldLabel_9              matlab.ui.control.Label
        T1EditFieldLabel_8              matlab.ui.control.Label
        T1EditFieldLabel_7              matlab.ui.control.Label
        T1EditFieldLabel_6              matlab.ui.control.Label
        pgt1                            matlab.ui.control.NumericEditField
        pct2                            matlab.ui.control.NumericEditField
        pct1                            matlab.ui.control.NumericEditField
        pgt3                            matlab.ui.control.NumericEditField
        pgt2                            matlab.ui.control.NumericEditField
        SaturationentensiondurgulateurVLabel  matlab.ui.control.Label
        FonctiondetransfertdelaplaqueParrapportauTECPanel  matlab.ui.container.Panel
        GridLayout3_2                   matlab.ui.container.GridLayout
        Label_8                         matlab.ui.control.Label
        rt3                             matlab.ui.control.NumericEditField
        rt2                             matlab.ui.control.NumericEditField
        rt1                             matlab.ui.control.NumericEditField
        ct3                             matlab.ui.control.NumericEditField
        T3EditFieldLabel                matlab.ui.control.Label
        T2EditFieldLabel                matlab.ui.control.Label
        T1EditFieldLabel_5              matlab.ui.control.Label
        T1EditFieldLabel_4              matlab.ui.control.Label
        T1EditFieldLabel_3              matlab.ui.control.Label
        T1EditFieldLabel_2              matlab.ui.control.Label
        gt1                             matlab.ui.control.NumericEditField
        ct2                             matlab.ui.control.NumericEditField
        ct1                             matlab.ui.control.NumericEditField
        gt3                             matlab.ui.control.NumericEditField
        gt2                             matlab.ui.control.NumericEditField
        IdentificationautomatiqueTab    matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        AttentionlescolonesdoiventtreT1T2T3TempsCommandeLabel_2  matlab.ui.control.Label
        ident_pert_pow_feild            matlab.ui.control.NumericEditField
        PuissancedeperturbWEditFieldLabel  matlab.ui.control.Label
        ident_pert_end                  matlab.ui.control.NumericEditField
        FinsEditField_2Label            matlab.ui.control.Label
        ident_pert_start                matlab.ui.control.NumericEditField
        DbutsEditField_2Label           matlab.ui.control.Label
        IntervalepourlidentificationPerturbationLabel  matlab.ui.control.Label
        ImporterunerponselchelonPerturbationButton  matlab.ui.control.Button
        ImporterunerponselchelonsousformatcsvselonletamplateLabel_6  matlab.ui.control.Label
        ident_tec_end_time              matlab.ui.control.NumericEditField
        FinsEditFieldLabel              matlab.ui.control.Label
        indent_tec_start_time           matlab.ui.control.NumericEditField
        DbutsEditFieldLabel             matlab.ui.control.Label
        IntervalepourlidentificationTECLabel  matlab.ui.control.Label
        ImporterunerponselchelonsousformatcsvselonletamplateLabel_5  matlab.ui.control.Label
        ImporterunerponselchelonsousformatcsvselonletamplateLabel_4  matlab.ui.control.Label
        IdentifierButton                matlab.ui.control.Button
        ImporterunerponselchelonsousformatcsvselonletamplateLabel_3  matlab.ui.control.Label
        ImporterunerponselchelonsousformatcsvselonletamplateLabel_2  matlab.ui.control.Label
        AttentionlescolonesdoiventtreT1T2T3TempsCommandeLabel  matlab.ui.control.Label
        ImporterunerponselchelonsousformatcsvselonletamplateLabel  matlab.ui.control.Label
        ImporterunerponselchelonTECButton  matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
        ThermistancesNode               matlab.ui.container.TreeNode
        T1Node                          matlab.ui.container.TreeNode
        T2Node                          matlab.ui.container.TreeNode
        T3Node                          matlab.ui.container.TreeNode
        PerturbationsNode               matlab.ui.container.TreeNode
        PerturbationT1Node              matlab.ui.container.TreeNode
        PerturbationT2Node              matlab.ui.container.TreeNode
        PerturbationT3Node              matlab.ui.container.TreeNode
        RgulateurPIDFNode               matlab.ui.container.TreeNode
        UI_rep_pert                     matlab.ui.control.UIAxes
        UI_rep_tec                      matlab.ui.control.UIAxes
        EnattenteLabel                  matlab.ui.control.Label
        DmarrerButton                   matlab.ui.control.Button
        PerturbationPanel               matlab.ui.container.Panel
        GridLayout2_2                   matlab.ui.container.GridLayout
        Label_7                         matlab.ui.control.Label
        TempsdechangementsEditField_2   matlab.ui.control.NumericEditField
        TempsdechangementsEditField_2Label  matlab.ui.control.Label
        PuissancefinaleWEditField       matlab.ui.control.NumericEditField
        PuissancefinaleWEditFieldLabel  matlab.ui.control.Label
        PuissanceinitialeWEditField     matlab.ui.control.NumericEditField
        PuissanceinitialeLabel          matlab.ui.control.Label
        AppliquerparamtresButton        matlab.ui.control.Button
        DuredesimulationsEditField      matlab.ui.control.NumericEditField
        DuredesimulationsEditFieldLabel  matlab.ui.control.Label
        RgulateurPIDFPanel              matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        TdEditField                     matlab.ui.control.NumericEditField
        TdEditFieldLabel                matlab.ui.control.Label
        TfEditField                     matlab.ui.control.NumericEditField
        TfEditFieldLabel                matlab.ui.control.Label
        TiEditField                     matlab.ui.control.NumericEditField
        TiEditFieldLabel                matlab.ui.control.Label
        KcEditField                     matlab.ui.control.NumericEditField
        KcEditFieldLabel                matlab.ui.control.Label
        ConsignesdetempratureCPanel     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Label_2                         matlab.ui.control.Label
        TempsdechangementsEditField     matlab.ui.control.NumericEditField
        TempsdechangementsEditFieldLabel  matlab.ui.control.Label
        FinaleCEditField                matlab.ui.control.NumericEditField
        FinaleCEditFieldLabel           matlab.ui.control.Label
        InitialeCEditField              matlab.ui.control.NumericEditField
        InitialeLabel                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        %simulation; % Descriptio
        myscope;
        Ts = 5;
        precons = 25;
        matrice;
        consigne = [];
        commande;
        increment = 0;
        time;
        data;
        time2;
        data_consigne ;
        data_t1;
        data_t2 ;
        data_t3 ;
        v1;
        v2;
        v3;
        r1;
        r2;
        r3;
        adc1;
        adc2;
        adc3;
        predicted_t3;
        pwm_out;
        amp_in;
        tec_in;
        tec_out;
        pertub;
        model = "MAIN_SIMULINK_2023a_";
        model_perturb ="MAIN_SIMULINK_2023a/Puissance de perturbation";
        ident_pow;
        ident_pow_pert;
        ident_pow_pert_time;
        h1;
        TEC_gains;
        TEC_gains_V;
    end
    
    methods (Access = public)
        
        function AppliquerButtonPushedCallback(app,event)
            AppliquerButtonPushed(app,event);
        end
        function ArrterButtonPushedCallback(app, event)
            AppliquerparamtresButtonPushed(app,event);
        end
        function DmarrerButtonPushedCallback(app, event)
            DmarrerButtonPushed(app,event);
        end
        function StartStopButtonSizeChangedCallback(app, event)
        StartStopButtonSizeChanged(app, event);
        end

        function clear_axes(app) % Except identification axes
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UI_perturb);
            cla(app.UIAxesV);
            cla(app.UIAxesR);
            cla(app.UIAxesESTIMT3);
            cla(app.UIAxesADC);
            cla(app.UIAxesTEC_out);
            cla(app.UIAxesTEC_in);
            cla(app.UIAxesAMP_in);
            cla(app.UIAxesPWM);
        end

        function assign_all_to_workspace(app)
            % Cette fonction assigne toutes les variables nécessaire pour

            %% Consigne:  Température initiale, consigine init, consigne finale, step_time
            assignin('base', 'T_init', app.temp_init.Value);
            assignin('base', 'consigne_step_time', app.TempsdechangementsEditField.Value)
            assignin('base', 'consigne_init', app.InitialeCEditField.Value)
            assignin('base', 'consigne_finale', app.FinaleCEditField.Value)

            %% Régulateur: kc, bloc TiZ, bloc DFZ, u_op, sturation
                % Calcul des ft pour le régulateur
                DF      = tf([app.TdEditField.Value,1],[app.TfEditField.Value,1]);
                TI      = tf(1,[app.TiEditField.Value,1]);
                DFz     = c2d(DF, 1/app.sample_freq.Value, "tustin");
                Tiz     = c2d(TI, 1/app.sample_freq.Value, "zoh");
            assignin('base', 'kc', app.KcEditField.Value );
            assignin('base', 'DFz', DFz );
            assignin('base', 'Tiz', Tiz);
            %assignin('base', 'u_op' app.u_op.Value);
            assignin('base', 'sat_c', app.Slider_sat.Value(2));
            assignin('base', 'sat_f', app.Slider_sat.Value(1));

            %% PWM et circuit associé: Offset_pwm, 1/f du pwm, gain_pwm, #bit DCA (?)
            assignin('base', 'dac_bit', str2double(app.bit_dac.Value) );
            assignin('base', 'pwm_f', app.pwm_freq.Value );

            %% TEC: Lookup table gain
            assignin('base', 'tec_gains', str2double(strsplit(app.TEC_gains.Value, ',')));
            assignin('base', 'tec_tension', str2double(strsplit(app.TEC_gains_V.Value, ',')));

            %% Plaque: T1, T2, T3
                % Build the TFS
                    T1 = tf([app.gt1.Value], [app.ct1.Value, 1]);
                    T1.IODelay = app.rt1.Value;
                    T2 = tf([app.gt2.Value], [app.ct2.Value, 1]);
                    T2.IODelay = app.rt2.Value;
                    T3 = tf([app.gt3.Value], [app.ct3.Value, 1]);
                    T3.IODelay = app.rt3.Value;
            assignin('base', 'gp_1', T1);
            assignin('base', 'gp_2', T2);
            assignin('base', 'gp_3', T3);

            %% Perturb plaque: PT1, PT2, PT3, pertub init, pertub finale, step time
                % Build the TFS 
                    PT1 = tf([app.pgt1.Value], [app.pct1.Value, 1]);
                    PT1.IODelay = app.prt1.Value;
                    PT2 = tf([app.pgt2.Value], [app.pct2.Value, 1]);
                    PT2.IODelay = app.prt2.Value;
                    PT3 = tf([app.pgt3.Value], [app.pct3.Value, 1]);
                    PT3.IODelay = app.prt3.Value;
            assignin('base', 'gpp_1', PT1);
            assignin('base', 'gpp_2', PT2);
            assignin('base', 'gpp_3', PT3);
            assignin('base', 'pert_step_time', app.TempsdechangementsEditField_2.Value)
            assignin('base', 'pert_init', app.PuissanceinitialeWEditField.Value)
            assignin('base', 'pert_finale', app.PuissancefinaleWEditField.Value)

            %% Circuit de mesure: coef de SH-H (T2R et V2T), R_25, offset1, gain1, #adc_bit, gain2, gain3, offset2
            assignin('base', 'R_fixe', app.sth_rfixe.Value)
            assignin('base', 'A', app.sth_a.Value);
            assignin('base', 'B', app.sth_b.Value);
            assignin('base', 'C', app.sth_c.Value);
            assignin('base', 'D', app.sth_d.Value);
            assignin('base', 'A_2', app.sth_a2.Value);
            assignin('base', 'B_2', app.sth_b2.Value);
            assignin('base', 'C_2', app.sth_c2.Value);
            assignin('base', 'D_2', app.sth_d2.Value);
            assignin('base', 'adc_bit', str2double(app.bit_adc.Value))
             %% Électronique
            %% Circuits de mesures
            assignin('base','offset1', app.mes_offset_in.Value);
            assignin('base','offset2',app.mes_offset_in_3.Value);
            assignin('base','offset3',app.mes_offset_in_2.Value);
            assignin('base','gain1',app.mes_gain_in_2.Value);
            assignin('base','gain2',app.mes_gain_in_3.Value);
            assignin('base', 'gain3',app.mes_gain_in_4.Value);
            %% Filtre post-PWM
            filt_PWM = tf(str2num(app.FiltrePWM_NUM.Value), str2num(app.FiltrePWM_DEN.Value));
            assignin('base', 'filt_pwm', filt_PWM);
            assignin('base', 'gain_amp', app.gain_amp.Value);
            %% PWM
            assignin('base', 'freq_pwm', app.pwm_freq.Value);
            %% Estimation T3: ZT3
                ZT3 = c2d(T3, 1/app.sample_freq.Value, 'zoh');
            assignin('base', 'Z_T3', ZT3);

            %% Autre paramètres: f
            assignin('base', 'f', app.sample_freq.Value);
 
        end
   
        function power = convert_to_pow(app, tension)
            % Récupérer la lock-up table des gain tec et faire la
            % conversion pour tous les tensions dans le vecteur

            % Table de correspondance
            gain_tec    = str2double(strsplit(app.TEC_gains.Value, ','));
            tension_tec = str2double(strsplit(app.TEC_gains_V.Value, ','));
            
            % Interpolation pour toutes les valeurs du vecteur
            power = interp1(tension_tec, gain_tec, tension, 'linear', 'extrap');
        end
        function bool_list = built_option_list(app)
            % Retourne une liste de bool qui dicte quel ft on identifie
                option_to_check = {app.T1Node.Text, app.T2Node.Text,...
                    app.T3Node.Text, app.PerturbationT1Node.Text, app.PerturbationT2Node.Text, ...
                    app.PerturbationT3Node.Text, app.RgulateurPIDFNode.Text};
               
                checked = app.Tree.CheckedNodes; 
                checked = {checked.Text};

                bool_list = false(1, length(option_to_check));
                for i = 1:length(option_to_check)
                    bool_list(i) = any(strcmp(option_to_check{i}, checked));
                end   
        end

        function reset_to_defaults(app)
            %% Consigne
            app.temp_init.Value = 25;  
            app.TempsdechangementsEditField.Value = 500;  
            app.InitialeCEditField.Value = 30;  
            app.FinaleCEditField.Value = 20; 
            app.DuredesimulationsEditField.Value = 1000;
            set_param(app.model,"StopTime",num2str(app.DuredesimulationsEditField.Value));
        
            %% Régulateur
            app.KcEditField.Value = 0.155; 
            app.TdEditField.Value = 13;  
            app.TfEditField.Value = 4.3328;  
            app.TiEditField.Value = 145;  
            app.sample_freq.Value = 0.2; 
            app.Slider_sat.Value = [-1, 1]; 
        
            %% PWM et DAC
            app.bit_dac.Value = '10';
            app.pwm_freq.Value = 1000;  % À iplémenter dans le simulink
            app.gain_amp.Value = 1;
        
            %% TEC
            %app.TEC_gains.Value = '1.12, 1.62, 1.865, 2.1, 1.5, 1.72, 2.25, 2.22'; 
            %old 
            app.TEC_gains.Value = '1.16, 1.60, 1.79, 2.1, 1.5, 1.715, 2.19, 2.18';
            app.TEC_gains_V.Value = '-0.8, -0.6, -0.4, -0.2, 0.1, 0.2, 0.4, 0.6'; 
        
            %% Plaque : Gp
            app.gt1.Value = 12.202; app.ct1.Value = 104.685;    app.rt1.Value = 0; 
            app.gt2.Value = 0.805;  app.ct2.Value = 36.237;     app.rt2.Value = 5;
            app.gt3.Value = 0.856;  app.ct3.Value = 7.192;      app.rt3.Value = 15;
        
            %% Perturbations : Gpp
            app.pgt1.Value = 4.076;     app.pct1.Value = 128.015;   app.prt1.Value = 0;  
            app.pgt2.Value = 3.452;     app.pct2.Value = 117.484;   app.prt2.Value = 0;
            app.pgt3.Value = 2.891;     app.pct3.Value = 128.015;   app.prt3.Value = 10;
        
            app.TempsdechangementsEditField_2.Value = 0;  
            app.PuissanceinitialeWEditField.Value = 0;  
            app.PuissancefinaleWEditField.Value = 0; 
        
            %% Circuit de mesure
            app.sth_rfixe.Value = 10000;  
            app.sth_a.Value = -14.65719769; app.sth_b.Value = 4798.842; app.sth_c.Value = -115334; app.sth_d.Value = -3730535;
            app.sth_a2.Value = 0.00335401643468053; app.sth_b2.Value = 0.000256523550896126; app.sth_c2.Value = 0.00000260597012072052; app.sth_d2.Value = 6.3292612648746e-8;
        
            app.bit_adc.Value = '10';
        
            %% Électronique / gain & offset
            app.mes_offset_in.Value     = 1.52;
            app.mes_offset_in_3.Value   = 1.74;
            app.mes_offset_in_2.Value   = 1.98;
            app.mes_gain_in_2.Value     = 2.40735;
            app.mes_gain_in_3.Value     = 3.13733;
            app.mes_gain_in_4.Value     = 4.64232;
        
            %% Filtre PWM (num et den sous forme de texte)
            app.FiltrePWM_NUM.Value = "[1]";
            app.FiltrePWM_DEN.Value = "[(4e-3)^3 5*(4e-3)^2 6*4e-3 1]";
        end
        
        function uptate_identified_feild(app)
            % Récupérer identification
            gp_1    = evalin('base', 'gp_1');
            gp_2    = evalin('base', 'gp_2');
            gp_3    = evalin('base', 'gp_3');

            gpp_1   = evalin('base', 'gpp_1');
            gpp_2   = evalin('base', 'gpp_2');
            gpp_3   = evalin('base', 'gpp_3');

            Kc      = evalin('base', 'kc');
            Ti      = evalin('base', 'Ti');
            Td      = evalin('base', 'Td');
            Tf      = evalin('base', 'Tf');
            
            % Traiter pour récupérer les paramètre pertinents
            gain_t1 = gp_1.Numerator{1}(end) / gp_1.Denominator{1}(2);
                ct_t1 = gp_1.Denominator{1}(1) / gp_1.Denominator{1}(2);
            gain_t2 = gp_2.Numerator{1}(end) / gp_2.Denominator{1}(2);
                ct_t2 = gp_2.Denominator{1}(1) / gp_2.Denominator{1}(2);
            gain_t3 = gp_3.Numerator{1}(end) / gp_3.Denominator{1}(2);
                ct_t3 = gp_3.Denominator{1}(1) / gp_3.Denominator{1}(2);

            gain_pert1 = gpp_1.Numerator{1}(end) / gpp_1.Denominator{1}(2);
                ct_pert1 = gpp_1.Denominator{1}(1) / gpp_1.Denominator{1}(2);
            gain_pert2 = gpp_2.Numerator{1}(end) / gpp_2.Denominator{1}(2);
                ct_pert2 = gpp_2.Denominator{1}(1) / gpp_2.Denominator{1}(2);
            gain_pert3 = gpp_3.Numerator{1}(end) / gpp_3.Denominator{1}(2);
                ct_pert3 = gpp_3.Denominator{1}(1) / gpp_3.Denominator{1}(2);
            
            % Mettre à jour les feil
            app.gt1.Value = gain_t1;
                app.ct1.Value = ct_t1;
            app.gt2.Value = gain_t2;
                app.ct2.Value = ct_t2;
            app.gt3.Value = gain_t3;
                app.ct3.Value = ct_t3;

            app.pgt1.Value = gain_pert1;
                app.pct1.Value = ct_pert1;
            app.pgt2.Value = gain_pert2;
                app.pct2.Value = ct_pert2;
            app.pgt3.Value = gain_pert3;
                app.pct3.Value = ct_pert3;

            app.KcEditField.Value = Kc;
            app.TiEditField.Value = Ti;
            app.TdEditField.Value = Td;
            app.TfEditField.Value = Tf;
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        load_system(app.model);
        reset_to_defaults(app);
        assign_all_to_workspace(app);
        SwitchValueChanged(app);
        end

        % Button pushed function: DmarrerButton
        function DmarrerButtonPushed(app, event)
            % Démarrer la simulation Simulink
            set_param(app.model, 'SimulationCommand', 'update');  % Appliquer les changements
            set_param(app.model, "SimulationCommand", "start");
            app.EnattenteLabel.Text = "Simulation en cours ...";
            try
            % Mettre à jour l'étiquette pour indiquer que la simulation a démarré
            clear_axes(app);
            %app.EnattenteLabel.Text = "Veuillez patienter quelques secondes......... :)";
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Commande pour ScopeData1 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               while strcmp(get_param(app.model, 'SimulationStatus'), 'running')
                    pause(0.1);  % Petite pause pour éviter une boucle trop intensive
                end
            % Récupérer les données de ScopeData1 depuis le workspace
            ScopeData1 = evalin('base', 'ScopeData1');  % Données du scope 1
            
            app.time = ScopeData1(:, 1);  
            app.data = ScopeData1(:, 2); 
            
            % Effacer l'axe avant de redessiner la courbe pour UIAxes1
            plot(app.UIAxes, app.time, app.data, 'DisplayName', 'Signal ScopeData1');
            title(app.UIAxes, 'Commande');
            xlabel(app.UIAxes, 'Temps');
            ylabel(app.UIAxes, 'u [v]');
            grid(app.UIAxes, 'on');
            
            % drawnow;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Commande pour MT1T2T3C %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Récupérer les données de MT1T2T3C depuis le workspace
            MT1T2T3C = evalin('base', 'MT1T2T3C');  % Données du scope 2
            % Extraction des données de temps et des signaux pour MT1T2T3C
            app.time2           = MT1T2T3C(:, 1);     % Première colonne = Temps
            app.data_consigne   = MT1T2T3C(:, 2);   % Deuxième colonne = Signal 1
            app.data_t1         = MT1T2T3C(:, 3);   % Troisième colonne = Signal 2
            app.data_t2         = MT1T2T3C(:, 4);   % Quatrième colonne = Signal 3
            app.data_t3         = MT1T2T3C(:, 5);   % Cinquième colonne = Signal 4
                
            
            % Effacer l'axe avant de redessiner la courbe pour UIAxes2  % 'reset' réinitialise l'axe complètement
            hold(app.UIAxes2, 'on');  % Garder le même graphique pour ajouter les courbes suivantes
            % Tracer les 4 signaux de MT1T2T3C sur l'UIAxes2
            plot(app.UIAxes2, app.time2, app.data_consigne, 'DisplayName', 'Consigne(r)');
            plot(app.UIAxes2, app.time2, app.data_t1, 'DisplayName', 'T1');
            plot(app.UIAxes2, app.time2, app.data_t2, 'DisplayName', 'T2');
            plot(app.UIAxes2, app.time2, app.data_t3, 'DisplayName', 'T3');
            
            % Ajouter une légende pour identifier les signaux
            legend(app.UIAxes2);
            
            % Ajouter un titre et des labels aux axes de UIAxes2
            title(app.UIAxes2, 'Signaux (MT1T2T3C)');
            xlabel(app.UIAxes2, 'Temps');
            ylabel(app.UIAxes2, 'Amplitude');
            grid(app.UIAxes2, 'on');
            
            % Relâcher le maintien de la courbe pour d'autres tracés si nécessaire
            hold(app.UIAxes2, 'off');
            
            % Forcer le rafraîchissement de l'interface graphique
            % drawnow;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Graph des Tension %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            VT1T2T3     = evalin('base', 'VT1T2T3');
            vtime       = VT1T2T3(:, 1);    % Première colonne  = Temps
            app.v1      = VT1T2T3(:, 2);    % Deuxième colonne  = V1
            app.v2      = VT1T2T3(:, 3);    % Troisième colonne = V2
            app.v3      = VT1T2T3(:, 4);    % Quatrième colonne = V3

            hold(app.UIAxesV, 'on');
            plot(app.UIAxesV, vtime, app.v1, 'DisplayName', 'V1');
            plot(app.UIAxesV, vtime, app.v2, 'DisplayName', 'V2');
            plot(app.UIAxesV, vtime, app.v3, 'DisplayName', 'V3');
            hold(app.UIAxesV, 'off');
            legend(app.UIAxesV);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Graph de la perturbation %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            perturb_scope   = evalin('base', 'perturb_scope');
            pert_time       = perturb_scope(:, 1);    % Première colonne  = Temps
            app.pertub      = perturb_scope(:, 2);    % Deuxième colonne  = V1
            
            plot(app.UI_perturb, pert_time, app.pertub, 'DisplayName', 'Puissance de perturbation');
            legend(app.UIAxesV);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Graph des Résistance %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            RT1T2T3     = evalin('base', 'RT1T2T3');
            rtime       = RT1T2T3(:, 1);    % Première colonne  = Temps
            app.r1      = RT1T2T3(:, 2);    % Deuxième colonne  = R1
            app.r2      = RT1T2T3(:, 3);    % Troisième colonne = R2
            app.r3      = RT1T2T3(:, 4);    % Quatrième colonne = R3

            hold(app.UIAxesR, 'on');
            plot(app.UIAxesR, rtime, app.r1, 'DisplayName', 'R1');
            plot(app.UIAxesR, rtime, app.r2, 'DisplayName', 'R2');
            plot(app.UIAxesR, rtime, app.r3, 'DisplayName', 'R3');
            hold(app.UIAxesR, 'off');
            legend(app.UIAxesR);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Graph de l'estimation %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            PT3                 = evalin('base', 'PT3');
            pt3time             = PT3(:, 1);    % Première colonne  = Temps
            app.predicted_t3    = PT3(:, 2);    % Deuxième colonne  = T3_prédit
            %app.diff_t3         = app.data_t3 - app.predicted_t3;

            hold(app.UIAxesESTIMT3, 'on');
            plot(app.UIAxesESTIMT3, pt3time, app.predicted_t3, 'DisplayName', 'T3 estimé');
            plot(app.UIAxesESTIMT3, app.time, app.data_t3, 'DisplayName', 'T3 simulé');
            % plot(app.UIAxesADC, pt3time, app.diff_t3, 'DisplayName', 'Différence');
            hold(app.UIAxesESTIMT3, 'off');
            legend(app.UIAxesESTIMT3);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Graph de des ADC %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            ADC123      = evalin('base', 'ADC123');
            adctime     = ADC123(:, 1);        % Première colonne  = Temps
            app.adc1    = ADC123(:, 2);    % Deuxième colonne  = V1
            app.adc2    = ADC123(:, 3);    % Troisième colonne = V2
            app.adc3    = ADC123(:, 4);    % Quatrième colonne = V3

            hold(app.UIAxesADC, 'on');
            plot(app.UIAxesADC, adctime, app.adc1, 'DisplayName', 'ADC1');
            plot(app.UIAxesADC, adctime, app.adc2, 'DisplayName', 'ADC2');
            plot(app.UIAxesADC, adctime, app.adc3, 'DisplayName', 'ADC3');
            hold(app.UIAxesADC, 'off');
            legend(app.UIAxesADC);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Graph des autres signaux %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            PWM_out     = evalin('base', 'PWM_out');
            pmw_time    = PWM_out(:, 1);    % Première colonne  = Temps
            app.pwm_out = PWM_out(:, 2);   % Deuxième colonne  = pwm_out
            plot(app.UIAxesPWM, pmw_time, app.pwm_out, 'DisplayName', 'Sortie PWM');
            legend(app.UIAxesPWM);

            AMP_in      = evalin('base', 'AMP_in');
            amp_time    = AMP_in(:, 1);     % Première colonne  = Temps
            app.amp_in  = AMP_in(:, 2);     % Deuxième colonne  = amp_in
            plot(app.UIAxesAMP_in, amp_time, app.amp_in, 'DisplayName', 'Entré ampli');
            legend(app.UIAxesAMP_in);

            TEC_in      = evalin('base', 'TEC_in');
            tecin_time  = TEC_in(:, 1);     % Première colonne  = Temps
            app.tec_in  = TEC_in(:, 2);     % Deuxième colonne  = V
            plot(app.UIAxesTEC_in, tecin_time, app.tec_in, 'DisplayName', 'Entré du TEC');
            legend(app.UIAxesTEC_in);

            TEC_out     = evalin('base', 'TEC_out');
            tecout_time = TEC_out(:, 1);    % Première colonne  = Temps
            app.tec_out = TEC_out(:, 2);    % Deuxième colonne  = W
            plot(app.UIAxesTEC_out, tecout_time, app.tec_out, 'DisplayName', 'Sortie du TEC');
            legend(app.UIAxesTEC_out);
            app.EnattenteLabel.Text = "Simulation terminé.";
            catch ME
            
               uialert(app.UIFigure,"Erreur :" + ME.message,"Erreur");
            end
        end

        % Button pushed function: AppliquerparamtresButton
        function AppliquerparamtresButtonPushed(app, event)
            try
            app.EnattenteLabel.Text ="Envoi des parametres .....";
            assign_all_to_workspace(app);

            set_param(app.model,"StopTime",num2str(app.DuredesimulationsEditField.Value));
            set_param(app.model, 'SimulationCommand', 'update');
           
            pause(0.1);
            app.EnattenteLabel.Text ="Paramètres envoyés avec succès";
            catch ME
                uialert(app.UIFigure,"Erreur :" + ME.message,"Erreur");
            end

        end

        % Button pushed function: SauvegarderButton
        function SauvegarderButtonPushed(app, event)
            % fixer un intervalle de temps selon le temps d'echantillonnage:
           % app.increment = app.increment +1 ;
           % %% chaque rangee contient dans l'odre : temps - consigne - commande(tension) - t1 - t2 - t3 - t3 estimer
           % app.EnattenteLabel.Text = 'En attente de sauvegarde......';
           % indices = mod(app.time2, 5) == 0;
           % matrice_filtre = [app.time2(indices), app.data_t3(indices), app.data(indices), app.data_t1(indices), app.data_t2(indices), app.data_consigne(indices)];
           % % convertir en tableau puis leur donnée un nom
           % T = array2table(matrice_filtre);
           % T.Properties.VariableNames = ["Temps", "Consigne", "Commande", "T1", "T2", "T3"];
           % pause(0.5);
           % app.EnattenteLabel.Text = 'Données sauvegardées !';
           % %% convertir en excel file
           % %xlswrite('test.xlsx',app.matrice);
           % formatSpec = 'Test%d.xlsx';
           % writetable(T, sprintf(formatSpec,app.increment));
           % 


           if endsWith(app.save_file_name.Value, '.csv')
                file_name = app.save_file_name.Value;
            else
                file_name = sprintf('%s.csv', app.save_file_name.Value);
           end
           %puissance_tot = app.pertub + app.tec_out;
            DATA = table(app.time, app.data_t1+app.temp_init.Value, app.data_t2+app.temp_init.Value, app.data_t3+app.temp_init.Value, app.data_consigne+app.temp_init.Value, app.data, ...
                'VariableNames', {'Temps', 'T1', 'T2', 'T3', 'Consigne', 'Commande'});

            writetable(DATA, file_name);
            app.EnattenteLabel.Text = 'Sauvegardé avec succès.';
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == 'Sans'
                set_param(app.model + "/DAC (incluant PWM)/PWM", 'Commented', 'through');
                set_param(app.model + '/Conditionnement', 'Commented', 'through');
                set_param(app.model + '/Filtre trible RC', 'Commented', 'through');
            else
                set_param(app.model+ "/DAC (incluant PWM)/PWM", 'Commented', 'off');
                set_param(app.model+ '/Conditionnement', 'Commented', 'off');
                set_param(app.model+ '/Filtre trible RC', 'Commented', 'off');
            end
        end

        % Button pushed function: ParamtrespardfautButton
        function ParamtrespardfautButtonPushed(app, event)
            try
            reset_to_defaults(app);
            assign_all_to_workspace(app);
            app.EnattenteLabel.Text = "Paramètres par défaut appliqués !";
            catch ME
               uialert(app.UIFigure,"Erreur :" + ME.message,"Erreur");
            end
        end

        % Button pushed function: ImporterunerponselchelonTECButton
        function ImporterunerponselchelonTECButtonPushed(app, event)
            % Ouvrir une boîte de dialogue pour sélectionner un fichier JSON
            [file, path] = uigetfile('*.csv', 'Sélectionnez un fichier .csv');            % Vérifier si l'utilisateur a annulé la sélection
            if isequal(file, 0)
                disp('❌ Sélection annulée.');
                return;
            end
        
            % Construire le chemin complet du fichier sélectionné
            fichierSource = fullfile(path, file);
            
            % Définir le chemin de destination (dossier actuel)
            fichierDestination = fullfile(pwd, 'imported_iddata_tec.csv');
        
            % Copier le fichier dans le dossier actuel sous le nom param.json
            copyfile(fichierSource, fichierDestination);
            addpath("support\");
            cla(app.UI_rep_tec);
            res_exp = read_csv("imported_iddata_tec.csv");
            y3  = res_exp.T3(1:end, 1);
            y2  = res_exp.T2(1:end, 1);
            y1  = res_exp.T1(1:end, 1);
            t   = res_exp.Temps(1:end, 1);

            hold(app.UI_rep_tec, 'on');
            plot(app.UI_rep_tec, t, y3, 'DisplayName', 'T3');
            plot(app.UI_rep_tec, t, y2, 'DisplayName', 'T2');
            plot(app.UI_rep_tec, t, y1, 'DisplayName', 'T1');
            try
                u = res_exp.Puissance(1:end, 1);
                yyaxis(app.UI_rep_tec, 'right')
                plot(app.UI_rep_tec, t, u, 'DisplayName', 'Puissance (W)');
                app.ident_pow = u;
            catch
                u = res_exp.Commande(1:end, 1);
                app.ident_pow = convert_to_pow(app, u);
                yyaxis(app.UI_rep_tec, 'right')
                plot(app.UI_rep_tec, t, u, 'DisplayName', 'Commande (V)');
            end
            legend(app.UI_rep_tec, 'Location', 'best');
            
            app.indent_tec_start_time.Value = 0;%t(1, 1);
            app.ident_tec_end_time.Value = t(end, 1);

        end

        % Button pushed function: 
        % ImporterunerponselchelonPerturbationButton
        function ImporterunerponselchelonPerturbationButtonPushed(app, event)
            % Ouvrir une boîte de dialogue pour sélectionner un fichier JSON
            [file, path] = uigetfile('*.csv', 'Sélectionnez un fichier .csv');
            
            % Vérifier si l'utilisateur a annulé la sélection
            if isequal(file, 0)
                disp('❌ Sélection annulée.');
                return;
            end
            
            % Construire le chemin complet du fichier sélectionné
            fichierSource = fullfile(path, file);
            
            % Définir le chemin de destination (dossier actuel)
            fichierDestination = fullfile(pwd, 'imported_iddata_pert.csv');
        
            % Copier le fichier dans le dossier actuel sous le nom param.json
            copyfile(fichierSource, fichierDestination);
            addpath("support\");
            
            res_exp = read_csv("imported_iddata_pert.csv");
            y3  = res_exp.T3(1:end, 1);
            y2  = res_exp.T2(1:end, 1);
            y1  = res_exp.T1(1:end, 1);
            t   = res_exp.Temps(1:end, 1);
            app.ident_pow_pert_time;
            u   = ones(size(t))*app.ident_pert_pow_feild.Value;
            cla(app.UI_rep_pert);

            hold(app.UI_rep_pert, 'on');
            plot(app.UI_rep_pert, t, y3, 'DisplayName', 'T3');
            plot(app.UI_rep_pert, t, y2, 'DisplayName', 'T2');
            plot(app.UI_rep_pert, t, y1, 'DisplayName', 'T1');
            yyaxis(app.UI_rep_pert, 'right')
            app.h1 = plot(app.UI_rep_pert, t, u, 'DisplayName', 'Puissance [W]');
            legend(app.UI_rep_pert, 'Location', 'best');
                
            app.ident_pow_pert = u;
            app.ident_pert_start.Value = 0;%t(1, 1);
            app.ident_pert_end.Value = t(end, 1);

        end

        % Button pushed function: IdentifierButton
        function IdentifierButtonPushed(app, event)
            app.EnattenteLabel.Text = "Identification en cours...";
            % Identification avec échelon de puissance au tec et régulateur
            auto_ident_for_app_v2("imported_iddata_tec.csv", ...
                app.indent_tec_start_time.Value, ...
                app.ident_tec_end_time.Value, ...
                app.ident_pow, ...
                built_option_list(app))
            % Identification avec échelon de puissance de perturbation
            indent_pertub_V2("imported_iddata_pert.csv", ...
                app.ident_pert_start.Value, ...
                app.ident_pert_end.Value, ...
                app.ident_pow_pert, ...
                built_option_list(app))
            % Mettre à jour les fichiers
            uptate_identified_feild(app);
            app.EnattenteLabel.Text = "Identification complété avec succès";
        end

        % Value changed function: ident_pert_pow_feild
        function ident_pert_pow_feildValueChanged(app, event)
            value = app.ident_pert_pow_feild.Value;
            try
                app.ident_pow_pert = ones(size(app.ident_pow_pert)) * value;
                p_old = app.h1.YData;
                p_new = ones(size(p_old)) * value;
                app.h1.YData = p_new;
                axis(app.UI_rep_pert, 'auto');
            catch
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ConsignesdetempratureCPanel = uipanel(app.GridLayout);
            app.ConsignesdetempratureCPanel.Title = 'Consigne(s) de température [C]';
            app.GridLayout2 = uigridlayout(app.ConsignesdetempratureCPanel);
            app.InitialeLabel = uilabel(app.GridLayout2);
            app.InitialeLabel.Text = 'Initiale [°C]';
            app.InitialeCEditField = uieditfield(app.GridLayout2, 'numeric');
            app.InitialeCEditField.Value = 30;
            app.FinaleCEditFieldLabel = uilabel(app.GridLayout2);
            app.FinaleCEditFieldLabel.Text = 'Finale [°C]';
            app.FinaleCEditField = uieditfield(app.GridLayout2, 'numeric');
            app.FinaleCEditField.Value = 20;
            app.TempsdechangementsEditFieldLabel = uilabel(app.GridLayout2);
            app.TempsdechangementsEditFieldLabel.Text = 'Temps de changement [s]';
            app.TempsdechangementsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TempsdechangementsEditField.Value = 500;
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Tooltip = {'Le temps de changement est le moment où la consigner passe de la valeur initiale à la valeur finale spécifié.'};
            app.Label_2.Text = 'ⓘ';
            app.RgulateurPIDFPanel = uipanel(app.GridLayout);
            app.RgulateurPIDFPanel.Title = 'Régulateur PIDF';
            app.GridLayout3 = uigridlayout(app.RgulateurPIDFPanel);
            app.KcEditFieldLabel = uilabel(app.GridLayout3);
            app.KcEditFieldLabel.Text = 'Kc';
            app.KcEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KcEditField.Value = 0.155;
            app.TiEditFieldLabel = uilabel(app.GridLayout3);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TiEditField.Value = 145;
            app.TfEditFieldLabel = uilabel(app.GridLayout3);
            app.TfEditFieldLabel.Text = 'Tf';
            app.TfEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TfEditField.Value = 4.33;
            app.TdEditFieldLabel = uilabel(app.GridLayout3);
            app.TdEditFieldLabel.Text = 'Td';
            app.TdEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TdEditField.Value = 13;
            app.Label_3 = uilabel(app.GridLayout3);
            app.Label_3.Tooltip = {'Gain du régulateur'};
            app.Label_3.Text = 'ⓘ';
            app.Label_4 = uilabel(app.GridLayout3);
            app.Label_4.Tooltip = {'Constante de temps du filtre du régulateur'};
            app.Label_4.Text = 'ⓘ';
            app.Label_5 = uilabel(app.GridLayout3);
            app.Label_5.Tooltip = {'Consante de temps de l''action intégrale du régulateur'};
            app.Label_5.Text = 'ⓘ';
            app.Label_6 = uilabel(app.GridLayout3);
            app.Label_6.Tooltip = {'Constante de temps de l''action dérivative du régulateur'};
            app.Label_6.Text = 'ⓘ';
            app.DuredesimulationsEditFieldLabel = uilabel(app.GridLayout);
            app.DuredesimulationsEditFieldLabel.Text = 'Durée de simulation [s]';
            app.DuredesimulationsEditField = uieditfield(app.GridLayout, 'numeric');
            app.DuredesimulationsEditField.Value = 1000;
            app.AppliquerparamtresButton = uibutton(app.GridLayout, 'push');
            app.AppliquerparamtresButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerparamtresButtonPushed, true);
            app.AppliquerparamtresButton.Text = 'Appliquer paramètres';
            app.PerturbationPanel = uipanel(app.GridLayout);
            app.PerturbationPanel.Title = 'Perturbation';
            app.GridLayout2_2 = uigridlayout(app.PerturbationPanel);
            app.PuissanceinitialeLabel = uilabel(app.GridLayout2_2);
            app.PuissanceinitialeLabel.Text = 'Puissance initiale [W]';
            app.PuissanceinitialeWEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.PuissancefinaleWEditFieldLabel = uilabel(app.GridLayout2_2);
            app.PuissancefinaleWEditFieldLabel.Text = 'Puissance finale [W]';
            app.PuissancefinaleWEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.TempsdechangementsEditField_2Label = uilabel(app.GridLayout2_2);
            app.TempsdechangementsEditField_2Label.Text = 'Temps de changement [s]';
            app.TempsdechangementsEditField_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.Label_7 = uilabel(app.GridLayout2_2);
            app.Label_7.Tooltip = {'Le temps de changement est le moment où la puissance de la perturbation passe de la valeur initiale à la valeur finale spécifié.'};
            app.Label_7.Text = 'ⓘ';
            app.DmarrerButton = uibutton(app.GridLayout, 'push');
            app.DmarrerButton.ButtonPushedFcn = createCallbackFcn(app, @DmarrerButtonPushed, true);
            app.DmarrerButton.Text = 'Démarrer';
            app.EnattenteLabel = uilabel(app.GridLayout);
            app.EnattenteLabel.Text = 'En attente...';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PrincipaleTab = uitab(app.TabGroup);
            app.PrincipaleTab.Title = 'Principale';
            app.GridLayout6 = uigridlayout(app.PrincipaleTab);
            app.UIAxes2 = uiaxes(app.GridLayout6);
            title(app.UIAxes2, 'Évolution de la consigne et des température de T1, T2 et T3')
            xlabel(app.UIAxes2, 'Temps [s]')
            ylabel(app.UIAxes2, 'Température [ C]')
            zlabel(app.UIAxes2, 'Z')
            app.UI_perturb = uiaxes(app.GridLayout6);
            title(app.UI_perturb, {'Perturbation en fonction du temps'; ' '})
            xlabel(app.UI_perturb, 'Temps [s]')
            ylabel(app.UI_perturb, 'Perturbation [W]')
            zlabel(app.UI_perturb, 'Z')
            app.UIAxes = uiaxes(app.GridLayout6);
            title(app.UIAxes, 'Évolution de la commande ')
            xlabel(app.UIAxes, 'Temps [s]')
            ylabel(app.UIAxes, 'Commande [V]')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.GridLayout6);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
            app.TensionsauxthermistancesTab = uitab(app.TabGroup);
            app.TensionsauxthermistancesTab.Title = 'Tensions aux thermistances';
            app.GridLayout10 = uigridlayout(app.TensionsauxthermistancesTab);
            app.UIAxesV = uiaxes(app.GridLayout10);
            title(app.UIAxesV, 'Tension à T1, T2 et T3')
            xlabel(app.UIAxesV, 'Temps [s]')
            ylabel(app.UIAxesV, 'Tension [V]')
            zlabel(app.UIAxesV, 'Z')
            app.RsistancesdesthermistancesTab = uitab(app.TabGroup);
            app.RsistancesdesthermistancesTab.Title = 'Résistances des thermistances';
            app.GridLayout9 = uigridlayout(app.RsistancesdesthermistancesTab);
            app.UIAxesR = uiaxes(app.GridLayout9);
            title(app.UIAxesR, 'Résistance de T1, T2 et T3')
            xlabel(app.UIAxesR, 'Temps [s]')
            ylabel(app.UIAxesR, 'Résistance [Ohm]')
            zlabel(app.UIAxesR, 'Z')
            app.EstimationdeT3Tab = uitab(app.TabGroup);
            app.EstimationdeT3Tab.Title = 'Estimation de T3';
            app.GridLayout8 = uigridlayout(app.EstimationdeT3Tab);
            app.UIAxesESTIMT3 = uiaxes(app.GridLayout8);
            title(app.UIAxesESTIMT3, 'T3 mesuré ; T3 estimé/ Entré du régulateur ; Écart ')
            xlabel(app.UIAxesESTIMT3, 'Temps [s]')
            ylabel(app.UIAxesESTIMT3, 'Température [°C]')
            zlabel(app.UIAxesESTIMT3, 'Z')
            app.SortiesADCTab = uitab(app.TabGroup);
            app.SortiesADCTab.Title = 'Sorties ADC';
            app.GridLayout7 = uigridlayout(app.SortiesADCTab);
            app.UIAxesADC = uiaxes(app.GridLayout7);
            title(app.UIAxesADC, 'Sorties de l''ADC T1-T2-T3')
            xlabel(app.UIAxesADC, 'Temps [s]')
            ylabel(app.UIAxesADC, 'Niveau [-]')
            zlabel(app.UIAxesADC, 'Z')
            app.AutressignauxTab = uitab(app.TabGroup);
            app.AutressignauxTab.Title = 'Autres signaux';
            app.GridLayout5 = uigridlayout(app.AutressignauxTab);
            app.UIAxesPWM = uiaxes(app.GridLayout5);
            title(app.UIAxesPWM, 'Sortie PWM')
            xlabel(app.UIAxesPWM, 'Temps [s]')
            ylabel(app.UIAxesPWM, 'Tension [V]')
            zlabel(app.UIAxesPWM, 'Z')
            app.UIAxesAMP_in = uiaxes(app.GridLayout5);
            title(app.UIAxesAMP_in, 'Entrée amplificateur de puissance')
            xlabel(app.UIAxesAMP_in, 'Temps [s]')
            ylabel(app.UIAxesAMP_in, 'Tension [V]')
            zlabel(app.UIAxesAMP_in, 'Z')
            app.UIAxesTEC_in = uiaxes(app.GridLayout5);
            title(app.UIAxesTEC_in, 'Entrée TEC')
            xlabel(app.UIAxesTEC_in, 'Temps [s]')
            ylabel(app.UIAxesTEC_in, 'Tension [V]')
            zlabel(app.UIAxesTEC_in, 'Z')
            app.UIAxesTEC_out = uiaxes(app.GridLayout5);
            title(app.UIAxesTEC_out, 'Sortie TEC')
            xlabel(app.UIAxesTEC_out, 'Temps [s]')
            ylabel(app.UIAxesTEC_out, 'Puissance [W]')
            zlabel(app.UIAxesTEC_out, 'Z')
            app.ParamtresavancsTab = uitab(app.TabGroup);
            app.ParamtresavancsTab.Title = 'Paramètres avancés';
            app.GridLayout11 = uigridlayout(app.ParamtresavancsTab);
            app.FonctiondetransfertdelaplaqueParrapportauTECPanel = uipanel(app.GridLayout11);
            app.FonctiondetransfertdelaplaqueParrapportauTECPanel.Title = 'Fonction de transfert de la plaque (Par rapport au TEC)';
            app.GridLayout3_2 = uigridlayout(app.FonctiondetransfertdelaplaqueParrapportauTECPanel);
            app.gt2 = uieditfield(app.GridLayout3_2, 'numeric');
            app.gt2.Value = 0.78;
            app.gt3 = uieditfield(app.GridLayout3_2, 'numeric');
            app.gt3.Value = 0.13;
            app.ct1 = uieditfield(app.GridLayout3_2, 'numeric');
            app.ct1.Value = 105;
            app.ct2 = uieditfield(app.GridLayout3_2, 'numeric');
            app.ct2.Value = 37;
            app.gt1 = uieditfield(app.GridLayout3_2, 'numeric');
            app.gt1.Value = 11;
            app.T1EditFieldLabel_2 = uilabel(app.GridLayout3_2);
            app.T1EditFieldLabel_2.Text = 'Gain statique';
            app.T1EditFieldLabel_3 = uilabel(app.GridLayout3_2);
            app.T1EditFieldLabel_3.Text = 'Constante de temps [s]';
            app.T1EditFieldLabel_4 = uilabel(app.GridLayout3_2);
            app.T1EditFieldLabel_4.Text = 'Retard [s]';
            app.T1EditFieldLabel_5 = uilabel(app.GridLayout3_2);
            app.T1EditFieldLabel_5.Text = 'T1';
            app.T2EditFieldLabel = uilabel(app.GridLayout3_2);
            app.T2EditFieldLabel.Text = 'T2';
            app.T3EditFieldLabel = uilabel(app.GridLayout3_2);
            app.T3EditFieldLabel.Text = 'T3';
            app.ct3 = uieditfield(app.GridLayout3_2, 'numeric');
            app.ct3.Value = 6.7;
            app.rt1 = uieditfield(app.GridLayout3_2, 'numeric');
            app.rt1.Limits = [0 Inf];
            app.rt2 = uieditfield(app.GridLayout3_2, 'numeric');
            app.rt2.Limits = [0 Inf];
            app.rt2.Value = 5;
            app.rt3 = uieditfield(app.GridLayout3_2, 'numeric');
            app.rt3.Limits = [0 Inf];
            app.rt3.Value = 15;
            app.Label_8 = uilabel(app.GridLayout3_2);
            app.Label_8.Tooltip = {'Les fonctions de transfert sont de premier ordre, sans zéro et avec ou sans retard.'};
            app.Label_8.Text = 'ⓘ';
            app.SaturationentensiondurgulateurVLabel = uilabel(app.GridLayout11);
            app.SaturationentensiondurgulateurVLabel.Text = 'Saturation en tension du régulateur [V]';
            app.FonctionsdetransfertdelaperturbationsurlaplaquePanel = uipanel(app.GridLayout11);
            app.FonctionsdetransfertdelaperturbationsurlaplaquePanel.Title = 'Fonctions de transfert de la perturbation sur la plaque';
            app.GridLayout3_3 = uigridlayout(app.FonctionsdetransfertdelaperturbationsurlaplaquePanel);
            app.pgt2 = uieditfield(app.GridLayout3_3, 'numeric');
            app.pgt2.Value = 0.155;
            app.pgt3 = uieditfield(app.GridLayout3_3, 'numeric');
            app.pgt3.Value = 145;
            app.pct1 = uieditfield(app.GridLayout3_3, 'numeric');
            app.pct1.Value = 4.33;
            app.pct2 = uieditfield(app.GridLayout3_3, 'numeric');
            app.pct2.Value = 13;
            app.pgt1 = uieditfield(app.GridLayout3_3, 'numeric');
            app.pgt1.Value = 0.155;
            app.T1EditFieldLabel_6 = uilabel(app.GridLayout3_3);
            app.T1EditFieldLabel_6.Text = 'Gain statique';
            app.T1EditFieldLabel_7 = uilabel(app.GridLayout3_3);
            app.T1EditFieldLabel_7.Text = 'Constante de temps [s]';
            app.T1EditFieldLabel_8 = uilabel(app.GridLayout3_3);
            app.T1EditFieldLabel_8.Text = 'Retard [s]';
            app.T1EditFieldLabel_9 = uilabel(app.GridLayout3_3);
            app.T1EditFieldLabel_9.Text = 'T1';
            app.T2EditFieldLabel_2 = uilabel(app.GridLayout3_3);
            app.T2EditFieldLabel_2.Text = 'T2';
            app.T3EditFieldLabel_2 = uilabel(app.GridLayout3_3);
            app.T3EditFieldLabel_2.Text = 'T3';
            app.pct3 = uieditfield(app.GridLayout3_3, 'numeric');
            app.pct3.Value = 145;
            app.prt1 = uieditfield(app.GridLayout3_3, 'numeric');
            app.prt2 = uieditfield(app.GridLayout3_3, 'numeric');
            app.prt2.Value = 5;
            app.prt3 = uieditfield(app.GridLayout3_3, 'numeric');
            app.prt3.Value = 15;
            app.Label_9 = uilabel(app.GridLayout3_3);
            app.Label_9.Tooltip = {'Les fonctions de transfert sont de premier ordre, sans zéro et avec ou sans retard.'};
            app.Label_9.Text = 'ⓘ';
            app.Slider_sat = uislider(app.GridLayout11, 'range');
            app.Slider_sat.Limits = [-5 5];
            app.Slider_sat.Step = 0.2;
            app.Slider_sat.Value = [-1 1];
            app.FrquencedchantillonageLabel = uilabel(app.GridLayout11);
            app.FrquencedchantillonageLabel.Text = 'Fréquence d''échantillonage [Hz]:';
            app.sample_freq = uieditfield(app.GridLayout11, 'numeric');
            app.sample_freq.Value = 0.2;
            app.TabGroup2 = uitabgroup(app.GridLayout11);
            app.PWMetAmpliTab = uitab(app.TabGroup2);
            app.PWMetAmpliTab.Title = 'PWM et Ampli.';
            app.GridLayout13 = uigridlayout(app.PWMetAmpliTab);
            app.FrquenceduPWMLabel = uilabel(app.GridLayout13);
            app.FrquenceduPWMLabel.Text = 'Fréquence du PWM [Hz]:';
            app.pwm_freq = uieditfield(app.GridLayout13, 'numeric');
            app.pwm_freq.Value = 1000;
            app.ParamtreduPWMLabel = uilabel(app.GridLayout13);
            app.ParamtreduPWMLabel.Text = 'Paramètre du PWM';
            app.AmplificateurdepuissanceLabel_2 = uilabel(app.GridLayout13);
            app.AmplificateurdepuissanceLabel_2.Text = 'Amplificateur de puissance';
            app.GainLabel_2 = uilabel(app.GridLayout13);
            app.GainLabel_2.Text = 'Gain :';
            app.gain_amp = uieditfield(app.GridLayout13, 'numeric');
            app.gain_amp.Value = 1;
            app.FiltrepostPWMTab = uitab(app.TabGroup2);
            app.FiltrepostPWMTab.Title = 'Filtre post-PWM';
            app.GridLayout16 = uigridlayout(app.FiltrepostPWMTab);
            app.FonctiondetransfertdufiltrepostPWMLabel = uilabel(app.GridLayout16);
            app.FonctiondetransfertdufiltrepostPWMLabel.Text = 'Fonction de transfert du filtre post-PWM';
            app.NumrateurenpuissancesdcroissantesdesexxxxxLabel = uilabel(app.GridLayout16);
            app.NumrateurenpuissancesdcroissantesdesexxxxxLabel.Text = {'Numérateur en puissances décroissantes de s :'; 'ex:[x  x x  x]'};
            app.FiltrePWM_NUM = uieditfield(app.GridLayout16, 'text');
            app.FiltrePWM_NUM.Value = '[1]';
            app.DnominateurenpuissancesdcroissantesdesexxxxxLabel = uilabel(app.GridLayout16);
            app.DnominateurenpuissancesdcroissantesdesexxxxxLabel.Text = {'Dénominateur en puissances décroissantes de s '; 'ex:[x x x x]'};
            app.FiltrePWM_DEN = uieditfield(app.GridLayout16, 'text');
            app.FiltrePWM_DEN.Value = '[ (4e-3)^3 5*(4e-3)^2 6*4e-3 1]';
            app.CircuitsdemesureTab = uitab(app.TabGroup2);
            app.CircuitsdemesureTab.Title = 'Circuits de mesure';
            app.GridLayout15 = uigridlayout(app.CircuitsdemesureTab);
            app.ConditionnementprADCLabel = uilabel(app.GridLayout15);
            app.ConditionnementprADCLabel.Text = 'Conditionnement pré-ADC';
            app.offset_T1Label = uilabel(app.GridLayout15);
            app.offset_T1Label.Text = 'Offset T1:';
            app.mes_offset_in = uieditfield(app.GridLayout15, 'numeric');
            app.mes_offset_in.Value = 1.52;
            app.offset_T1Label_2 = uilabel(app.GridLayout15);
            app.offset_T1Label_2.Text = 'Offset T3:';
            app.mes_offset_in_2 = uieditfield(app.GridLayout15, 'numeric');
            app.mes_offset_in_2.Value = 1.98;
            app.GainT1Label = uilabel(app.GridLayout15);
            app.GainT1Label.Text = 'Gain T1:';
            app.mes_gain_in_2 = uieditfield(app.GridLayout15, 'numeric');
            app.GainT1Label_2 = uilabel(app.GridLayout15);
            app.GainT1Label_2.Text = 'Gain T2:';
            app.mes_gain_in_3 = uieditfield(app.GridLayout15, 'numeric');
            app.GainT3Label = uilabel(app.GridLayout15);
            app.GainT3Label.Text = 'Gain T3:';
            app.mes_gain_in_4 = uieditfield(app.GridLayout15, 'numeric');
            app.offset_T1Label_3 = uilabel(app.GridLayout15);
            app.offset_T1Label_3.Text = 'Offset T2:';
            app.mes_offset_in_3 = uieditfield(app.GridLayout15, 'numeric');
            app.mes_offset_in_3.Value = 1.74;
            app.bit_dac = uidropdown(app.GridLayout11);
            app.bit_dac.Items = {'10', '12', '16'};
            app.bit_dac.Value = '10';
            app.BitDACLabel_2 = uilabel(app.GridLayout11);
            app.BitDACLabel_2.Text = '#Bit DAC:';
            app.bit_adc = uidropdown(app.GridLayout11);
            app.bit_adc.Items = {'10', '12', '16'};
            app.bit_adc.Value = '10';
            app.BitADCLabel = uilabel(app.GridLayout11);
            app.BitADCLabel.Text = '#Bit ADC:';
            app.CoefficientsdeSteinhartHartPanel = uipanel(app.GridLayout11);
            app.CoefficientsdeSteinhartHartPanel.Title = 'Coefficients de Steinhart-Hart ';
            app.GridLayout12 = uigridlayout(app.CoefficientsdeSteinhartHartPanel);
            app.AEditField_2Label = uilabel(app.GridLayout12);
            app.AEditField_2Label.Text = 'A';
            app.sth_a = uieditfield(app.GridLayout12, 'numeric');
            app.sth_a.ValueDisplayFormat = '%11.8g';
            app.sth_a.Value = -14.65719769;
            app.BEditFieldLabel = uilabel(app.GridLayout12);
            app.BEditFieldLabel.Text = 'B';
            app.sth_b = uieditfield(app.GridLayout12, 'numeric');
            app.sth_b.ValueDisplayFormat = '%11.8g';
            app.sth_b.Value = 4798.842;
            app.CEditFieldLabel = uilabel(app.GridLayout12);
            app.CEditFieldLabel.Text = 'C';
            app.sth_c = uieditfield(app.GridLayout12, 'numeric');
            app.sth_c.ValueDisplayFormat = '%11.8g';
            app.sth_c.Value = -115334;
            app.DEditFieldLabel = uilabel(app.GridLayout12);
            app.DEditFieldLabel.Text = 'D';
            app.sth_d = uieditfield(app.GridLayout12, 'numeric');
            app.sth_d.ValueDisplayFormat = '%11.8g';
            app.sth_d.Value = -3730535;
            app.A_2EditFieldLabel = uilabel(app.GridLayout12);
            app.A_2EditFieldLabel.Text = 'A_2';
            app.sth_a2 = uieditfield(app.GridLayout12, 'numeric');
            app.sth_a2.ValueDisplayFormat = '%11.8g';
            app.sth_a2.Value = 0.00335401643468053;
            app.B_2EditFieldLabel = uilabel(app.GridLayout12);
            app.B_2EditFieldLabel.Text = 'B_2';
            app.sth_b2 = uieditfield(app.GridLayout12, 'numeric');
            app.sth_b2.ValueDisplayFormat = '%11.8g';
            app.sth_b2.Value = 0.000256523550896126;
            app.C_2EditFieldLabel = uilabel(app.GridLayout12);
            app.C_2EditFieldLabel.Text = 'C_2';
            app.sth_c2 = uieditfield(app.GridLayout12, 'numeric');
            app.sth_c2.ValueDisplayFormat = '%11.8g';
            app.sth_c2.Value = 2.60597012072052e-06;
            app.D_2EditFieldLabel = uilabel(app.GridLayout12);
            app.D_2EditFieldLabel.Text = 'D_2';
            app.sth_d2 = uieditfield(app.GridLayout12, 'numeric');
            app.sth_d2.ValueDisplayFormat = '%11.8g';
            app.sth_d2.Value = 6.3292612648746e-08;
            app.R25CLabel = uilabel(app.GridLayout12);
            app.R25CLabel.Text = 'R_fixe';
            app.sth_rfixe = uieditfield(app.GridLayout12, 'numeric');
            app.sth_rfixe.ValueDisplayFormat = '%11.5g';
            app.sth_rfixe.Value = 10000;
            app.CoefficientspourTempratureRsistanceLabel = uilabel(app.GridLayout12);
            app.CoefficientspourTempratureRsistanceLabel.Text = 'Coefficients pour Température -> Résistance';
            app.CoefficientspourRsistanceTempratureLabel = uilabel(app.GridLayout12);
            app.CoefficientspourRsistanceTempratureLabel.Text = 'Coefficients pour Résistance -> Température';
            app.SimulationduPWMPanel = uipanel(app.GridLayout11);
            app.SimulationduPWMPanel.Title = 'Simulation du PWM';
            app.GridLayout4 = uigridlayout(app.SimulationduPWMPanel);
            app.Switch = uiswitch(app.GridLayout4, 'slider');
            app.Switch.Items = {'Sans', 'Avec'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Sans';
            app.PointdoprationCPanel = uipanel(app.GridLayout11);
            app.PointdoprationCPanel.Title = 'Point d''opération [°C]';
            app.GridLayout4_2 = uigridlayout(app.PointdoprationCPanel);
            app.temp_init = uieditfield(app.GridLayout4_2, 'numeric');
            app.temp_init.Value = 25;
            app.lectroniqueLabel = uilabel(app.GridLayout11);
            app.lectroniqueLabel.Text = 'Électronique :';
            app.IdentificationautomatiqueTab = uitab(app.TabGroup);
            app.IdentificationautomatiqueTab.Title = 'Identification automatique';
            app.GridLayout17 = uigridlayout(app.IdentificationautomatiqueTab);
            app.UI_rep_tec = uiaxes(app.GridLayout17);
            title(app.UI_rep_tec, 'Réponse à l''échelon importé (TEC)')
            xlabel(app.UI_rep_tec, 'Temps [s]')
            ylabel(app.UI_rep_tec, 'Température [ °C]')
            zlabel(app.UI_rep_tec, 'Z')
            app.UI_rep_pert = uiaxes(app.GridLayout17);
            title(app.UI_rep_pert, 'Réponse à l''échelon importé (Perturbation)')
            xlabel(app.UI_rep_pert, 'Temps [s]')
            ylabel(app.UI_rep_pert, 'Température [°C]')
            zlabel(app.UI_rep_pert, 'Z')
            app.Tree = uitree(app.GridLayout17, 'checkbox');
            app.ThermistancesNode = uitreenode(app.Tree);
            app.ThermistancesNode.Text = 'Thermistances';
            app.T1Node = uitreenode(app.ThermistancesNode);
            app.T1Node.NodeData = 'True';
            app.T1Node.Text = 'T1';
            app.T2Node = uitreenode(app.ThermistancesNode);
            app.T2Node.NodeData = 'True';
            app.T2Node.Text = 'T2';
            app.T3Node = uitreenode(app.ThermistancesNode);
            app.T3Node.Text = 'T3';
            app.PerturbationsNode = uitreenode(app.Tree);
            app.PerturbationsNode.Text = 'Perturbations';
            app.PerturbationT1Node = uitreenode(app.PerturbationsNode);
            app.PerturbationT1Node.Text = 'Perturbation → T1';
            app.PerturbationT2Node = uitreenode(app.PerturbationsNode);
            app.PerturbationT2Node.Text = 'Perturbation → T2';
            app.PerturbationT3Node = uitreenode(app.PerturbationsNode);
            app.PerturbationT3Node.Text = 'Perturbation → T3';
            app.RgulateurPIDFNode = uitreenode(app.Tree);
            app.RgulateurPIDFNode.Text = 'Régulateur (PIDF)';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.ThermistancesNode, app.T1Node, app.T2Node, app.T3Node, app.RgulateurPIDFNode];
            app.ImporterunerponselchelonTECButton = uibutton(app.GridLayout17, 'push');
            app.ImporterunerponselchelonTECButton.ButtonPushedFcn = createCallbackFcn(app, @ImporterunerponselchelonTECButtonPushed, true);
            app.ImporterunerponselchelonTECButton.Text = 'Importer une réponse à l''échelon (TEC)';
            app.ImporterunerponselchelonsousformatcsvselonletamplateLabel = uilabel(app.GridLayout17);
            app.ImporterunerponselchelonsousformatcsvselonletamplateLabel.Text = {'1. Importer la.les réponse.s à l''échelon .csv'; ''; ''};
            app.AttentionlescolonesdoiventtreT1T2T3TempsCommandeLabel = uilabel(app.GridLayout17);
            app.AttentionlescolonesdoiventtreT1T2T3TempsCommandeLabel.Text = 'Attention: les colones doivent être T1, T2, T3, Temps et Commande ';
            app.ImporterunerponselchelonsousformatcsvselonletamplateLabel_2 = uilabel(app.GridLayout17);
            app.ImporterunerponselchelonsousformatcsvselonletamplateLabel_2.Text = '2. Valider que les données sont luent correctement avec l''affichage ci-gauche';
            app.ImporterunerponselchelonsousformatcsvselonletamplateLabel_3 = uilabel(app.GridLayout17);
            app.ImporterunerponselchelonsousformatcsvselonletamplateLabel_3.Text = '3. Sélectionner les éléments à identifier avec le menu déroulant ci-droit';
            app.IdentifierButton = uibutton(app.GridLayout17, 'push');
            app.IdentifierButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifierButtonPushed, true);
            app.IdentifierButton.Text = '➥Identifier';
            app.ImporterunerponselchelonsousformatcsvselonletamplateLabel_4 = uilabel(app.GridLayout17);
            app.ImporterunerponselchelonsousformatcsvselonletamplateLabel_4.Text = '5. Appuyer sur le bouton ci-droit pour démmarrer l''identification';
            app.ImporterunerponselchelonsousformatcsvselonletamplateLabel_5 = uilabel(app.GridLayout17);
            app.ImporterunerponselchelonsousformatcsvselonletamplateLabel_5.Text = '4. Au besoin, modifier les intervales de temps qui seront utilisés pour l''identification';
            app.IntervalepourlidentificationTECLabel = uilabel(app.GridLayout17);
            app.IntervalepourlidentificationTECLabel.Text = 'Intervale pour l''identification (TEC)';
            app.DbutsEditFieldLabel = uilabel(app.GridLayout17);
            app.DbutsEditFieldLabel.Text = 'Début [s]';
            app.indent_tec_start_time = uieditfield(app.GridLayout17, 'numeric');
            app.indent_tec_start_time.AllowEmpty = 'on';
            app.indent_tec_start_time.Value = [];
            app.FinsEditFieldLabel = uilabel(app.GridLayout17);
            app.FinsEditFieldLabel.Text = 'Fin [s]';
            app.ident_tec_end_time = uieditfield(app.GridLayout17, 'numeric');
            app.ident_tec_end_time.AllowEmpty = 'on';
            app.ident_tec_end_time.Value = [];
            app.ImporterunerponselchelonsousformatcsvselonletamplateLabel_6 = uilabel(app.GridLayout17);
            app.ImporterunerponselchelonsousformatcsvselonletamplateLabel_6.Text = 'INSTRUCTIONS';
            app.ImporterunerponselchelonPerturbationButton = uibutton(app.GridLayout17, 'push');
            app.ImporterunerponselchelonPerturbationButton.ButtonPushedFcn = createCallbackFcn(app, @ImporterunerponselchelonPerturbationButtonPushed, true);
            app.ImporterunerponselchelonPerturbationButton.Text = 'Importer une réponse à l''échelon (Perturbation)';
            app.IntervalepourlidentificationPerturbationLabel = uilabel(app.GridLayout17);
            app.IntervalepourlidentificationPerturbationLabel.Text = 'Intervale pour l''identification (Perturbation)';
            app.DbutsEditField_2Label = uilabel(app.GridLayout17);
            app.DbutsEditField_2Label.Text = 'Début [s]';
            app.ident_pert_start = uieditfield(app.GridLayout17, 'numeric');
            app.ident_pert_start.AllowEmpty = 'on';
            app.ident_pert_start.Value = [];
            app.FinsEditField_2Label = uilabel(app.GridLayout17);
            app.FinsEditField_2Label.Text = 'Fin [s]';
            app.ident_pert_end = uieditfield(app.GridLayout17, 'numeric');
            app.ident_pert_end.AllowEmpty = 'on';
            app.ident_pert_end.Value = [];
            app.PuissancedeperturbWEditFieldLabel = uilabel(app.GridLayout17);
            app.PuissancedeperturbWEditFieldLabel.Text = 'Puissance de perturb. [W]';
            app.ident_pert_pow_feild = uieditfield(app.GridLayout17, 'numeric');
            app.ident_pert_pow_feild.ValueChangedFcn = createCallbackFcn(app, @ident_pert_pow_feildValueChanged, true);
            app.AttentionlescolonesdoiventtreT1T2T3TempsCommandeLabel_2 = uilabel(app.GridLayout17);
            app.AttentionlescolonesdoiventtreT1T2T3TempsCommandeLabel_2.Text = 'Attention: l''identification automatique considère que la perturbation est active dès le temps de début';
            app.ParamtrespardfautButton = uibutton(app.GridLayout, 'push');
            app.ParamtrespardfautButton.ButtonPushedFcn = createCallbackFcn(app, @ParamtrespardfautButtonPushed, true);
            app.ParamtrespardfautButton.Text = 'Paramètres par défaut';
            app.SauvegarderButton = uibutton(app.GridLayout, 'push');
            app.SauvegarderButton.ButtonPushedFcn = createCallbackFcn(app, @SauvegarderButtonPushed, true);
            app.SauvegarderButton.Text = 'Sauvegarder';
            app.NomdufichierLabel = uilabel(app.GridLayout);
            app.NomdufichierLabel.Text = 'Nom du fichier:';
            app.save_file_name = uieditfield(app.GridLayout, 'text');
            app.save_file_name.Value = 'asservissement.csv';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_simulink_2023

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end