classdef App_simulink < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LoadInputButton                 simulink.ui.control.LoadInputButton
        CommandeactuelleLabel           matlab.ui.control.Label
        DuredesimulationEditField       matlab.ui.control.NumericEditField
        DuredesimulationEditFieldLabel  matlab.ui.control.Label
        AsservissementencoursLabel      matlab.ui.control.Label
        SaveOutputButton                simulink.ui.control.SaveOutputButton
        RgulateurPIDFPanel              matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        TdField                         matlab.ui.control.NumericEditField
        TdLabel                         matlab.ui.control.Label
        TfField                         matlab.ui.control.NumericEditField
        TfLabel                         matlab.ui.control.Label
        TiField                         matlab.ui.control.NumericEditField
        TiEditFieldLabel                matlab.ui.control.Label
        KcField                         matlab.ui.control.NumericEditField
        KcEditFieldLabel                matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TimeScope2                      matlab.ui.scope.TimeScope
        TimeScope                       matlab.ui.scope.TimeScope
        SimulationProgress              simulink.ui.control.SimulationProgress
        SimulationControls              simulink.ui.control.SimulationControls
        CommandeactuelleGauge           matlab.ui.control.LinearGauge
        Label_5                         matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        Status                          matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ConsignesdetempratureCPanel     matlab.ui.container.Panel
        KcField_4                       matlab.ui.control.NumericEditField
        KcEditFieldLabel_4              matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        Label_6                         matlab.ui.control.Label
        KcField_5                       matlab.ui.control.NumericEditField
        TempsdechangementLabel          matlab.ui.control.Label
        KcField_3                       matlab.ui.control.NumericEditField
        ConsignefinaleLabel             matlab.ui.control.Label
        KcField_2                       matlab.ui.control.NumericEditField
        ConsigneintialeLabel            matlab.ui.control.Label
        ChargerParametresButton         matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
    
    end
    
    methods (Access = public)
        
        function DmarrerButtonPushedCallback(app, event)
            DmarrerButtonPushed(app, event);
       end
        % Callback function for the STOP Button press
        function ArrterButtonPushedCallback(app, event)
            ArrterButtonPushed(app, event);
        end
         % Callback function for the STOP Button press
        function ChargerParametresButtonPushedCallback(app, event)
           ChargerParametresButtonPushed(app, event);
        end
        

        function params_str = Reg_generation(app)
    % Définir la période d'échantillonnage
    Ts = 5;
    
    % Fonction de transfert du régulateur
    DF = tf([app.TdField.Value, 1], [app.TfField.Value, 1]);
    PI = tf(1, [app.TiField.Value, 1]);
    
    % Conversion en transformée en Z (Tustin)
    DFz = c2d(DF, Ts, 'zoh');
    PIz = c2d(PI, Ts, 'zoh');
    
    % Extraction des coefficients (SISO uniquement)
    DFnum = cell2mat(DFz.Numerator);
    DFden = -cell2mat(DFz.Denominator);
    PInum = cell2mat(PIz.Numerator);
    PIden = -cell2mat(PIz.Denominator);
    
    % Mettre 0 dans la première valeur du dénominateur
    DFden(1) = 0;
    PIden(1) = 0;
    
    % Générer la chaîne de paramètres
    params_str = sprintf('PIDF|%.4f|%s|%s|%s|%s|%.4f', ...
        app.KcField.Value, ...
        strjoin(string(DFnum), ','), ...
        strjoin(string(DFden), ','), ...
        PInum(2), ...
        strjoin(string(PIden), ','), ...
        app.ConsigneField.Value);

    
    % Affichage pour vérification (optionnel)
    disp(params_str);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%%%% Update fctn
        end

        % Callback function
        function DmarrerButtonPushed(app, event)

            app.Status.Text = ['Erreur : ', ME.message];
            disp(['Erreur : ', ME.message]);
        end

        % Callback function
        function ArrterButtonPushed(app, event)
   try
       flushinput(app.serialObj);
        flushoutput(a+pp.serialObj);  % Vide le buffer de lecture (entrée)
            writeline(app.serialObj, 'STOP');
            app.Status.Text = 'Status: Envoi du signal STOP à Arduino...';
            pause(1);
            response = readline(app.serialObj);
            disp(['Réponse Arduino0: ', response]);  % Afficher la réponse pour le débogage
            
                  app.running = false; % Mark the system as stopped
                  stop(app.Timer); % Arrêter le timer
                  app.Status.Text = 'Asservissement arrêté !';
            %else
                 %.Status.Text = 'arrêt impossible.';


    catch   
        app.Status.Text = 'Status: Failed to stop system';
    end
        end

        % Button pushed function: ChargerParametresButton
        function ChargerParametresButtonPushed(app, event)
try 
    if app.precons ~= app.ConsigneField.Value
        app.Lamp_2.Color = 'white';
        app.precons = app.ConsigneField.Value;
        app.Status.Text = '';
    end
    Reg_generation(app);
    % Affichage du statut d'envoi des données
    app.Status.Text = 'Envoi des paramètres en cours...';  % Mise à jour du statut
    disp('Envoi des paramètres en cours...');  % Affichage console pour le débogage
    
    % Vider le buffer de l'objet série avant d'envoyer les données
    flushinput(app.serialObj);  % Vide le buffer de lecture (entrée)
    flushoutput(app.serialObj);

    % Concaténer tous les paramètres dans une seule chaîne
    params_str = Reg_generation(app);
    % Envoi des paramètres via l'objet série déjà existant
    writeline(app.serialObj, params_str);  % Envoie tous les paramètres en une seule fois
    pause(3);  % Pause pour permettre à Arduino de traiter
    
    % Lecture de la réponse d'Arduino pour vérifier la réception
    response = readline(app.serialObj);  % ou readline(app.serialObj)
    disp("Réponse Arduino1 : " + response);  % Affichage de la réponse d'Arduino
    
    % Vérification de la réponse d'Arduino
    if contains(response, 'Commande valide.')  % Vérifie que la réponse contient "PID_SET"
        app.Status.Text = 'Données envoyées avec succès.';  % Mise à jour du statut
        disp('Données envoyées avec succès.');
    elseif contains(response, 'Commande invalide.')
        app.Status.Text = "Echec d'envoi des données.";  % Mise à jour du statut
        disp("Erreur lors de l'envoi des données.");
    %else
        %app.Status.Text = 'Réponse inattendue Arduino.';  % Mise à jour du statut
        %disp('Réponse inattendue Arduino.');
    end
    
catch ME
    % Gestion des erreurs
    app.Status.Text = "Erreur lors de l'envoi des paramètres.';  % Mise à jour du statut";
    uialert(app.UIFigure, sprintf("Choisissez un autre port"), 'Erreur Communication');
    disp(['Erreur : ', ME.message]);  % Affichage du message d'erreur
end


        end

        % Callback function
        function ConnecterButtonPushed(app, event)
            disp(app.preport);
            disp(app.port.Value);
            if ~strcmp(app.preport, app.port.Value)
            try
                disp(app.preport);

                % Initialize the serial object for communication with Arduino
                app.serialObj = serialport(app.port.Value, 115200);
                configureTerminator(app.serialObj, "LF"); % Configure the serial object to use LF terminator
                app.Status.Text = 'Communication Série initialisée';
                app.preport = app.port.Value;
                
               
                if app.Status.Text == "Communication Série initialisée"
                    app.Lamp.Color = 'g';
                end
                %app.TimerExecutionMode = 'fixedRate';
                %app.Timer.Period = 1; % Mettre à jour toutes les secondes
                %app.Timer.TimerFcn = @(~,~) updateTime(app); % Callback
            catch
                app.Status.Text = "Échec de l'initialisation";
                app.preport = app.port.Value;
                app.Lamp.Color = 'r';
           
            end
            end
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ChargerParametresButton = uibutton(app.GridLayout, 'push');
            app.ChargerParametresButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerParametresButtonPushed, true);
            app.ChargerParametresButton.Text = 'Appliquer paramètres';
            app.ConsignesdetempratureCPanel = uipanel(app.GridLayout);
            app.ConsignesdetempratureCPanel.Title = 'Consigne(s) de température [°C] ';
            app.GridLayout3 = uigridlayout(app.ConsignesdetempratureCPanel);
            app.ConsigneintialeLabel = uilabel(app.GridLayout3);
            app.ConsigneintialeLabel.Text = 'Initiale';
            app.KcField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.KcField_2.ValueDisplayFormat = '%11.6g';
            app.KcField_2.Value = 30;
            app.ConsignefinaleLabel = uilabel(app.GridLayout3);
            app.ConsignefinaleLabel.Text = 'Finale';
            app.KcField_3 = uieditfield(app.GridLayout3, 'numeric');
            app.KcField_3.ValueDisplayFormat = '%11.6g';
            app.KcField_3.Value = 20;
            app.TempsdechangementLabel = uilabel(app.GridLayout3);
            app.TempsdechangementLabel.Text = 'Temps de changement [s]';
            app.KcField_5 = uieditfield(app.GridLayout3, 'numeric');
            app.KcField_5.ValueDisplayFormat = '%11.6g';
            app.KcField_5.Value = 1000;
            app.Label_6 = uilabel(app.GridLayout3);
            app.Label_6.Tooltip = {'Entrez oco le moment où la consigne changera pour prendre sa valeur finale'};
            app.Label_6.Text = 'ⓘ';
            app.KcEditFieldLabel_4 = uilabel(app.ConsignesdetempratureCPanel);
            app.KcEditFieldLabel_4.Text = 'Kc';
            app.KcField_4 = uieditfield(app.ConsignesdetempratureCPanel, 'numeric');
            app.KcField_4.ValueDisplayFormat = '%11.6g';
            app.KcField_4.Value = 0.068218474078553;
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
            app.Status = uilabel(app.GridLayout);
            app.Status.Text = 'En attente .......';
            app.Lamp_2 = uilamp(app.GridLayout);
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '';
            app.CommandeactuelleGauge = uigauge(app.GridLayout, 'linear');
            app.SimulationControls = uisimcontrols(app.GridLayout);
            app.SimulationControls.Simulation = app.Simulation;
            app.SimulationProgress = uisimprogress(app.GridLayout);
            app.SimulationProgress.Simulation = app.Simulation;
            app.TimeScope = uitimescope(app.GridLayout);
            app.TimeScope.Title = 'Title';
            app.TimeScope.YLabel = 'Amplitude';
            app.TimeScope2 = uitimescope(app.GridLayout);
            app.TimeScope2.Title = 'Commande';
            app.TimeScope2.YLabel = 'Amplitude';
            app.RgulateurPIDFPanel = uipanel(app.GridLayout);
            app.RgulateurPIDFPanel.Title = 'Régulateur PIDF';
            app.GridLayout2 = uigridlayout(app.RgulateurPIDFPanel);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Tooltip = {'Entrez ici la valeur de la constante de temps de la partie filtre de votre PIDF'};
            app.Label.Text = 'ⓘ';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Tooltip = {'Entrez ici la valeur de la constante de temps de la partie dérivatrice de votre PIDF'};
            app.Label_2.Text = 'ⓘ';
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Tooltip = {'Entrez ici la valeur du gain proportionnel de votre PIDF'};
            app.Label_3.Text = 'ⓘ';
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Tooltip = {'Entrez ici la valeur du gain intégral de votre PIDF'};
            app.Label_4.Text = 'ⓘ';
            app.KcEditFieldLabel = uilabel(app.GridLayout2);
            app.KcEditFieldLabel.Text = 'Kc';
            app.KcField = uieditfield(app.GridLayout2, 'numeric');
            app.KcField.ValueDisplayFormat = '%11.6g';
            app.KcField.Value = 0.068218474078553;
            app.TiEditFieldLabel = uilabel(app.GridLayout2);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiField = uieditfield(app.GridLayout2, 'numeric');
            app.TiField.ValueDisplayFormat = '%11.6g';
            app.TiField.Value = 124.85342485148;
            app.TfLabel = uilabel(app.GridLayout2);
            app.TfLabel.Text = 'Tf';
            app.TfField = uieditfield(app.GridLayout2, 'numeric');
            app.TfField.ValueDisplayFormat = '%11.6g';
            app.TfField.Value = 4.3322782900504;
            app.TdLabel = uilabel(app.GridLayout2);
            app.TdLabel.Text = 'Td';
            app.TdField = uieditfield(app.GridLayout2, 'numeric');
            app.TdField.ValueDisplayFormat = '%11.6g';
            app.TdField.Value = 8.6646;
            app.SaveOutputButton = uisimdatabutton('saveoutput', app.GridLayout);
            app.SaveOutputButton.Simulation = app.Simulation;
            app.AsservissementencoursLabel = uilabel(app.GridLayout);
            app.AsservissementencoursLabel.Text = 'Asservissement en cours ...';
            app.DuredesimulationEditFieldLabel = uilabel(app.GridLayout);
            app.DuredesimulationEditFieldLabel.Text = 'Durée de simulation';
            app.DuredesimulationEditField = uieditfield(app.GridLayout, 'numeric');
            app.CommandeactuelleLabel = uilabel(app.GridLayout);
            app.CommandeactuelleLabel.Text = 'Commande actuelle';
            app.LoadInputButton = uisimdatabutton('loadinput', app.GridLayout);
            app.LoadInputButton.Simulation = app.Simulation;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_simulink

            % Associate the Simulink Model
            app.Simulation = simulation('MAIN_SIMULINK');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end