classdef app1_2023_simulink_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        SimulationTimeEditField         slrealtime.ui.control.SimulationTimeEditField
        EnattenteLabel                  matlab.ui.control.Label
        ArrterButton                    matlab.ui.control.Button
        DmarrerButton                   matlab.ui.control.Button
        SauvegarderButton               matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        AsservissementencoursLabel      matlab.ui.control.Label
        PerturbationPanel               matlab.ui.container.Panel
        GridLayout2_2                   matlab.ui.container.GridLayout
        Label_7                         matlab.ui.control.Label
        TempsdechangementsEditField_2   matlab.ui.control.NumericEditField
        TempsdechangementsEditField_2Label  matlab.ui.control.Label
        PuissancefinaleEditField_2      matlab.ui.control.NumericEditField
        PuissancefinaleEditField_2Label  matlab.ui.control.Label
        PuissancefinaleEditField        matlab.ui.control.NumericEditField
        PuissancefinaleEditFieldLabel   matlab.ui.control.Label
        AppliquerparamtresButton        matlab.ui.control.Button
        DuredesimulationEditField       matlab.ui.control.NumericEditField
        DuredesimulationEditFieldLabel  matlab.ui.control.Label
        CommandeactuelleVLabel          matlab.ui.control.Label
        Gauge                           matlab.ui.control.LinearGauge
        RgulateurPIDFPanel              matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        TdEditField                     matlab.ui.control.NumericEditField
        TdEditFieldLabel                matlab.ui.control.Label
        TfEditField                     matlab.ui.control.NumericEditField
        TfEditFieldLabel                matlab.ui.control.Label
        TiEditField                     matlab.ui.control.NumericEditField
        TiEditFieldLabel                matlab.ui.control.Label
        KcEditField                     matlab.ui.control.NumericEditField
        KcEditFieldLabel                matlab.ui.control.Label
        ConsignesdetempratureCPanel     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Label_2                         matlab.ui.control.Label
        TempsdechangementsEditField     matlab.ui.control.NumericEditField
        TempsdechangementsEditFieldLabel  matlab.ui.control.Label
        FinaleEditField                 matlab.ui.control.NumericEditField
        FinaleEditFieldLabel            matlab.ui.control.Label
        InitialeEditField               matlab.ui.control.NumericEditField
        InitialeEditFieldLabel          matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %simulation; % Descriptio
        myscope;
    end
    
    methods (Access = public)
        
        function AppliquerButtonPushedCallback(app,event)
            AppliquerButtonPushed(app,event);
        end
        function ArrterButtonPushedCallback(app, event)
            AppliquerparamtresButtonPushed(app,event);
        end
        function DmarrerButtonPushedCallback(app, event)
            DmarrerButtonPushed(app,event);
        end
        function StartStopButtonSizeChangedCallback(app, event)
        StartStopButtonSizeChanged(app, event);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        load_system("MAIN_SIMULINK2023");
        end

        % Button pushed function: DmarrerButton
        function DmarrerButtonPushed(app, event)
set_param("MAIN_SIMULINK2023","SimulationCommand","start");
cla(app.UIAxes);
if exist('Donnee',"var")
    % Récupérer les données de la variable 'A'
    data = Donnee; 
    time = data(1:end,1);  
     
    plot(app.UIAxes, time, signal);
    title(app.UIAxes, "Sortie osci");
    xlabel(app.UIAxes, "Temps");
    ylabel(app.UIAxes, "Données");
    
    % Ajouter une légende (si nécessaire)
    legend(app.UIAxes, "Signal1", "Signal2");  % Exemple de légende

    % Activer la grille
    grid(app.UIAxes, "on");

else
    % Si la variable 'A' n'existe pas, afficher un message d'erreur
    disp("Erreur d'affichage : la variable 'Donnee' n'existe pas dans l'espace de travail.");
end

        end

        % Button pushed function: AppliquerparamtresButton
        function AppliquerparamtresButtonPushed(app, event)
            try
            Ts = 5;
            DF = tf([app.TdEditField.Value,1],[app.TfEditField.Value,1]);
            PI = tf(1,[app.TiEditField.Value,1]);
            DFz = c2d(DF,Ts,"zoh");
            PIz= c2d(PI,Ts,"zoh");
            assignin("base","PIz",PIz);
            assignin("base","DFz",DFz);
            assignin("base","kc",app.KcEditField.Value);
            assignin("base","Time",app.TempsdechangementsEditField.Value);
            assignin("base","before",app.InitialeEditField.Value);
            assignin("base","after",app.FinaleEditField.Value);
            set_param("MAIN_SIMULINK2023","StopTime",num2str(app.DuredesimulationEditField.Value));
            catch ME
                %disp("Erreur detetee");
                uialert(app.UIFigure,"Erreur :" + ME.message,"Erreur");
            end

        end

        % Button pushed function: ArrterButton
        function ArrterButtonPushed(app, event)
            set_param("MAIN_SIMULINK2023","SimulationCommand","stop");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Évolution de la commande ')
            xlabel(app.UIAxes, 'Temps[s]')
            ylabel(app.UIAxes, 'Commande [V]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Température T3 Vs Consigne')
            xlabel(app.UIAxes2, 'Temps[s]')
            ylabel(app.UIAxes2, 'Température [ C]')
            zlabel(app.UIAxes2, 'Z')
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
            app.ConsignesdetempratureCPanel = uipanel(app.GridLayout);
            app.ConsignesdetempratureCPanel.Title = 'Consigne(s) de température [C]';
            app.GridLayout2 = uigridlayout(app.ConsignesdetempratureCPanel);
            app.InitialeEditFieldLabel = uilabel(app.GridLayout2);
            app.InitialeEditFieldLabel.Text = 'Initiale';
            app.InitialeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.InitialeEditField.Value = 30;
            app.FinaleEditFieldLabel = uilabel(app.GridLayout2);
            app.FinaleEditFieldLabel.Text = 'Finale';
            app.FinaleEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TempsdechangementsEditFieldLabel = uilabel(app.GridLayout2);
            app.TempsdechangementsEditFieldLabel.Text = 'Temps de changement [s]';
            app.TempsdechangementsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = 'ⓘ';
            app.RgulateurPIDFPanel = uipanel(app.GridLayout);
            app.RgulateurPIDFPanel.Title = 'Régulateur PIDF';
            app.GridLayout3 = uigridlayout(app.RgulateurPIDFPanel);
            app.KcEditFieldLabel = uilabel(app.GridLayout3);
            app.KcEditFieldLabel.Text = 'Kc';
            app.KcEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TiEditFieldLabel = uilabel(app.GridLayout3);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TfEditFieldLabel = uilabel(app.GridLayout3);
            app.TfEditFieldLabel.Text = 'Tf';
            app.TfEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TdEditFieldLabel = uilabel(app.GridLayout3);
            app.TdEditFieldLabel.Text = 'Td';
            app.TdEditField = uieditfield(app.GridLayout3, 'numeric');
            app.Label_3 = uilabel(app.GridLayout3);
            app.Label_3.Text = 'ⓘ';
            app.Label_4 = uilabel(app.GridLayout3);
            app.Label_4.Text = 'ⓘ';
            app.Label_5 = uilabel(app.GridLayout3);
            app.Label_5.Text = 'ⓘ';
            app.Label_6 = uilabel(app.GridLayout3);
            app.Label_6.Text = 'ⓘ';
            app.Gauge = uigauge(app.GridLayout, 'linear');
            app.Gauge.Limits = [-1 1];
            app.CommandeactuelleVLabel = uilabel(app.GridLayout);
            app.CommandeactuelleVLabel.Text = 'Commande actuelle [V]';
            app.DuredesimulationEditFieldLabel = uilabel(app.GridLayout);
            app.DuredesimulationEditFieldLabel.Text = 'Durée de simulation';
            app.DuredesimulationEditField = uieditfield(app.GridLayout, 'numeric');
            app.AppliquerparamtresButton = uibutton(app.GridLayout, 'push');
            app.AppliquerparamtresButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerparamtresButtonPushed, true);
            app.AppliquerparamtresButton.Text = 'Appliquer paramètres';
            app.PerturbationPanel = uipanel(app.GridLayout);
            app.PerturbationPanel.Title = 'Perturbation';
            app.GridLayout2_2 = uigridlayout(app.PerturbationPanel);
            app.PuissancefinaleEditFieldLabel = uilabel(app.GridLayout2_2);
            app.PuissancefinaleEditFieldLabel.Text = 'Puissance finale';
            app.PuissancefinaleEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.PuissancefinaleEditField_2Label = uilabel(app.GridLayout2_2);
            app.PuissancefinaleEditField_2Label.Text = 'Puissance finale';
            app.PuissancefinaleEditField_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.TempsdechangementsEditField_2Label = uilabel(app.GridLayout2_2);
            app.TempsdechangementsEditField_2Label.Text = 'Temps de changement [s]';
            app.TempsdechangementsEditField_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.Label_7 = uilabel(app.GridLayout2_2);
            app.Label_7.Text = 'ⓘ';
            app.AsservissementencoursLabel = uilabel(app.GridLayout);
            app.AsservissementencoursLabel.Text = 'Asservissement en cours...';
            app.Lamp = uilamp(app.GridLayout);
            app.SauvegarderButton = uibutton(app.GridLayout, 'push');
            app.SauvegarderButton.Text = 'Sauvegarder';
            app.DmarrerButton = uibutton(app.GridLayout, 'push');
            app.DmarrerButton.ButtonPushedFcn = createCallbackFcn(app, @DmarrerButtonPushed, true);
            app.DmarrerButton.Text = 'Démarrer';
            app.ArrterButton = uibutton(app.GridLayout, 'push');
            app.ArrterButton.ButtonPushedFcn = createCallbackFcn(app, @ArrterButtonPushed, true);
            app.ArrterButton.Text = 'Arrêter';
            app.EnattenteLabel = uilabel(app.GridLayout);
            app.EnattenteLabel.Text = 'En attente...';
            app.SimulationTimeEditField = slrealtime.ui.control.SimulationTimeEditField(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_2023_simulink_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end