classdef Regu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        SauvegarderlesdonnesButton  matlab.ui.control.Button
        RgulateurPIDFPanel          matlab.ui.container.Panel
        Label_4                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        TdField                     matlab.ui.control.NumericEditField
        TdLabel                     matlab.ui.control.Label
        TfField                     matlab.ui.control.NumericEditField
        TfLabel                     matlab.ui.control.Label
        TiField                     matlab.ui.control.NumericEditField
        TiEditFieldLabel            matlab.ui.control.Label
        KcField                     matlab.ui.control.NumericEditField
        KcEditFieldLabel            matlab.ui.control.Label
        Lamp_2                      matlab.ui.control.Lamp
        PortUSBDropDownLabel        matlab.ui.control.Label
        port                        matlab.ui.control.DropDown
        Connecter                   matlab.ui.control.Button
        Status                      matlab.ui.control.Label
        TimesLabel                  matlab.ui.control.Label
        TimerLabel                  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        ConsignedetempratureCPanel  matlab.ui.container.Panel
        ConsigneField               matlab.ui.control.NumericEditField
        ConsigneEditFieldLabel      matlab.ui.control.Label
        ChargerParametresButton     matlab.ui.control.Button
        ArrterButton                matlab.ui.control.Button
        DmarrerButton               matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
    end

    properties (Access = public)
        asservi = false ;
        preport = "a";
        precons = [25, 25];
        increment;
        Timer;
        t0;
        %serialObj        serialport
        running          logical = false;
        serialObj;
        temps ;
        temp3;
        h1;
        h2;
        h3;
        h4;
        h5;
        T1;
        T2;
        T3_estim;
        matrice;
        consigne = [];
        commande ;
        Ts = 5;
    end
    
    methods (Access = public)
        function DmarrerButtonPushedCallback(app, event)
            DmarrerButtonPushed(app, event);
        end

        % Callback function for the STOP Button press
        function ArrterButtonPushedCallback(app, event)
            ArrterButtonPushed(app, event);
        end
         % Callback function for the STOP Button press
        function ChargerParametresButtonPushedCallback(app, event)
           ChargerParametresButtonPushed(app, event);
        end
        
        
        function ConnecterButtonPushedCallback(app, event)
            ConnecterButtonPushed(app, event);
        end 

        function params_str = Reg_generation(app)
        % Fonction de transfert du régulateur
        DF = tf([app.TdField.Value, 1], [app.TfField.Value, 1]);
        PI = tf(1, [app.TiField.Value, 1]);
    
        % Conversion en transformée en Z (ZOH)
        DFz = c2d(DF, app.Ts, 'zoh');
        PIz = c2d(PI, app.Ts, 'zoh');
        
        % Extraction des coefficients (SISO uniquement)
        DFnum = cell2mat(DFz.Numerator);
        DFden = -cell2mat(DFz.Denominator);
        PInum = cell2mat(PIz.Numerator);
        PIden = -cell2mat(PIz.Denominator);
    
        % Mettre 0 dans la première valeur du dénominateur
        DFden(1) = 0;
        PIden(1) = 0;
    
        % Générer la chaîne de paramètres
        params_str = sprintf('PIDF|%.4f|%s|%s|%s|%s|%.4f\n', ...
        app.KcField.Value, ...
        strjoin(string(DFnum), ','), ...
        strjoin(string(DFden), ','), ...
        PInum(2), ...
        strjoin(string(PIden), ','), ...
        app.ConsigneField.Value);
        % Affichage pour vérification (optionnel)
        disp(params_str);
        end

        function updateSerialPortList(app)
        % Liste les ports série disponibles
        serialPorts = serialportlist("available");

        % Vérifie s'il y a des ports disponibles
        if isempty(serialPorts)
        app.port.Items = {"Aucun port disponible"};
        else
        app.port.Items = serialPorts;
        end

        % Sélectionne automatiquement le premier port disponible
        if ~isempty(serialPorts)
            app.port.Value = serialPorts(1);
        end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        updateSerialPortList(app);
        end

        % Button pushed function: DmarrerButton
        function DmarrerButtonPushed(app, event)
        try
        % **1. Vider le buffer série en lecture et en écriture pour ne pas avoir d'anciennes données**
        flushinput(app.serialObj);
        flushoutput(app.serialObj);
        %  Vérifier si l'asservissement était arrêté avant**
        if ~app.running  % Si l'asservissement était arrêté
            % Réinitialisation des données
            app.temps = [];
            app.temp3 = [];
            app.T1 = [];
            app.T2 = [];
            app.T3_estim = [];
            app.commande = [];
        % Effacer les courbes existantes
            if isfield(app, 'h1') && isvalid(app.h1)
                set(app.h1, 'XData', NaN, 'YData', NaN);
                set(app.h2, 'XData', NaN, 'YData', NaN);
                set(app.h3, 'XData', NaN, 'YData', NaN);
                set(app.h4, 'XData', NaN, 'YData', NaN);
                set(app.h5,'XData', NaN, 'YData', NaN');
            end
        % Remettre l'affichage à zéro
        cla(app.UIAxes);
        cla(app.UIAxes2);
        end

        % 2. Envoi du signal "START" à Arduino**
        writeline(app.serialObj, 'START');
        app.Status.Text = 'Status: Envoi du signal START à Arduino...';

        % 3. Attendre une réponse avec un timeout**
        timeout = 2;  % Timeout en secondes
        tStart = tic;  % Démarrer le chrono
    
        response = "";
        while toc(tStart) < timeout
            if app.serialObj.NumBytesAvailable > 0
                response = readline(app.serialObj);
            break;
            end
        pause(0.1);  % Éviter d'utiliser trop de CPU
        end
    
        % Vérifier si la réponse a été reçue
        %if response == ""
            %app.Status.Text = 'Erreur: Timeout - Aucune réponse d’Arduino';
           % disp('Erreur: Timeout - Aucune réponse d’Arduino');
            %return;
        %end
    
        %disp(['Réponse Arduino : ', response]);

        % 4. Vérification de la réponse d'Arduino**
        %if contains(response, 'Asservissement debute.')
            app.Status.Text = 'Asservissement démarré avec succès!';
            app.running = true;
            app.t0 = tic;
    
            % Vérification et démarrage du timer
            if ~isempty(app.TimerLabel)  % Vérifie si TimerLabel est défini
                app.Timer = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 1, ...
                    'TimerFcn', @(~,~) set(app.TimerLabel, 'Text', sprintf('%.2f s', toc(app.t0))) ...
                );
                start(app.Timer);
            else
                disp('TimerLabel non défini');
            end
        %else
           % app.Status.Text = 'Réponse inattendue';
            %return;
        %end

        % 5. Initialisation du graphique si première exécution**
        if isempty(app.temps)
            app.temps = [];
            app.temp3 = [];
            app.T1 = [];
            app.T2 = [];
            app.T3_estim = [];
            app.commande = [];
            app.t0 = tic;
            axes(app.UIAxes);
            axes(app.UIAxes2);
            hold(app.UIAxes2, 'on');
            hold(app.UIAxes, 'on');
             % Création des courbes une seule fois
            app.h1 = plot(app.UIAxes, NaN, NaN, 'r', 'LineWidth', 2, 'DisplayName', 'T1'); 
            app.h2 = plot(app.UIAxes, NaN, NaN, 'g', 'LineWidth', 2, 'DisplayName', 'T2'); 
            app.h3 = plot(app.UIAxes, NaN, NaN, 'b', 'LineWidth', 2, 'DisplayName', 'T3'); 
            app.h4 = plot(app.UIAxes, NaN, NaN, 'k--', 'LineWidth', 2, 'DisplayName', 'T3 estimé');
            app.h5 = plot(app.UIAxes2,NaN, NaN, 'r', 'LineWidth', 2);
            legend(app.UIAxes, 'show');
            xlabel(app.UIAxes, 'Temps (s)');
            ylabel(app.UIAxes, 'Température (°C)');
            legend(app.UIAxes, 'show'); % Afficher la légende
            grid(app.UIAxes, 'on');
        end


        % 6. Lecture des données série en continu**
        while app.running
        if app.serialObj.NumBytesAvailable > 0
        data = readline(app.serialObj);
        disp("Réponse Arduino : " + data);
        
        % Convertir en valeurs numériques
        data = strrep(data, 'Data', '');
        temp = str2double(split(data, ','));  % Séparer les valeurs avec ','
    
        % Vérifier si les valeurs sont valides
        if any(isnan(temp)) || length(temp) ~= 5
            disp("Donnée invalide (NaN) reçue, ignorée");
            return;
        end

        % Ajouter aux vecteurs de stockage
        t = toc(app.t0);
        app.temps = [app.temps, t];
        app.T1 = [app.T1, temp(1)]; 
        app.T2 = [app.T2, temp(2)];
        app.temp3 = [app.temp3, temp(3)];
        app.T3_estim = [app.T3_estim, temp(4)];
        app.commande = [app.commande, temp(5)];
        app.consigne = [app.consigne, app.precons(1)];
        % Affichage des nouvelles valeurs
        disp("T1: " + temp(1) + " | T2: " + temp(2) + " | T3: " + temp(3) + " | T3_estimé: " +temp(4));
        
        % Mise à jour du graphique
        set(app.h1, 'XData', app.temps, 'YData', app.T1);
        set(app.h2, 'XData', app.temps, 'YData', app.T2);
        set(app.h3, 'XData', app.temps, 'YData', app.temp3);
        set(app.h4, 'XData', app.temps, 'YData', app.T3_estim);
        legend(app.UIAxes, 'T1', 'T2', 'T3', 'T3 estimé');
        set(app.h5,'XData', app.temps, 'YData', app.commande);
        drawnow;
    

        % Vérification de l'asservissement
        if  ~app.asservi
         disp(app.precons);
        if app.precons(2) <=  app.precons(1)
        if (app.precons(1) - 0.05 <= app.temp3(end)) 
            app.Status.Text = sprintf("Asservissement Terminé en %.2f s!", t);
            app.Lamp_2.Color = 'g';
            app.asservi = true;
        end
        elseif app.precons(2) >=  app.precons(1)
        if (app.precons(1) + 0.05 >= app.temp3(end)) 
            app.Status.Text = sprintf("Asservissement Terminé en %.2f s!", t);
            app.Lamp_2.Color = 'g';
            app.asservi = true;
        end
        end
        end
        end 
        %pause(0.1);
        end

        catch ME
        app.Status.Text = ['Erreur : ', ME.message];
        disp(['Erreur : ', ME.message]);
        end
        end

        % Button pushed function: ArrterButton
        function ArrterButtonPushed(app, event)
         try
            flushinput(app.serialObj);
            flushoutput(app.serialObj);  % Vide le buffer de lecture (entrée)
            writeline(app.serialObj, 'STOP');
            app.Status.Text = 'Status: Envoi du signal STOP à Arduino...';
            pause(1);
            %response = readline(app.serialObj);
            %disp(['Réponse Arduino0: ', response]);  % Afficher la réponse pour le débogage
           
            app.running = false; % Mark the system as stopped
            stop(app.Timer); % Arrêter le timer
            app.Status.Text = 'Asservissement arrêté !';
        %else
            %app.Status.Text = 'arrêt impossible.';
        catch   
            app.Status.Text = 'Status: Failed to stop system';
        end
        end

        % Button pushed function: ChargerParametresButton
        function ChargerParametresButtonPushed(app, event)
        try 
            app.asservi = false;
            app.Lamp_2.Color = 'r';
            app.precons(2) = app.precons(1);
            app.precons(1) = app.ConsigneField.Value;
            app.Status.Text = '';
        % Affichage du statut d'envoi des données
        app.Status.Text = 'Envoi des paramètres en cours...';  % Mise à jour du statut
        disp('Envoi des paramètres en cours...');  % Affichage console pour le débogage
        
        % Vider le buffer de l'objet série avant d'envoyer les données
        flushinput(app.serialObj);  % Vide le buffer de lecture (entrée)
        flushoutput(app.serialObj);
    
        % Concaténer tous les paramètres dans une seule chaîne
        params_str = Reg_generation(app);
        % Envoi des paramètres via l'objet série déjà existant
        writeline(app.serialObj, params_str);  % Envoie tous les paramètres en une seule fois
        pause(2);  % Pause pour permettre à Arduino de traiter
        
        % Lecture de la réponse d'Arduino pour vérifier la réception
        response = readline(app.serialObj);  % ou readline(app.serialObj)
        disp("Réponse Arduino1 : " + response);  % Affichage de la réponse d'Arduino
    
        % Vérification de la réponse d'Arduino
        %if contains(response, 'Commande valide.')  % Vérifie que la réponse contient "PID_SET"
            app.Status.Text = 'Données envoyées avec succès.';  % Mise à jour du statut
            %%disp('Données envoyées avec succès.');
        %elseif contains(response, 'Commande invalide.')
            %app.Status.Text = "Echec d'envoi des données.";  % Mise à jour du statut
            %disp("Erreur lors de l'envoi des données.");
        %else
            %app.Status.Text = 'Réponse inattendue Arduino.';  % Mise à jour du statut
            %disp('Réponse inattendue Arduino.');
       % end
    
        catch ME
            % Gestion des erreurs
            app.Status.Text = "Erreur lors de l'envoi des paramètres.';  % Mise à jour du statut";
            uialert(app.UIFigure, sprintf("Choisissez un autre port"), 'Erreur Communication');
            disp(['Erreur : ', ME.message]);  % Affichage du message d'erreur
        end


        end

        % Button pushed function: Connecter
        function ConnecterButtonPushed(app, event)
           if ~strcmp(app.preport, app.port.Value)
            try
                % Initialize the serial object for communication with Arduino
                app.serialObj = serialport(app.port.Value, 115200);
                configureTerminator(app.serialObj, "LF"); % Configure the serial object to use LF terminator
                

                app.Status.Text = 'Communication Série initialisée';
                app.preport = app.port.Value;
               
            catch 
                app.Status.Text = "Échec de l'initialisation";
                app.preport = app.port.Value;
           
            end
           end
        end

        % Button pushed function: SauvegarderlesdonnesButton
        function SauvegarderlesdonnesButtonPushed(app, event)
          % fixer un intervalle de temps selon le temps d'echantillonnage:
           time = (0:size(app.commande, 2)-1)*app.Ts;
           app.increment = app.increment +1 ;
           %% chaque rangee contient dans l'odre : temps - consigne - commande(tension) - t1 - t2 - t3 - t3 estimer
           app.matrice = [time(:) app.consigne(:) app.commande(:) app.T1(:) app.T2(:) app.temp3(:) app.T3_estim(:)];
           % convertir en tableau puis leur donnée un nom
           T = array2table(app.matrice);
           T.Properties.VariableNames = ["Temps", "Consigne", "Commande", "T1", "T2", "T3", "T3 estimé"];
           app.Status.Text = 'Données sauvegardées !';
           %% convertir en excel file
           %xlswrite('test.xlsx',app.matrice);
           formatSpec = 'Test%d.xlsx';
           writetable(T, sprintf(formatSpec,app.increment));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Commande u')
            xlabel(app.UIAxes2, 'Temps[s]')
            ylabel(app.UIAxes2, 'u[V]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Températures')
            xlabel(app.UIAxes, 'Temps[s]')
            ylabel(app.UIAxes, 'Température[°C]')
            zlabel(app.UIAxes, 'Z')
            app.DmarrerButton = uibutton(app.GridLayout, 'push');
            app.DmarrerButton.ButtonPushedFcn = createCallbackFcn(app, @DmarrerButtonPushed, true);
            app.DmarrerButton.Text = 'Démarrer';
            app.ArrterButton = uibutton(app.GridLayout, 'push');
            app.ArrterButton.ButtonPushedFcn = createCallbackFcn(app, @ArrterButtonPushed, true);
            app.ArrterButton.Text = 'Arrêter';
            app.ChargerParametresButton = uibutton(app.GridLayout, 'push');
            app.ChargerParametresButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerParametresButtonPushed, true);
            app.ChargerParametresButton.Text = 'Charger Paramètres';
            app.ConsignedetempratureCPanel = uipanel(app.GridLayout);
            app.ConsignedetempratureCPanel.Title = 'Consigne de température [°C] ';
            app.ConsigneEditFieldLabel = uilabel(app.ConsignedetempratureCPanel);
            app.ConsigneEditFieldLabel.Text = 'Consigne';
            app.ConsigneField = uieditfield(app.ConsignedetempratureCPanel, 'numeric');
            app.ConsigneField.Limits = [20 30];
            app.ConsigneField.Value = 25;
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'sweep7.png');
            app.TimerLabel = uilabel(app.GridLayout);
            app.TimerLabel.Text = '';
            app.TimesLabel = uilabel(app.GridLayout);
            app.TimesLabel.Text = 'Time(s)';
            app.Status = uilabel(app.GridLayout);
            app.Status.Text = 'En attente .......';
            app.Connecter = uibutton(app.GridLayout, 'push');
            app.Connecter.ButtonPushedFcn = createCallbackFcn(app, @ConnecterButtonPushed, true);
            app.Connecter.Text = 'Connecter à l''Arduino';
            app.port = uidropdown(app.GridLayout);
            app.port.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.port.Value = 'COM1';
            app.PortUSBDropDownLabel = uilabel(app.GridLayout);
            app.PortUSBDropDownLabel.Text = 'Port USB';
            app.Lamp_2 = uilamp(app.GridLayout);
            app.RgulateurPIDFPanel = uipanel(app.GridLayout);
            app.RgulateurPIDFPanel.Title = 'Régulateur PIDF';
            app.KcEditFieldLabel = uilabel(app.RgulateurPIDFPanel);
            app.KcEditFieldLabel.Text = 'Kc';
            app.KcField = uieditfield(app.RgulateurPIDFPanel, 'numeric');
            app.KcField.ValueDisplayFormat = '%11.6g';
            app.KcField.Value = 0.068218474078553;
            app.TiEditFieldLabel = uilabel(app.RgulateurPIDFPanel);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiField = uieditfield(app.RgulateurPIDFPanel, 'numeric');
            app.TiField.ValueDisplayFormat = '%11.6g';
            app.TiField.Value = 124.85342485148;
            app.TfLabel = uilabel(app.RgulateurPIDFPanel);
            app.TfLabel.Text = 'Tf';
            app.TfField = uieditfield(app.RgulateurPIDFPanel, 'numeric');
            app.TfField.ValueDisplayFormat = '%11.6g';
            app.TfField.Value = 4.3322782900504;
            app.TdLabel = uilabel(app.RgulateurPIDFPanel);
            app.TdLabel.Text = 'Td';
            app.TdField = uieditfield(app.RgulateurPIDFPanel, 'numeric');
            app.TdField.ValueDisplayFormat = '%11.6g';
            app.TdField.Value = 8.6646;
            app.Label = uilabel(app.RgulateurPIDFPanel);
            app.Label.Tooltip = {'Entrez ici la valeur de la constante de temps de la partie filtre de votre PIDF'};
            app.Label.Text = 'ⓘ';
            app.Label_2 = uilabel(app.RgulateurPIDFPanel);
            app.Label_2.Tooltip = {'Entrez ici la valeur de la constante de temps de la partie dérivatrice de votre PIDF'};
            app.Label_2.Text = 'ⓘ';
            app.Label_3 = uilabel(app.RgulateurPIDFPanel);
            app.Label_3.Tooltip = {'Entrez ici la valeur du gain proportionnel de votre PIDF'};
            app.Label_3.Text = 'ⓘ';
            app.Label_4 = uilabel(app.RgulateurPIDFPanel);
            app.Label_4.Tooltip = {'Entrez ici la valeur du gain intégral de votre PIDF'};
            app.Label_4.Text = 'ⓘ';
            app.SauvegarderlesdonnesButton = uibutton(app.GridLayout, 'push');
            app.SauvegarderlesdonnesButton.ButtonPushedFcn = createCallbackFcn(app, @SauvegarderlesdonnesButtonPushed, true);
            app.SauvegarderlesdonnesButton.Text = 'Sauvegarder les données';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Regu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end