classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThermoProUIFigure              matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Button                         matlab.ui.control.Button
        RsolutionenyEditField          matlab.ui.control.NumericEditField
        RsolutionenyEditFieldLabel     matlab.ui.control.Label
        RsolutionenxEditField          matlab.ui.control.NumericEditField
        NombredlmentenxLabel           matlab.ui.control.Label
        ChargerJSONButton              matlab.ui.control.Button
        PositiondesthermistancesPanel  matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        T3xyEditField_2                matlab.ui.control.NumericEditField
        T2xyEditField_4                matlab.ui.control.NumericEditField
        T1xyEditField_2                matlab.ui.control.NumericEditField
        T3xyEditField                  matlab.ui.control.NumericEditField
        T3xyEditFieldLabel             matlab.ui.control.Label
        T2xyEditField                  matlab.ui.control.NumericEditField
        Label_9                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        T2xyEditFieldLabel             matlab.ui.control.Label
        T1xyEditField                  matlab.ui.control.NumericEditField
        T1xyEditFieldLabel             matlab.ui.control.Label
        TempambianteCEditField         matlab.ui.control.NumericEditField
        TempambianteCEditFieldLabel    matlab.ui.control.Label
        TempinitialedelaplaqueCEditField  matlab.ui.control.NumericEditField
        TempinitialeplaqueCLabel       matlab.ui.control.Label
        ParamtrespardfautButton        matlab.ui.control.Button
        AppliquerButton                matlab.ui.control.Button
        DuresEditField                 matlab.ui.control.NumericEditField
        DuresEditFieldLabel            matlab.ui.control.Label
        ConductivitWmKEditField        matlab.ui.control.NumericEditField
        ConductivitWmKEditFieldLabel   matlab.ui.control.Label
        rhoField                       matlab.ui.control.NumericEditField
        MasseVolgcm3Label              matlab.ui.control.Label
        LargeurmmEditFieldLabel        matlab.ui.control.Label
        startButton                    matlab.ui.control.Button
        PerturbationcalorifiquePanel   matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        DbutLabel_2                    matlab.ui.control.Label
        PositionxminxmaxEditField_2Label_5  matlab.ui.control.Label
        DbutEditField_2Label_2         matlab.ui.control.Label
        PuissanceEditField_2           matlab.ui.control.NumericEditField
        PuissanceEditField_2Label      matlab.ui.control.Label
        PositionxminxmaxEditField_2Label_4  matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        PositionxminxmaxEditField_4    matlab.ui.control.NumericEditField
        EditField_5                    matlab.ui.control.NumericEditField
        PositionxminxmaxEditField_3    matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        FinEditField_2                 matlab.ui.control.NumericEditField
        DbutEditField_2                matlab.ui.control.NumericEditField
        Label_5                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        PuissancecalorifiqueTECPanel   matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        PuissanceEditField             matlab.ui.control.NumericEditField
        PuissanceEditFieldLabel        matlab.ui.control.Label
        FinEditField                   matlab.ui.control.NumericEditField
        FinLabel                       matlab.ui.control.Label
        DbutEditField                  matlab.ui.control.NumericEditField
        DbutLabel                      matlab.ui.control.Label
        Switch_2                       matlab.ui.control.Switch
        PositionxminxmaxEditField_6    matlab.ui.control.NumericEditField
        PositionxminxmaxEditField_6Label  matlab.ui.control.Label
        EditField_7                    matlab.ui.control.NumericEditField
        PositionxminxmaxEditField_5    matlab.ui.control.NumericEditField
        EditField_6                    matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        PositionxminxmaxEditField_2Label_3  matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        LargeurmmEditField             matlab.ui.control.NumericEditField
        Image                          matlab.ui.control.Image
        hField                         matlab.ui.control.NumericEditField
        CoeffdeConvectionWm2KLabel     matlab.ui.control.Label
        cpField                        matlab.ui.control.NumericEditField
        CapacitCalorifJkgKLabel        matlab.ui.control.Label
        paisseurmmEditField            matlab.ui.control.NumericEditField
        paisseurmmEditFieldLabel       matlab.ui.control.Label
        LongueurmmEditField            matlab.ui.control.NumericEditField
        LongueurmmEditFieldLabel       matlab.ui.control.Label
        stopButton                     matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        ParamtredesauvegardePanel      matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        save_succes_text               matlab.ui.control.Label
        file_name                      matlab.ui.control.EditField
        NomdufichierEditFieldLabel     matlab.ui.control.Label
        save                           matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        TempsTotal ;  % Durée de la simulation [s]
        Lx      ;     % Longueur [m]
        Ly      ;     % Largeur [m]
        epaisseur;    % Épaisseur [m]
        Nx      ;     % Nombre d'éléments en x
        Ny      ;     % Nombre d'éléments en y
        k       ;     % Conductivité thermique [W/m·K]
        rho     ;     % Densité [kg/m^3]
        cp      ;     % Chaleur spécifique [J/kg·K]
        %materiau     % Nom du matériau
        alpha   ;     % Diffusivité thermique [m^2/s] //////
        h_conv  ;     % Coefficient de convection [W/m^2·K]
        dx      ;     % Pas spatial en x //////
        dy      ;     % Pas spatial en y   //////
        %dz     ;     % epaisseur 
        Therm1_loc_x ;
        Therm1_loc_y ;
        Therm2_loc_x ;
        Therm2_loc_y ;
        Therm3_loc_x ;
        Therm3_loc_y ;
        pert_loc_x_min  ;
        pert_loc_x_max  ;
        pert_loc_y_min  ;
        pert_loc_y_max  ;
        pert_pow        ; % Puissance de la perturbation [W]
        t_pert_deb      ; % Temps début perturbation [s]
        t_pert_fin      ; % Temps fin perturbation [s]
        dt      ;  % Pas en temps [s]       //////
        Nt      ;  % Nombre d'itérations temporelles /////
        T_amb ;
        T_init  ;
        pin     ;
        pin_start_time ; % Le temps de début d'envoie de la puissance [s]
        pin_end_time   ; % Le temps de fin d'envoie de la puissance [s]
        pin_loc_x_min  ;
        pin_loc_x_max  ;
        pin_loc_y_min  ;
        pin_loc_y_max  ;
        f1_surf ;
        timeText;
        f2_t1   ;
        f2_t2   ;
        f2_t3   ;
        worker  ;
        sample_rate = 1500;
    end
    properties(Access = public)
        state = "run";
    end


    methods (Access = private)
        
         function ChargerJSONButtonPushedCallback(app, event)
           ChargerJSONButtonPushed(app, event);
       end
        % Callback function for the dedddfaultParameters Button press
        function ParamtrespardfautButtonPushedCallback(app, event)
            ParamtrespardfautButtonPushed(app, event);
        end
                % Callback function for the defaultParameters Button press
        function ChargerparamtresButtonPushedCallback(app, event)
            AppliquerButtonPushed(app, event);
        end

        function setup_graphs(app, X, Y, T)
            %% Figure de plaque
                % x= 2;
                % disp(x);
                % cla(app.UIAxes);
                % cla(app.UIAxes2);
                 delete(app.f2_t1);
                 delete(app.f2_t2);
                 delete(app.f2_t3);
            app.f1_surf = meshc(app.UIAxes2, 1000*X, 1000*Y, T);
            shading(app.UIAxes2, 'interp');
            xlabel(app.UIAxes2, 'Longueur de la plaque (mm)');
            ylabel(app.UIAxes2, 'Largeur de la plaque (mm)');
            zlabel(app.UIAxes2, 'Température en °C');
            title(app.UIAxes2, ['Température à t = ' num2str(0) ' s'],'FontSize',16);
            app.timeText = title(app.UIAxes2, ['Temps : ' num2str(0, '%.2f') ' s'], 'FontSize', 16);
            colorbar(app.UIAxes2);
            colormap(app.UIAxes2, jet);
            clim(app.UIAxes2, [10 50]);
            grid(app.UIAxes2, 'on');
            view(app.UIAxes2, 3);
            %zticks(app.UIAxes2, floor(0):1:ceil(100));
            %axis tight
            pbaspect(app.UIAxes2, [app.Lx app.Ly min([app.Lx app.Ly])]);

            %% Figure de thermistance
            hold(app.UIAxes, 'on');
            dx = app.Lx / app.Nx;
            dy = app.Ly / app.Ny;
            initTherm1 = T( (fix(app.Therm1_loc_x)/dx + 1), (fix(app.Therm1_loc_y)/dy + 1) );
            initTherm2 = T( (fix(app.Therm2_loc_x)/dx + 1), (fix(app.Therm2_loc_y)/dy + 1) );
            initTherm3 = T( (fix(app.Therm3_loc_x)/dx + 1), (fix(app.Therm3_loc_y)/dy + 1) );
            app.f2_t1 = plot(app.UIAxes, 0, initTherm1, 'r', 'DisplayName', 'Thermistance 1');
            app.f2_t2 = plot(app.UIAxes, 0, initTherm2, 'g', 'DisplayName', 'Thermistance 2');
            app.f2_t3 = plot(app.UIAxes, 0, initTherm3, 'b', 'DisplayName', 'Thermistance 3');
            grid(app.UIAxes, 'on');
            app.UIAxes.FontSize = 16;
            xlabel(app.UIAxes, 'Temps [s]', 'FontSize', 16);
            ylabel(app.UIAxes, 'Température [°C]', 'FontSize', 16);
            title(app.UIAxes, 'Température aux thermistances', 'FontSize', 16);
            legend(app.UIAxes, 'show', 'FontSize', 14, 'Location', 'best');
            hold(app.UIAxes, "off")
        end
        
        function update_graphs(app, data, dt)
                % Déclaration persistante pour stocker les données secondaires
                persistent timeData therm1Data therm2Data therm3Data updateCounter %energyAddedData energyLossData
                if app.state ~= "run"
                   if app.state == 'pause'
                        waitfor(app, 'state', "run");
                   else %app.state == 'stop'
                        timeData = [];
                        therm1Data = [];
                        therm2Data = [];
                        therm3Data = [];
                        updateCounter = [];
                        % Vider les graphiques
                        % delete(app.f2_t1);
                        % delete(app.f2_t2);
                        % delete(app.f2_t3);
                         return
                    end
                end
                %% --- Callback du DataQueue ---
                % Mise à jour du graphique 1 (surface)
                set(app.f1_surf, 'ZData', data.snapshot - 273.15);
                currentTime = data.t * dt;
                
                set(app.timeText, 'String', sprintf('Temps : %.2f s', currentTime));
               
                
                % Initialisation ou mise à jour des données persistantes
                if isempty(timeData)
                    timeData = currentTime;
                    therm1Data = data.therm1;
                    therm2Data = data.therm2;
                    therm3Data = data.therm3;
                    %energyAddedData = data.energyAdded;
                    %energyLossData = data.energyLoss;
                    updateCounter = 1;
                else
                    timeData(end+1) = currentTime;
                    if ~isempty(data.therm1)
                        therm1Data(end+1) = data.therm1;
                        therm2Data(end+1) = data.therm2;
                        therm3Data(end+1) = data.therm3;
                        %energyAddedData(end+1) = data.energyAdded;
                        %energyLossData(end+1) = data.energyLoss;
                    end
                    updateCounter = updateCounter + 1;
                end
                
                % Mise à jour des graphiques secondaires une fois sur deux
                %if mod(updateCounter, 3) == 0 && ~isempty(therm1Data)
                    set(app.f2_t1, 'XData', timeData, 'YData', therm1Data - 273.15);
                    set(app.f2_t2, 'XData', timeData, 'YData', therm2Data - 273.15);
                    set(app.f2_t3, 'XData', timeData, 'YData', therm3Data - 273.15);
                    %set(f3_add, 'XData', timeData, 'YData', energyAddedData);
                    %set(f3_loss, 'XData', timeData, 'YData', energyLossData);
                %end
                drawnow;
            end
        
        function maj_champs(app)         
            % Mets à jour les champs 

            % Paramètre dimentionnel de la plaque
            app.DuresEditField.Value            = app.TempsTotal;     % Durée de la simulation
            app.LongueurmmEditField.Value       = app.Lx*1000 ;       % Longueur [m]
            app.LargeurmmEditField.Value        = app.Ly*1000 ;       % Largeur [m]
            app.paisseurmmEditField.Value       = app.epaisseur ;     % epaisseur[m]
            app.RsolutionenxEditField.Value     = app.Nx;             % Nbr de "pixel" en x
            app.RsolutionenyEditField.Value     = app.Ny;             % Nbr de "pixel" en x
            
            % Paramètre thermique de la paque
            app.hField.Value                    = app.h_conv;  % Coeff. de convection[W/m^2·K]
            app.cpField.Value                   = app.cp;      % Chaleur spécifique  [J/kg·K]
            app.rhoField.Value                  = app.rho ;    % Densité [kg/m^3]
            app.ConductivitWmKEditField.Value   = app.k;       % Conductivité Thermique [W/m·K]

            % Conditions initiales
            app.TempinitialedelaplaqueCEditField.Value  = app.T_init;   % Température initialle de la plaque en degree celcius
            app.TempambianteCEditField.Value            = app.T_amb;  % Température ambiante en degree celcius

            % Position des thermistances
            app.T1xyEditField.Value         = app.Therm1_loc_x;   % Localisation de la thermistance 1 sur x [m]
            app.T1xyEditField_2.Value       = app.Therm1_loc_y;   % Localisation de la thermistance 1 sur y [m]
            app.T2xyEditField.Value         = app.Therm2_loc_x;   % Localisation de la thermistance 2 sur x [m]
            app.T2xyEditField_4.Value       = app.Therm2_loc_y;   % Localisation de la thermistance 2 sur y [m]
            app.T3xyEditField.Value         = app.Therm3_loc_x;   % Localisation de la thermistance 3 sur x [m]
            app.T3xyEditField_2.Value       = app.Therm3_loc_y;   % Localisation de la thermistance 3 sur y [m]
    
            % Paramètres de Puissance
            app.PuissanceEditField.Value            = app.pin;              % Puissance [W] (example: 5 W localized input)
            app.DbutEditField.Value                 = app.pin_start_time;   % Moment du début de la dissipation calorifique [s]
            app.FinEditField.Value                  = app.pin_end_time;     % Moment de fin de la dissipation calorifique [s]
            app.PositionxminxmaxEditField_6.Value   = app.pin_loc_x_min;    %Localisation de la puissance en x - min
            app.EditField_6.Value                   = app.pin_loc_x_max;    %Localisation de la puissance en x - min
            app.PositionxminxmaxEditField_5.Value   = app.pin_loc_y_min;    %Localisation de la puissance en y - min
            app.EditField_7.Value                   = app.pin_loc_y_max;    %Localisation de la puissance en y - max
            
            % Paramètre de Perturbation
            app.PositionxminxmaxEditField_4.Value   = app.pert_loc_x_min;
            app.EditField_4.Value                   = app.pert_loc_x_max;
            app.PositionxminxmaxEditField_3.Value   = app.pert_loc_y_min;
            app.EditField_5.Value                   = app.pert_loc_y_max;
            app.PuissanceEditField_2.Value          = app.pert_pow; % À réviser  V^2/R
            if isnan(app.t_pert_deb)
                app.DbutEditField_2.Value = [];
                app.FinEditField_2.Value = [];
            else
                app.DbutEditField_2.Value           = app.t_pert_deb; %% Metre des valeur ic
            end
            if isnan(app.t_pert_fin)
               app.FinEditField_2.Value = [];
            else
                app.FinEditField_2.Value            = app.t_pert_fin; 

            end
        end
        function UICloseRequest(app, event)
            % Vérifier s'il y a un pool actif
            pool = gcp('nocreate');
            poolMessage = ''; % Initialiser le message additionnel
                
            if ~isempty(pool)
                poolMessage = '⚠ Un pool de workers est actif et sera fermé.               ';
            end
                
            % Construire le message de confirmation
            confirmationMessage = sprintf('%sVoulez-vous vraiment quitter l''application ?', poolMessage);
                
            % Afficher la boîte de confirmation avec le message mis à jour
            selection = uiconfirm(app.ThermoProUIFigure, confirmationMessage, ...
                'Confirmation', 'Options', {'Oui', 'Non'}, 'DefaultOption', 2);
                
            % Si l'utilisateur choisit "Oui"
            if strcmp(selection, 'Oui')
                if ~isempty(pool)
                    delete(pool); % Ferme le pool
                end
                delete(app); % Ferme l'application proprement
            end
        end
    end
    methods (Static)

        function ispause = check_if_pause()
            ispause = false;
            %if app.state ~= false
               % ispause = app.state;
            %end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Statup_fct(app)
            app.ThermoProUIFigure.CloseRequestFcn = @(src, event) UICloseRequest(app, event);
            ParamtrespardfautButtonPushed(app, true)
            pool = gcp('nocreate');
            if isempty(pool)
                pool = parpool('local', 1);
            end  
        end

        % Button pushed function: ChargerJSONButton
        function ChargerJSONButtonPushed(app, event)
            % Ouvrir une boîte de dialogue pour sélectionner un fichier JSON
            [file, path] = uigetfile('*.json', 'Sélectionnez un fichier JSON');
        
            % Vérifier si l'utilisateur a annulé la sélection
            if isequal(file, 0)
                disp('❌ Sélection annulée.');
                return;
            end
        
            % Construire le chemin complet du fichier sélectionné
            fichierSource = fullfile(path, file);
            
            % Définir le chemin de destination (dossier actuel)
            fichierDestination = fullfile(pwd, 'param.json');
        
            % Copier le fichier dans le dossier actuel sous le nom param.json
            copyfile(fichierSource, fichierDestination);
        end

        % Button pushed function: ParamtrespardfautButton
        function ParamtrespardfautButtonPushed(app, event)
            %% Lire le contenu du fichier JSON
            fid = fopen('default.json', 'r');
            raw = fread(fid, inf, 'uint8=>char')';
            fclose(fid);
                
            %% Décoder le JSON en structure MATLAB
            params = jsondecode(raw);
        
            %% Accéder aux valeurs
            app.TempsTotal =    params.simulation.TempsTotal;     % Durée de la simulation
            app.Lx =            params.geometrie.Lx;       % Longueur [m]
            app.Ly =            params.geometrie.Ly;       % Largeur [m]
            app.epaisseur =     params.geometrie.epaisseur;     % epaisseur[m]
        
            app.Nx =            params.grille.Nx;      % Nombre d'élements en x
            app.Ny =            params.grille.Ny;      % Nombre d'élements en y

            %app.dx =            app.Lx / app.Nx; %%%%   ////////
            %app.dy =            app.Ly / app.Ny; %%%%   ////////
            %app.dz =            app.epaisseur; %%%%   ////////
        
            app.k =             params.materiau.k;      % Conductivité Thermique [W/m·K]
            app.rho =           params.materiau.rho;  % Densité [kg/m^3]
            app.cp =            params.materiau.cp;    % Chaleur spécifique  [J/kg·K]
            %app.alpha =         app.k/(app.rho*app.cp);    % Diffusivité Thermique [m^2/s] %%%%   ////////
            %app.materiau = params.materiau.nom; % Nom du matériau
            %app.couplage_tec = params.puissance.couplage_TEC; % Coefficient de couplage avec le TEC
        
            %% Paramètres de Convection
            app.h_conv =        params.conditions_limites.h_conv;  % Coeff. de convection[W/m^2·K]
        
            %% Localisation des thermistances
            app.Therm1_loc_x =  params.conditions_initiales.Therm1_loc_x ;   % Localisation de la thermistance sur x [m]
            app.Therm1_loc_y =  params.conditions_initiales.Therm1_loc_y ;   % Localisation de la thermistance sur y [m]
            app.Therm2_loc_x =  params.conditions_initiales.Therm2_loc_x ;   % Localisation de la thermistance sur x [m]
            app.Therm2_loc_y =  params.conditions_initiales.Therm2_loc_y ;   % Localisation de la thermistance sur y [m]
            app.Therm3_loc_x =  params.conditions_initiales.Therm3_loc_x ;   % Localisation de la thermistance sur x [m]
            app.Therm3_loc_y =  params.conditions_initiales.Therm3_loc_y ;   % Localisation de la thermistance sur y [m]
        
            %% Traitement des valeurs de localisation des thermistances (JE CROIS QUE C'EST À RETIRER ÇA)
%%%%%%%%%% Pourquoi on les regroupe ici, dans le Json sont dégroupé et on doit les dégrouper
                 % après pour les utiliser anyways?
            %app.Therm1_loc =    [(fix(app.Therm1_loc_x/app.dx) + 1) ,(fix(app.Therm1_loc_y/app.dy) + 1)] ;   % Position de la mesure de température(Thermistance1) %%%%   ////////
            %app.Therm2_loc =    [(fix(app.Therm2_loc_x/app.dx) + 1) ,(fix(app.Therm2_loc_y/app.dy) + 1)] ;   % Position de la mesure de température(Thermistance2) %%%%   ////////
            %app.Therm3_loc =    [(fix(app.Therm3_loc_x/app.dx) + 1) ,(fix(app.Therm3_loc_y/app.dy) + 1)] ;   % Position de la mesure de température(Thermistance3) %%%%   ////////
         
             %% Paramètres de perturbations 
            app.pert_loc_x_min =    params.pertub.pert_loc_x_min;
            %app.pert_loc_x_min =    fix(app.pert_loc_x_min/app.dx)+1; %%%%   ////////
            app.pert_loc_x_max =    params.pertub.pert_loc_x_max;
            %app.pert_loc_x_max =    fix(app.pert_loc_x_max/app.dx)+1; %%%%   ////////
            app.pert_loc_y_min =    params.pertub.pert_loc_y_min;
            %app.pert_loc_y_min =    fix(app.pert_loc_y_min/app.dy)+1; %%%%   ////////
            app.pert_loc_y_max =    params.pertub.pert_loc_y_max;
            %app.pert_loc_y_max =    fix(app.pert_loc_y_max/app.dy)+1; %%%%   ////////
            app.pert_pow =          params.pertub.pert_pow;
            app.t_pert_deb = params.pertub.t_pert_deb;
            if isnan(app.t_pert_deb)
                app.t_pert_deb = [];
            end
            app.t_pert_fin = params.pertub.t_pert_fin;
            if isnan(app.t_pert_fin)
                app.t_pert_fin = [];
            end
            
            %% Paramètres de Puissance
            app.pin =               params.puissance.pin ;  % Puissance [W] (example: 5 W localized input)
            app.pin_start_time =    params.puissance.pin_start_time ; % Moment du début de la dissipation calorifique [s]
            if isnan(app.pin_start_time)
                app.pin_start_time = 0;
            end
            app.pin_end_time =      params.puissance.pin_end_time ;   % Moment de fin de la dissipation calorifique [s]
            if isnan(app.pin_end_time)
                app.pin_end_time = [];
            end
            app.pin_loc_x_min =     params.puissance.pin_loc_x_min;      %Localisation de la puissance en x - min
            %app.pin_loc_x_min =     (fix(app.pin_loc_x_min/app.dx)+ 1); %%%%   ////////
        
            app.pin_loc_x_max =     params.puissance.pin_loc_x_max;      %Localisation de la puissance en x - min
            %app.pin_loc_x_max =     (fix(app.pin_loc_x_max/app.dx)+ 1); %%%%   ////////
        
            app.pin_loc_y_min =     params.puissance.pin_loc_y_min;      %Localisation de la puissance en y - min
            %app.pin_loc_y_min =     (fix(app.pin_loc_y_min/app.dy) + 1); %%%%   ////////
        
            app.pin_loc_y_max =     params.puissance.pin_loc_y_max;      %Localisation de la puissance en y - max
            %app.pin_loc_y_max =     (fix(app.pin_loc_y_max/app.dy) + 1); %%%%   ////////
        
            app.T_amb =             params.conditions_initiales.T_amb ;% Température pièce en Celsius
            app.T_init =            params.conditions_initiales.T_init;
            %% Enregistrement dans le json
            % Charger le fichier JSON
%%%%%% Il faudrait aussi que ça affiche les valeurs pas défaut dans
%%%%%% l'interface quand on les charge
            fid = fopen('param.json', 'r');
            raw = fread(fid, inf, 'uint8=>char')';
            fclose(fid);
            param = jsondecode(raw);
            
            % Modifier les valeurs du dictionnaire avec tes variables
            param.simulation.TempsTotal =       app.TempsTotal;
            param.geometrie.Lx =                app.Lx;
            param.geometrie.Ly =                app.Ly;
            param.geometrie.epaisseur =         app.epaisseur;
            
            param.grille.Nx =                   app.Nx;
            param.grille.Ny =                   app.Ny;
            
            param.materiau.k =                  app.k;
            param.materiau.rho =                app.rho;
            param.materiau.cp =                 app.cp;
            param.conditions_limites.h_conv =   app.h_conv ;
            %param.materiau.nom = app.materiau;
            %param.puissance.couplage_TEC = app.couplage_tec;
            
            param.conditions_initiales.Therm1_loc_x =   app.Therm1_loc_x;
            param.conditions_initiales.Therm1_loc_y =   app.Therm1_loc_y;
            param.conditions_initiales.Therm2_loc_x =   app.Therm2_loc_x;
            param.conditions_initiales.Therm2_loc_y =   app.Therm2_loc_y;
            param.conditions_initiales.Therm3_loc_x =   app.Therm3_loc_x;
            param.conditions_initiales.Therm3_loc_y =   app.Therm3_loc_y;
            param.conditions_initiales.T_amb =        app.T_amb  ;
            param.conditions_initiales.T_init =         app.T_init ;

%%%%% Point à valider, pourquoi on utilise les champs pour les paramètre
%%%%% par défaut ? %%%%%%%%
            param.pertub.pert_loc_x_min =       app.pert_loc_x_min;
            param.pertub.pert_loc_x_max =       app.pert_loc_x_max;
            param.pertub.pert_loc_y_min =       app.pert_loc_y_min;
            param.pertub.pert_loc_y_max =       app.pert_loc_y_max;
            param.pertub.pert_pow =             app.pert_pow;
%%%%%%%% Maybe à remettre
            %param.pertub.t_pert_deb = app.t_pert_deb;
            %param.pertub.t_pert_fin = app.t_pert_fin;

            param.puissance.pin =               app.pin ;
%%% Je crois qu'il faut changer comment on attribue les variable ici, genre
%%% utiliser les champs qu'on va ajouter
            param.puissance.pin_start_time =    app.pin_start_time;
            param.puissance.pin_end_time =      app.pin_end_time;
            param.puissance.pin_loc_x_min =     app.pin_loc_x_min;
            param.puissance.pin_loc_x_max =     app.pin_loc_x_max;
            param.puissance.pin_loc_y_min =     app.pin_loc_y_min;
            param.puissance.pin_loc_y_max =     app.pin_loc_y_max;
            
            % Enregistrer les modifications dans le fichier JSON
            fid = fopen('param.json', 'w');
            fprintf(fid, '%s', jsonencode(param, 'PrettyPrint', true));
            fclose(fid);
            maj_champs(app);
        end

        % Button pushed function: AppliquerButton
        function AppliquerButtonPushed(app, event)
%%%%% Maybe changer le nom pour "appliquer les paramètres"           
            %% Accéder aux valeurs
            app.TempsTotal =    app.DuresEditField.Value ;     % Durée de la simulation
            app.Lx =            app.LongueurmmEditField.Value /1000 ;       % Longueur [m]
            app.Ly =            app.LargeurmmEditField.Value  /1000 ;       % Largeur [m]
            app.epaisseur =     app.paisseurmmEditField.Value ;     % epaisseur[m]
%%%% Ici faudrait donner la possibilité au user de changer ça, mettre un
%%%% boutons et non le calculer
            app.Nx =            app.RsolutionenxEditField.Value;      % Nombre d'élements en x 
            app.Ny =            app.RsolutionenyEditField.Value;      % Nombre d'élements en y
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.k =             app.ConductivitWmKEditField.Value;      % Conductivité Thermique [W/m·K]
            app.rho =           app.rhoField.Value;  % Densité [kg/m^3]
            app.cp =            app.cpField.Value ;    % Chaleur spécifique  [J/kg·K]
            %app.alpha =         app.k/(app.rho*app.cp);    % Diffusivité Thermique [m^2/s] %%%%   ////////
            %app.materiau =  ; % Nom du matériau
            %app.couplage_tec = ; % Coefficient de couplage avec le TEC
    
            %% Paramètres de Convection
            app.h_conv =        app.hField.Value;  % Coeff. de convection[W/m^2·K]
    
            %% Localisation des thermistances (C'est quoi les app.T1xyEditFeild.value? )
            app.Therm1_loc_x =      app.T1xyEditField.Value ;   % Localisation de la thermistance sur x [m]
            app.Therm1_loc_y =      app.T1xyEditField_2.Value ;   % Localisation de la thermistance sur y [m]
            app.Therm2_loc_x =      app.T2xyEditField.Value ;   % Localisation de la thermistance sur x [m]
            app.Therm2_loc_y =      app.T2xyEditField_4.Value ;   % Localisation de la thermistance sur y [m]
            app.Therm3_loc_x =      app.T3xyEditField.Value ;   % Localisation de la thermistance sur x [m]
            app.Therm3_loc_y =      app.T3xyEditField_2.Value ;   % Localisation de la thermistance sur y [m]
    
            %% Traitement des valeurs de localisation des thermistances
%%%%%% Ici, pourquoi on les rassemble si on les sépare plus tard
            %app.Therm1_loc =        [(fix(app.Therm1_loc_x/app.dx) + 1) ,(fix(app.Therm1_loc_y/app.dy) + 1)] ;   % Position de la mesure de température(Thermistance1)%%%%   ////////
            %app.Therm2_loc =        [(fix(app.Therm2_loc_x/app.dx) + 1) ,(fix(app.Therm2_loc_y/app.dy) + 1)] ;   % Position de la mesure de température(Thermistance2)%%%%   ////////
            %app.Therm3_loc =        [(fix(app.Therm3_loc_x/app.dx) + 1) ,(fix(app.Therm3_loc_y/app.dy) + 1)] ;   % Position de la mesure de température(Thermistance3)%%%%   ////////
    
             %% Paramètres de perturbations 
            app.pert_loc_x_min =    app.PositionxminxmaxEditField_4.Value ;
            %app.pert_loc_x_min =    fix(app.pert_loc_x_min/app.dx)+1; %%%%   ////////
            app.pert_loc_x_max =    app.EditField_4.Value;
            %app.pert_loc_x_max =    fix(app.pert_loc_x_max/app.dx)+1; %%%%   ////////
            app.pert_loc_y_min =    app.PositionxminxmaxEditField_3.Value;
            %app.pert_loc_y_min =    fix(app.pert_loc_y_min/app.dy)+1; %%%%   ////////
            app.pert_loc_y_max =    app.EditField_5.Value;
            %app.pert_loc_y_max =    fix(app.pert_loc_y_max/app.dy)+1; %%%%   ////////
            app.pert_pow =          app.PuissanceEditField_2.Value; % À réviser  V^2/R
            app.t_pert_deb =        app.DbutEditField_2.Value; %% Metre des valeur ic
            app.t_pert_fin =        app.FinEditField_2.Value;
    
    
        %% Paramètres de Puissance
            app.pin =               app.PuissanceEditField.Value; %* app.couplage_tec ;  %%%%   ////////     % Puissance [W] (example: 5 W localized input)
%%%% J'ai remis le pin start et end time même si on va avoir un boutons 
% Il faut modifier les valeurs qu'on y attribue parce qu'il faut add un
% feild
            app.pin_start_time =    app.DbutEditField.Value ; % Moment du début de la dissipation calorifique [s]
            app.pin_end_time =      app.FinEditField.Value ;   % Moment de fin de la dissipation calorifique [s]

            app.pin_loc_x_min =     app.PositionxminxmaxEditField_6.Value ; %Localisation de la puissance en x - min
            %app.pin_loc_x_min =     (fix(app.pin_loc_x_min/app.dx)+ 1);%%%%   ////////
            app.pin_loc_x_max =     app.EditField_6.Value;                  %Localisation de la puissance en x - min
            %app.pin_loc_x_max =     (fix(app.pin_loc_x_max/app.dx)+ 1);%%%%   ////////
        
            app.pin_loc_y_min =     app.PositionxminxmaxEditField_5.Value;  %Localisation de la puissance en y - min
            %app.pin_loc_y_min =     (fix(app.pin_loc_y_min/app.dy) + 1);%%%%   ////////
        
            app.pin_loc_y_max =     app.EditField_7.Value;                  %Localisation de la puissance en y - max
            %app.pin_loc_y_max =     (fix(app.pin_loc_y_max/app.dy) + 1);%%%%   ////////
        
            app.T_amb =           app.TempambianteCEditField.Value ;  % Température pièce en Celsius
            app.T_init =            app.TempinitialedelaplaqueCEditField.Value; % Température initiale de la plaque
        
    
            %% Enregistrement dans le json
               % Charger le fichier JSON
            fid = fopen('param.json', 'r');
            raw = fread(fid, inf, 'uint8=>char')';
            fclose(fid);
            param = jsondecode(raw);
            
            % Modifier les valeurs du dictionnaire avec tes variables
            param.simulation.TempsTotal =       app.TempsTotal;
            param.geometrie.Lx =                app.Lx;
            param.geometrie.Ly =                app.Ly;
            param.geometrie.epaisseur =         app.epaisseur;
            
            param.grille.Nx =                   app.Nx;
            param.grille.Ny =                   app.Ny;
            
            param.materiau.k =                  app.k;
            param.materiau.rho =                app.rho;
            param.materiau.cp =                 app.cp;
            param.conditions_limites.h_conv =   app.h_conv ;
            %param.materiau.nom = app.materiau;
            
            %param.puissance.couplage_TEC = app.couplage_tec;
            
            param.conditions_initiales.Therm1_loc_x =   app.Therm1_loc_x;
            param.conditions_initiales.Therm1_loc_y =   app.Therm1_loc_y;
            param.conditions_initiales.Therm2_loc_x =   app.Therm2_loc_x;
            param.conditions_initiales.Therm2_loc_y =   app.Therm2_loc_y;
            param.conditions_initiales.Therm3_loc_x =   app.Therm3_loc_x;
            param.conditions_initiales.Therm3_loc_y =   app.Therm3_loc_y;
            param.conditions_initiales.T_amb =        app.T_amb  ;
            param.conditions_initiales.T_init =         app.T_init ;
            
            param.pertub.pert_loc_x_min =               app.PositionxminxmaxEditField_4.Value;
            param.pertub.pert_loc_x_max =               app.EditField_6.Value;
            param.pertub.pert_loc_y_min =               app.PositionxminxmaxEditField_5.Value;
            param.pertub.pert_loc_y_max =               app.EditField_7.Value;
            param.pertub.pert_pow =                     app.pert_pow;
%%% Ajuster si on décide de remmetre ces params là
            param.pertub.t_pert_deb =                   app.t_pert_deb;
            param.pertub.t_pert_fin =                   app.t_pert_fin;

            param.puissance.pin =                       app.pin ;
            param.puissance.pin_start_time =            app.pin_start_time ;
            param.puissance.pin_end_time =              app.pin_end_time ;
            param.puissance.pin_loc_x_min =             app.PositionxminxmaxEditField_6.Value;
            param.puissance.pin_loc_x_max =             app.EditField_6.Value;
            param.puissance.pin_loc_y_min =             app.PositionxminxmaxEditField_5.Value;
            param.puissance.pin_loc_y_max =             app.EditField_7.Value;
            
            % Enregistrer les modifications dans le fichier JSON
            fid = fopen('param.json', 'w');
            fprintf(fid, '%s', jsonencode(param, 'PrettyPrint', true));
            fclose(fid);
            maj_champs(app);
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            cancel(app.worker);
            app.state = "stop";
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'Redémarrer ▶' ;
            app.startButton.BackgroundColor = [0 1 0]; 
            app.Button.Enable = 'off';
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '❚❚' ;
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            if app.state == "stop"
                app.update_graphs([], []);
            end
            app.state = "run";
            dx      = app.Lx / app.Nx;
            dy      = app.Ly / app.Ny;
            alpha   = app.k / (app.rho * app.cp);
            app.dt      = (1/(4*alpha)) * (dx^2 * dy^2) / (dx^2 + dy^2);
            dt = app.dt;
            x       = ((1:app.Nx) - 0.5) .* dx;
            y       = ((1:app.Ny) - 0.5) .* dy;
            [Y, X]  = meshgrid(y, x);% Utilisation d'un seul worker pour cette simulation
            T       = app.T_init .* ones(app.Nx, app.Ny);
            pool = gcp('nocreate');
            if isempty(pool)
                pool = parpool('local', 1);
            end  
            % if app.startButton.Text ~= 'Démarrer ▶'
            %     app.state = "stop";
            %     app.update_graphs(app, [], []);
            % end
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.startButton.Text = 'Arrêter ⬛';
            app.Button.Enable = 'on';
            app.save_succes_text.Text = '';
            app.startButton.BackgroundColor = [1 0 0]; 
            app.setup_graphs(X, Y, T);
             %% Création du DataQueue pour communication depuis le worker
             dq = parallel.pool.DataQueue;
             afterEach(dq, @(data) app.update_graphs(data, dt));
             
             %% Lancement de la simulation en arrière-plan via parfeval
             snapshotPeriod = app.sample_rate;  % Fréquence de snapshot (inchangée)
             app.worker = parfeval(@calcul_sim, 0, "param.json", snapshotPeriod, dq);
             wait(app.worker);
            app.Button.Enable = 'off';
            app.state = "stop";
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'Redémarrer ▶' ;
            app.startButton.BackgroundColor = [0 1 0];
        end

        % Value changed function: T2xyEditField_4
        function startupFcn(app, event)
        %%%%%%%%%% CECI N'EST PAS LA STARTUP APP, C'EST STARTUP APP 2 PARCE
        %%%%%%%%%% QUE MATLAB EST WACKO
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.state = "pause";
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button.Text = '▶' ;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.state = "run";
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '❚❚' ;
        end

        % Button pushed function: save
        function saveButtonPushed(app, event)
            plots = findobj(app.UIAxes, 'Type', 'line');  % Trouve toutes les courbes dans ax
            
            Time_data = get(plots(1), 'XData');
                Time_data = Time_data(:);
            T3_data = get(plots(1), 'YData');
                T3_data = T3_data(:);
            T2_data = get(plots(2), 'YData');
                T2_data = T2_data(:);
            T1_data = get(plots(3), 'YData');
                T1_data = T1_data(:);

            Puissance_TEC = zeros(size(Time_data));
                pin_start_index = max(1, min(round(app.pin_start_time / (app.dt*app.sample_rate)), size(Time_data, 1)) );
                if isempty(app.pin_end_time)
                    pin_end_index = size(Time_data, 1);
                else
                    pin_end_index =  max(1, min(round(app.pin_end_time / (app.dt*app.sample_rate)), size(Time_data, 1)) );
                end
                Puissance_TEC(pin_start_index: pin_end_index) = app.pin;
                
            Puissance_PERT = zeros(size(Time_data));
                pert_start_index =  max(1, min(round(app.t_pert_deb / (app.dt*app.sample_rate)), size(Time_data, 1)) );
                if isempty(app.t_pert_fin)
                    pert_end_index = size(Time_data, 1);
                else
                    pert_end_index =  max(1, min(round(app.t_pert_fin / (app.dt*app.sample_rate)), size(Time_data, 1)) );
                end
                Puissance_PERT(pert_start_index:pert_end_index) = app.pert_pow;

             Puissance_tot = Puissance_PERT + Puissance_TEC;

            if endsWith(app.file_name.Value, '.csv')
                file_name = app.file_name.Value;
            else
                file_name = sprintf('%s.csv', app.file_name.Value);
            end
            DATA = table(Time_data, T1_data, T2_data, T3_data, Puissance_tot, 'VariableNames', {'Temps', 'T1', 'T2', 'T3', 'Puissance'});
            writetable(DATA, file_name);
            app.save_succes_text.Text = 'Sauvegardé avec succès.';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ThermoProUIFigure = uifigure('Visible', 'off');
            app.ThermoProUIFigure.Name = 'ThermoPro';
            app.GridLayout = uigridlayout(app.ThermoProUIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Distribution de la température sur la plaque🌡')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Température aux thermistances🌡')
            xlabel(app.UIAxes, 'Temps [s]')
            ylabel(app.UIAxes, 'Température [°C]')
            zlabel(app.UIAxes, 'Z')
            app.ParamtredesauvegardePanel = uipanel(app.GridLayout);
            app.ParamtredesauvegardePanel.Title = 'Paramètre de sauvegarde';
            app.GridLayout5 = uigridlayout(app.ParamtredesauvegardePanel);
            app.save = uibutton(app.GridLayout5, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.save.Text = 'Sauvegarder';
            app.NomdufichierEditFieldLabel = uilabel(app.GridLayout5);
            app.NomdufichierEditFieldLabel.Text = 'Nom du fichier';
            app.file_name = uieditfield(app.GridLayout5, 'text');
            app.file_name.Value = 'simulation_data.csv';
            app.save_succes_text = uilabel(app.GridLayout5);
            app.save_succes_text.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '▶';
            app.stopButton = uibutton(app.GridLayout, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'Arrêter ⬛';
            app.LongueurmmEditFieldLabel = uilabel(app.GridLayout);
            app.LongueurmmEditFieldLabel.Text = 'Longueur [mm]';
            app.LongueurmmEditField = uieditfield(app.GridLayout, 'numeric');
            app.LongueurmmEditField.Limits = [0.001 Inf];
            app.LongueurmmEditField.Value = 290;
            app.paisseurmmEditFieldLabel = uilabel(app.GridLayout);
            app.paisseurmmEditFieldLabel.Text = 'Épaisseur [mm]';
            app.paisseurmmEditField = uieditfield(app.GridLayout, 'numeric');
            app.paisseurmmEditField.Limits = [0.001 Inf];
            app.paisseurmmEditField.Value = 0.001;
            app.CapacitCalorifJkgKLabel = uilabel(app.GridLayout);
            app.CapacitCalorifJkgKLabel.Text = 'Capacité Calorif. [J/kg·K]';
            app.cpField = uieditfield(app.GridLayout, 'numeric');
            app.cpField.Limits = [0 Inf];
            app.CoeffdeConvectionWm2KLabel = uilabel(app.GridLayout);
            app.CoeffdeConvectionWm2KLabel.Text = 'Coeff. de Convection [W/(m^2·K]';
            app.hField = uieditfield(app.GridLayout, 'numeric');
            app.hField.Limits = [0 Inf];
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
            app.LargeurmmEditField = uieditfield(app.GridLayout, 'numeric');
            app.LargeurmmEditField.Limits = [0.001 Inf];
            app.LargeurmmEditField.Value = 290;
            app.PuissancecalorifiqueTECPanel = uipanel(app.GridLayout);
            app.PuissancecalorifiqueTECPanel.Title = 'Puissance calorifique (TEC)';
            app.GridLayout3 = uigridlayout(app.PuissancecalorifiqueTECPanel);
            app.Label_3 = uilabel(app.GridLayout3);
            app.Label_3.Text = ',';
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = ',';
            app.PositionxminxmaxEditField_2Label_3 = uilabel(app.GridLayout3);
            app.PositionxminxmaxEditField_2Label_3.Text = 'Temps d''application de puissance';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = ';';
            app.EditField_6 = uieditfield(app.GridLayout3, 'numeric');
            app.PositionxminxmaxEditField_5 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_7 = uieditfield(app.GridLayout3, 'numeric');
            app.PositionxminxmaxEditField_6Label = uilabel(app.GridLayout3);
            app.PositionxminxmaxEditField_6Label.Text = 'Position [xmin, xmax; ymin, ymax] [mm]';
            app.PositionxminxmaxEditField_6 = uieditfield(app.GridLayout3, 'numeric');
            app.Switch_2 = uiswitch(app.GridLayout3, 'slider');
            app.Switch_2.Items = {'O', 'I'};
            app.Switch_2.Value = 'O';
            app.DbutLabel = uilabel(app.GridLayout3);
            app.DbutLabel.Text = 'Début :';
            app.DbutEditField = uieditfield(app.GridLayout3, 'numeric');
            app.FinLabel = uilabel(app.GridLayout3);
            app.FinLabel.Text = 'Fin :';
            app.FinEditField = uieditfield(app.GridLayout3, 'numeric');
            app.FinEditField.AllowEmpty = 'on';
            app.PuissanceEditFieldLabel = uilabel(app.GridLayout3);
            app.PuissanceEditFieldLabel.Text = 'Puissance';
            app.PuissanceEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PerturbationcalorifiquePanel = uipanel(app.GridLayout);
            app.PerturbationcalorifiquePanel.Title = 'Perturbation calorifique';
            app.GridLayout4 = uigridlayout(app.PerturbationcalorifiquePanel);
            app.Label_4 = uilabel(app.GridLayout4);
            app.Label_4.Text = ';';
            app.Label_6 = uilabel(app.GridLayout4);
            app.Label_6.Text = ',';
            app.Label_5 = uilabel(app.GridLayout4);
            app.Label_5.Text = ',';
            app.DbutEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.DbutEditField_2.AllowEmpty = 'on';
            app.FinEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.FinEditField_2.AllowEmpty = 'on';
            app.EditField_4 = uieditfield(app.GridLayout4, 'numeric');
            app.PositionxminxmaxEditField_3 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_5 = uieditfield(app.GridLayout4, 'numeric');
            app.PositionxminxmaxEditField_4 = uieditfield(app.GridLayout4, 'numeric');
            app.Switch = uiswitch(app.GridLayout4, 'slider');
            app.Switch.Items = {'O', 'I'};
            app.Switch.Value = 'O';
            app.PositionxminxmaxEditField_2Label_4 = uilabel(app.GridLayout4);
            app.PositionxminxmaxEditField_2Label_4.Text = 'Position [xmin, xmax; ymin, ymax] [mm]';
            app.PuissanceEditField_2Label = uilabel(app.GridLayout4);
            app.PuissanceEditField_2Label.Text = 'Puissance';
            app.PuissanceEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.DbutEditField_2Label_2 = uilabel(app.GridLayout4);
            app.DbutEditField_2Label_2.Text = 'Fin :';
            app.PositionxminxmaxEditField_2Label_5 = uilabel(app.GridLayout4);
            app.PositionxminxmaxEditField_2Label_5.Text = 'Temps d''application de perturbation';
            app.DbutLabel_2 = uilabel(app.GridLayout4);
            app.DbutLabel_2.Text = 'Début :';
            app.startButton = uibutton(app.GridLayout, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'Démarrer ▶ ';
            app.LargeurmmEditFieldLabel = uilabel(app.GridLayout);
            app.LargeurmmEditFieldLabel.Text = 'Largeur [mm]';
            app.MasseVolgcm3Label = uilabel(app.GridLayout);
            app.MasseVolgcm3Label.Text = 'Masse Vol. [g/cm^3]';
            app.rhoField = uieditfield(app.GridLayout, 'numeric');
            app.rhoField.Limits = [0 Inf];
            app.ConductivitWmKEditFieldLabel = uilabel(app.GridLayout);
            app.ConductivitWmKEditFieldLabel.Text = 'Conductivité [W/m·K]';
            app.ConductivitWmKEditField = uieditfield(app.GridLayout, 'numeric');
            app.ConductivitWmKEditField.Limits = [0 Inf];
            app.DuresEditFieldLabel = uilabel(app.GridLayout);
            app.DuresEditFieldLabel.Text = 'Durée [s]';
            app.DuresEditField = uieditfield(app.GridLayout, 'numeric');
            app.DuresEditField.Limits = [0.001 Inf];
            app.DuresEditField.Value = 0.001;
            app.AppliquerButton = uibutton(app.GridLayout, 'push');
            app.AppliquerButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButtonPushed, true);
            app.AppliquerButton.Text = 'Appliquer';
            app.ParamtrespardfautButton = uibutton(app.GridLayout, 'push');
            app.ParamtrespardfautButton.ButtonPushedFcn = createCallbackFcn(app, @ParamtrespardfautButtonPushed, true);
            app.ParamtrespardfautButton.Text = 'Paramètres par défaut ';
            app.TempinitialeplaqueCLabel = uilabel(app.GridLayout);
            app.TempinitialeplaqueCLabel.Text = 'Temp. initiale de la plaque [°C]';
            app.TempinitialedelaplaqueCEditField = uieditfield(app.GridLayout, 'numeric');
            app.TempinitialedelaplaqueCEditField.Limits = [-273.15 Inf];
            app.TempambianteCEditFieldLabel = uilabel(app.GridLayout);
            app.TempambianteCEditFieldLabel.Text = 'Temp. ambiante [°C]';
            app.TempambianteCEditField = uieditfield(app.GridLayout, 'numeric');
            app.TempambianteCEditField.Limits = [-273.15 Inf];
            app.PositiondesthermistancesPanel = uipanel(app.GridLayout);
            app.PositiondesthermistancesPanel.Title = 'Position des thermistances';
            app.GridLayout2 = uigridlayout(app.PositiondesthermistancesPanel);
            app.T1xyEditFieldLabel = uilabel(app.GridLayout2);
            app.T1xyEditFieldLabel.Text = 'T1 [x, y]';
            app.T1xyEditField = uieditfield(app.GridLayout2, 'numeric');
            app.T2xyEditFieldLabel = uilabel(app.GridLayout2);
            app.T2xyEditFieldLabel.Text = 'T2 [x, y]';
            app.Label_8 = uilabel(app.GridLayout2);
            app.Label_8.Text = ',';
            app.Label_7 = uilabel(app.GridLayout2);
            app.Label_7.Text = ',';
            app.Label_9 = uilabel(app.GridLayout2);
            app.Label_9.Text = ',';
            app.T2xyEditField = uieditfield(app.GridLayout2, 'numeric');
            app.T3xyEditFieldLabel = uilabel(app.GridLayout2);
            app.T3xyEditFieldLabel.Text = 'T3 [x, y]';
            app.T3xyEditField = uieditfield(app.GridLayout2, 'numeric');
            app.T1xyEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.T2xyEditField_4 = uieditfield(app.GridLayout2, 'numeric');
            app.T2xyEditField_4.ValueChangedFcn = createCallbackFcn(app, @startupFcn, true);
            app.T3xyEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.ChargerJSONButton = uibutton(app.GridLayout, 'push');
            app.ChargerJSONButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerJSONButtonPushed, true);
            app.ChargerJSONButton.Text = '📤 Charger .JSON';
            app.NombredlmentenxLabel = uilabel(app.GridLayout);
            app.NombredlmentenxLabel.Text = 'Résolution en x';
            app.RsolutionenxEditField = uieditfield(app.GridLayout, 'numeric');
            app.RsolutionenxEditField.Limits = [1 10000];
            app.RsolutionenxEditField.Value = 1;
            app.RsolutionenyEditFieldLabel = uilabel(app.GridLayout);
            app.RsolutionenyEditFieldLabel.Text = 'Résolution en y';
            app.RsolutionenyEditField = uieditfield(app.GridLayout, 'numeric');
            app.RsolutionenyEditField.Limits = [1 10000];
            app.RsolutionenyEditField.Value = 1;
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '❚❚';
            % Show the figure after all components are created
            app.ThermoProUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThermoProUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Statup_fct)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThermoProUIFigure)
        end
    end
end